classdef GUI_PPP_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        menu_file                       matlab.ui.container.Menu
        menu_file_setInputFile          matlab.ui.container.Menu
        menu_file_batch_proc            matlab.ui.container.Menu
        menu_file_parametersFiles       matlab.ui.container.Menu
        menu_file_parametersFiles_loadDefaultParameters  matlab.ui.container.Menu
        menu_file_parametersFiles_loadParameters  matlab.ui.container.Menu
        menu_file_parametersFiles_saveParameters  matlab.ui.container.Menu
        menu_file_settingsFiles         matlab.ui.container.Menu
        menu_file_settingsFiles_loadSettings  matlab.ui.container.Menu
        menu_file_settingsFiles_saveSettings  matlab.ui.container.Menu
        menu_file_exit                  matlab.ui.container.Menu
        menu_models                     matlab.ui.container.Menu
        menu_models_orbitClockData      matlab.ui.container.Menu
        menu_models_biases              matlab.ui.container.Menu
        menu_models_ionosphere          matlab.ui.container.Menu
        menu_models_troposphere         matlab.ui.container.Menu
        menu_models_otherCorrections    matlab.ui.container.Menu
        menu_estimation                 matlab.ui.container.Menu
        menu_estimation_ambiguityFixing  matlab.ui.container.Menu
        menu_estimation_adjustment      matlab.ui.container.Menu
        menu_run                        matlab.ui.container.Menu
        menu_run_processingOptions      matlab.ui.container.Menu
        menu_run_Export                 matlab.ui.container.Menu
        menu_plotting                   matlab.ui.container.Menu
        menu_single_plot                matlab.ui.container.Menu
        menu_multi_plot                 matlab.ui.container.Menu
        checkbox_batch_proc             matlab.ui.control.CheckBox
        pushbutton_menu_close_figures   matlab.ui.control.Button
        pushbutton_delete               matlab.ui.control.Button
        edit_output                     matlab.ui.control.EditField
        text_output                     matlab.ui.control.Label
        pushbutton_download             matlab.ui.control.Button
        pushbutton_stop_calculation     matlab.ui.control.Button
        pushbutton_run                  matlab.ui.control.Button
        text_version                    matlab.ui.control.Label
        uipanel_processingOptions       matlab.ui.container.Panel
        edit_smooth                     matlab.ui.control.EditField
        text_smooth                     matlab.ui.control.Label
        edit_omc_window                 matlab.ui.control.EditField
        text_omc_window                 matlab.ui.control.Label
        edit_omc_fac                    matlab.ui.control.EditField
        edit_omc_thresh_p               matlab.ui.control.EditField
        edit_omc_thresh_c               matlab.ui.control.EditField
        text_omc_fac                    matlab.ui.control.Label
        text_omc_thresh_p               matlab.ui.control.Label
        text_omc_thresh_c               matlab.ui.control.Label
        text302                         matlab.ui.control.Label
        edit_ss_thresh                  matlab.ui.control.EditField
        text_ss_thresh                  matlab.ui.control.Label
        edit_SNR_Mask                   matlab.ui.control.EditField
        text_SNR_mask                   matlab.ui.control.Label
        checkbox_check_omc              matlab.ui.control.CheckBox
        uibuttongroup_reset             matlab.ui.container.ButtonGroup
        radiobutton_reset_min           matlab.ui.control.RadioButton
        radiobutton_reset_epoch         matlab.ui.control.RadioButton
        edit_reset_epoch                matlab.ui.control.EditField
        checkbox_reset_fixed            matlab.ui.control.CheckBox
        checkbox_reset_float            matlab.ui.control.CheckBox
        text_reset_epoch                matlab.ui.control.Label
        uitable_excl_epochs             matlab.ui.control.Table
        text_excl_epochs                matlab.ui.control.Label
        uitable_exclude                 matlab.ui.control.Table
        buttongroup_timeSpan_format     matlab.ui.container.ButtonGroup
        text62                          matlab.ui.control.Label
        edit_timeFrame_to               matlab.ui.control.EditField
        edit_timeFrame_from             matlab.ui.control.EditField
        radiobutton_timeSpan_format_HOD  matlab.ui.control.RadioButton
        radiobutton_timeSpan_format_SOD  matlab.ui.control.RadioButton
        radiobutton_timeSpan_format_epochs  matlab.ui.control.RadioButton
        popupmenu_process               matlab.ui.control.DropDown
        text108                         matlab.ui.control.Label
        edit_Elevation_Mask             matlab.ui.control.EditField
        text_elev_mask                  matlab.ui.control.Label
        text_process                    matlab.ui.control.Label
        checkbox_AdjustPhase            matlab.ui.control.CheckBox
        uipanel_adjustment              matlab.ui.container.Panel
        uipanel_gnss_weights            matlab.ui.container.Panel
        text_weight_BDS                 matlab.ui.control.Label
        text_weight_GLO                 matlab.ui.control.Label
        text_weight_GAL                 matlab.ui.control.Label
        text_weight_GPS                 matlab.ui.control.Label
        edit_weight_BDS                 matlab.ui.control.EditField
        edit_weight_GAL                 matlab.ui.control.EditField
        edit_weight_GLO                 matlab.ui.control.EditField
        edit_weight_GPS                 matlab.ui.control.EditField
        text245                         matlab.ui.control.Label
        pushbutton_def_filter           matlab.ui.control.Button
        edit_Std_Iono                   matlab.ui.control.EditField
        text_std_iono                   matlab.ui.control.Label
        edit_Std_Iono_vtec              matlab.ui.control.EditField
        text_std_iono_vtec              matlab.ui.control.Label
        text241                         matlab.ui.control.Label
        uibuttongroup1                  matlab.ui.container.ButtonGroup
        edit_snr_weighting_function     matlab.ui.control.EditField
        edit_elevation_weighting_function  matlab.ui.control.EditField
        radiobutton_No_Dependency       matlab.ui.control.RadioButton
        radiobutton_Signal_Strength_Dependency  matlab.ui.control.RadioButton
        radiobutton_Elevation_Dependency  matlab.ui.control.RadioButton
        radiobutton_MPLC_Dependency     matlab.ui.control.RadioButton
        popupmenu_filter                matlab.ui.control.DropDown
        text102                         matlab.ui.control.Label
        uipanel_filter                  matlab.ui.container.Panel
        pushbutton_load_filter_settings  matlab.ui.control.Button
        pushbutton_save_filter_settings  matlab.ui.control.Button
        checkbox_estimate_rec_dcbs      matlab.ui.control.CheckBox
        popupmenu_filter_beidou_offset_dynmodel  matlab.ui.control.DropDown
        text_bds_time_offset_m          matlab.ui.control.Label
        text_bds_time_offset            matlab.ui.control.Label
        edit_filter_beidou_offset_Q     matlab.ui.control.EditField
        edit_filter_beidou_offset_sigma0  matlab.ui.control.EditField
        popupmenu_filter_dcbs_dynmodel  matlab.ui.control.DropDown
        text_dcbs_m                     matlab.ui.control.Label
        edit_filter_dcbs_Q              matlab.ui.control.EditField
        edit_filter_dcbs_sigma0         matlab.ui.control.EditField
        text_rec_dcbs                   matlab.ui.control.Label
        popupmenu_filter_iono_dynmodel  matlab.ui.control.DropDown
        text_iono_m                     matlab.ui.control.Label
        edit_filter_iono_Q              matlab.ui.control.EditField
        edit_filter_iono_sigma0         matlab.ui.control.EditField
        text_iono                       matlab.ui.control.Label
        popupmenu_filter_iono_vtec_dynmodel  matlab.ui.control.DropDown
        text_iono_vtec                  matlab.ui.control.Label
        edit_filter_iono_vtec_sigma0    matlab.ui.control.EditField
        edit_filter_iono_vtec_Q         matlab.ui.control.EditField
        text_iono_vtec_m                matlab.ui.control.Label
        popupmenu_filter_galileo_offset_dynmodel  matlab.ui.control.DropDown
        text_gal_time_offset_m          matlab.ui.control.Label
        text_gal_time_offset            matlab.ui.control.Label
        edit_filter_galileo_offset_Q    matlab.ui.control.EditField
        edit_filter_galileo_offset_sigma0  matlab.ui.control.EditField
        popupmenu_filter_zwd_dynmodel   matlab.ui.control.DropDown
        popupmenu_filter_ambiguities_dynmodel  matlab.ui.control.DropDown
        popupmenu_filter_glonass_offset_dynmodel  matlab.ui.control.DropDown
        popupmenu_filter_rec_clock_dynmodel  matlab.ui.control.DropDown
        popupmenu_filter_coord_dynmodel  matlab.ui.control.DropDown
        text107                         matlab.ui.control.Label
        text_zwd_m                      matlab.ui.control.Label
        edit_filter_zwd_Q               matlab.ui.control.EditField
        edit_filter_zwd_sigma0          matlab.ui.control.EditField
        text_zwd                        matlab.ui.control.Label
        text_glo_time_offset_m          matlab.ui.control.Label
        text_glo_time_offset            matlab.ui.control.Label
        text_gps_time_offset_m          matlab.ui.control.Label
        text_float_ambiguities_m        matlab.ui.control.Label
        text92                          matlab.ui.control.Label
        edit_filter_ambiguities_Q       matlab.ui.control.EditField
        edit_filter_ambiguities_sigma0  matlab.ui.control.EditField
        edit_filter_glonass_offset_Q    matlab.ui.control.EditField
        edit_filter_glonass_offset_sigma0  matlab.ui.control.EditField
        edit_filter_rec_clock_Q         matlab.ui.control.EditField
        edit_filter_rec_clock_sigma0    matlab.ui.control.EditField
        text_float_ambiguities          matlab.ui.control.Label
        text_gps_time_offset            matlab.ui.control.Label
        text87                          matlab.ui.control.Label
        text86                          matlab.ui.control.Label
        edit_filter_coord_Q             matlab.ui.control.EditField
        edit_filter_coord_sigma0        matlab.ui.control.EditField
        text_coordinates                matlab.ui.control.Label
        edit_Std_CA_Code                matlab.ui.control.EditField
        text_std_code                   matlab.ui.control.Label
        text_std_phase                  matlab.ui.control.Label
        edit_Std_Phase                  matlab.ui.control.EditField
        uipanel_orbitClockData          matlab.ui.container.Panel
        checkbox_obx                    matlab.ui.control.CheckBox
        uibuttongroup11                 matlab.ui.container.ButtonGroup
        text_corr2brdc_age_1            matlab.ui.control.Label
        edit_corr2brdc_age              matlab.ui.control.EditField
        text_corr2brdc_age_2            matlab.ui.control.Label
        text_obx                        matlab.ui.control.Label
        pushbutton_obx                  matlab.ui.control.Button
        edit_obx                        matlab.ui.control.EditField
        checkbox_MGEX                   matlab.ui.control.CheckBox
        popupmenu_CorrectionStream      matlab.ui.control.DropDown
        pushbutton_corr2brdc            matlab.ui.control.Button
        edit_corr2brdc                  matlab.ui.control.EditField
        text_CorrectionStream           matlab.ui.control.Label
        edit_clock                      matlab.ui.control.EditField
        pushbutton_clock                matlab.ui.control.Button
        pushbutton_sp3                  matlab.ui.control.Button
        edit_sp3                        matlab.ui.control.EditField
        text_clock                      matlab.ui.control.Label
        text_sp3                        matlab.ui.control.Label
        popupmenu_prec_prod             matlab.ui.control.DropDown
        text201                         matlab.ui.control.Label
        radiobutton_brdc_corr           matlab.ui.control.RadioButton
        radiobutton_prec_prod           matlab.ui.control.RadioButton
        uibuttongroup12                 matlab.ui.container.ButtonGroup
        pushbutton_nav_BDS              matlab.ui.control.Button
        text281                         matlab.ui.control.Label
        edit_nav_BDS                    matlab.ui.control.EditField
        pushbutton_nav_GAL              matlab.ui.control.Button
        edit_nav_GAL                    matlab.ui.control.EditField
        text192                         matlab.ui.control.Label
        pushbutton_nav_GPS              matlab.ui.control.Button
        edit_nav_GPS                    matlab.ui.control.EditField
        text191                         matlab.ui.control.Label
        pushbutton_nav_GLO              matlab.ui.control.Button
        edit_nav_GLO                    matlab.ui.control.EditField
        text190                         matlab.ui.control.Label
        popupmenu_nav_multi             matlab.ui.control.DropDown
        pushbutton_nav_multi            matlab.ui.control.Button
        edit_nav_multi                  matlab.ui.control.EditField
        radiobutton_single_nav          matlab.ui.control.RadioButton
        radiobutton_multi_nav           matlab.ui.control.RadioButton
        uibuttongroup_prec_prod_type    matlab.ui.container.ButtonGroup
        radiobutton_prec_prod_ultrarapid  matlab.ui.control.RadioButton
        radiobutton_prec_prod_rapid     matlab.ui.control.RadioButton
        radiobutton_prec_prod_final     matlab.ui.control.RadioButton
        uipanel_batch_proc              matlab.ui.container.Panel
        pushbutton_plot_stations        matlab.ui.control.Button
        checkbox_parfor                 matlab.ui.control.CheckBox
        pushbutton_add_folder           matlab.ui.control.Button
        checkbox_batch_manipulate_identical  matlab.ui.control.CheckBox
        checkbox_manipulate_all         matlab.ui.control.CheckBox
        load_process_list               matlab.ui.control.Button
        save_process_list               matlab.ui.control.Button
        pushbutton_delete_all_file      matlab.ui.control.Button
        pushbutton_add_files            matlab.ui.control.Button
        uitable_batch_proc              matlab.ui.control.Table
        uipanel_troposphere             matlab.ui.container.Panel
        pushbutton_tropo_file           matlab.ui.control.Button
        edit_tropo_file                 matlab.ui.control.EditField
        popupmenu_tropo_file            matlab.ui.control.DropDown
        uipanel26                       matlab.ui.container.Panel
        text_est_zwd_from               matlab.ui.control.Label
        edit_est_zwd_from               matlab.ui.control.EditField
        buttongroup_models_troposphere_Gw  matlab.ui.container.ButtonGroup
        radiobutton_models_troposphere_Gw_no  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_Gw_GPT3  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_Gw_GRAD  matlab.ui.control.RadioButton
        checkbox_estimate_ZWD           matlab.ui.control.CheckBox
        buttongroup_models_troposphere_mfw  matlab.ui.container.ButtonGroup
        radiobutton_models_troposphere_mfw_VMF3  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_mfw_GPT3  matlab.ui.control.RadioButton
        buttongroup_models_troposphere_zwd  matlab.ui.container.ButtonGroup
        radiobutton_models_troposphere_zwd_no  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_zwd_fromInSitu  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_zwd_GPT3  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_zwd_tropoFile  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_zwd_VMF3  matlab.ui.control.RadioButton
        uipanel24                       matlab.ui.container.Panel
        buttongroup_models_troposphere_Gh  matlab.ui.container.ButtonGroup
        radiobutton_models_troposphere_Gh_GRAD  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_Gh_GPT3  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_Gh_no  matlab.ui.control.RadioButton
        buttongroup_models_troposphere_mfh  matlab.ui.container.ButtonGroup
        radiobutton_models_troposphere_mfh_GPT3  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_mfh_VMF3  matlab.ui.control.RadioButton
        buttongroup_models_troposphere_zhd  matlab.ui.container.ButtonGroup
        radiobutton_models_troposphere_zhd_tropoFile  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_zhd_no  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_zhd_fromInSitu  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_zhd_GPT3  matlab.ui.control.RadioButton
        radiobutton_models_troposphere_zhd_VMF3  matlab.ui.control.RadioButton
        edit_temp                       matlab.ui.control.EditField
        edit_druck                      matlab.ui.control.EditField
        edit_feuchte                    matlab.ui.control.EditField
        text_feuchte                    matlab.ui.control.Label
        text_druck                      matlab.ui.control.Label
        text_temp                       matlab.ui.control.Label
        uipanel_ionosphere              matlab.ui.container.Panel
        buttongroup_bspline             matlab.ui.container.ButtonGroup
        edit_lat_res_level              matlab.ui.control.EditField
        edit_lat_degree                 matlab.ui.control.EditField
        text_lat_num_basis              matlab.ui.control.Label
        edit_lon_res_level              matlab.ui.control.EditField
        edit_lon_degree                 matlab.ui.control.EditField
        text_lon_num_basis              matlab.ui.control.Label
        edit_lat_max                    matlab.ui.control.EditField
        edit_lat_min                    matlab.ui.control.EditField
        text_lat_range                  matlab.ui.control.Label
        edit_lon_max                    matlab.ui.control.EditField
        edit_lon_min                    matlab.ui.control.EditField
        text_lon_range                  matlab.ui.control.Label
        edit_constraint_decrease        matlab.ui.control.EditField
        text_constraint_decrease        matlab.ui.control.Label
        buttongroup_models_ionosphere_ionex_type  matlab.ui.container.ButtonGroup
        radiobutton_ionex_rapid_highrate  matlab.ui.control.RadioButton
        radiobutton_ionex_rapid         matlab.ui.control.RadioButton
        radiobutton_ionex_final         matlab.ui.control.RadioButton
        edit_constraint_until           matlab.ui.control.EditField
        text_constraint_until           matlab.ui.control.Label
        buttongroup_source_ionosphere   matlab.ui.container.ButtonGroup
        radiobutton_source_ionosphere_IONEX  matlab.ui.control.RadioButton
        radiobutton_source_ionosphere_Klobuchar  matlab.ui.control.RadioButton
        radiobutton_source_ionosphere_NeQuick  matlab.ui.control.RadioButton
        radiobutton_source_ionosphere_CODE  matlab.ui.control.RadioButton
        radiobutton_model_ionosphere_CorrectionStream  matlab.ui.control.RadioButton
        buttongroup_models_ionosphere_autodetect  matlab.ui.container.ButtonGroup
        edit_iono_folder                matlab.ui.control.EditField
        pushbutton_iono_folder          matlab.ui.control.Button
        radiobutton_iono_folder_manual  matlab.ui.control.RadioButton
        radiobutton_iono_folder_auto    matlab.ui.control.RadioButton
        buttongroup_models_ionosphere_ionex  matlab.ui.container.ButtonGroup
        popupmenu_iono_interpol         matlab.ui.control.DropDown
        text_iono_interpol              matlab.ui.control.Label
        popupmenu_iono_source           matlab.ui.control.DropDown
        radiobutton_models_ionosphere_source  matlab.ui.control.RadioButton
        edit_iono_autodetect            matlab.ui.control.EditField
        radiobutton_models_ionosphere_ionex_autodetect  matlab.ui.control.RadioButton
        edit_ionex                      matlab.ui.control.EditField
        pushbutton_ionex                matlab.ui.control.Button
        radiobutton_models_ionosphere_ionex_manually  matlab.ui.control.RadioButton
        buttongroup_models_ionosphere   matlab.ui.container.ButtonGroup
        radiobutton_models_ionosphere_2freq  matlab.ui.control.RadioButton
        radiobutton_models_ionosphere_3freq  matlab.ui.control.RadioButton
        radiobutton_models_ionosphere_estimateConstraint  matlab.ui.control.RadioButton
        radiobutton_models_ionosphere_estimateVTEC  matlab.ui.control.RadioButton
        radiobutton_models_ionosphere_correct  matlab.ui.control.RadioButton
        radiobutton_models_ionosphere_estimate  matlab.ui.control.RadioButton
        radiobutton_models_ionosphere_off  matlab.ui.control.RadioButton
        uipanel_ambiguityFixing         matlab.ui.container.Panel
        edit_fixing_window              matlab.ui.control.EditField
        text_fixing_window              matlab.ui.control.Label
        text_hmw_release                matlab.ui.control.Label
        text_hmw_thresh                 matlab.ui.control.Label
        edit_hmw_thresh                 matlab.ui.control.EditField
        edit_hmw_release                matlab.ui.control.EditField
        text_hmw_fixing                 matlab.ui.control.Label
        text_pppar_excl_sats            matlab.ui.control.Label
        edit_pppar_excl_sats            matlab.ui.control.EditField
        text_pppar_cutoff               matlab.ui.control.Label
        edit_pppar_cutoff               matlab.ui.control.EditField
        uibuttongroup_wrongFixes        matlab.ui.container.ButtonGroup
        radiobutton_wrongFixes_off      matlab.ui.control.RadioButton
        radiobutton_wrongFixes_3        matlab.ui.control.RadioButton
        radiobutton_wrongFixes_vTPv     matlab.ui.control.RadioButton
        radiobutton_wrongFixes_diff     matlab.ui.control.RadioButton
        uibuttongroup_refSat            matlab.ui.container.ButtonGroup
        text_refSatBDS                  matlab.ui.control.Label
        edit_refSatBDS                  matlab.ui.control.EditField
        edit_refSatGAL                  matlab.ui.control.EditField
        edit_refSatGPS                  matlab.ui.control.EditField
        text_refSatGAL                  matlab.ui.control.Label
        text_refSatGPS                  matlab.ui.control.Label
        radiobutton_refSat              matlab.ui.control.RadioButton
        radiobutton_refSat_manually     matlab.ui.control.RadioButton
        radiobutton_refSat_high         matlab.ui.control.RadioButton
        checkbox_fixing                 matlab.ui.control.CheckBox
        edit_start_NL                   matlab.ui.control.EditField
        edit_start_WL                   matlab.ui.control.EditField
        text_start_NL                   matlab.ui.control.Label
        text_start_WL                   matlab.ui.control.Label
        uipanel_single_plot             matlab.ui.container.Panel
        pushbutton_load_true_kinematic  matlab.ui.control.Button
        checkbox_plot_mp                matlab.ui.control.CheckBox
        checkbox_plot_GI                matlab.ui.control.CheckBox
        checkbox_plot_dcb               matlab.ui.control.CheckBox
        checkbox_plot_UTM               matlab.ui.control.CheckBox
        checkbox_singlemultiplot        matlab.ui.control.CheckBox
        checkbox_plot_appl_biases       matlab.ui.control.CheckBox
        text239                         matlab.ui.control.Label
        text238                         matlab.ui.control.Label
        text237                         matlab.ui.control.Label
        pushbutton_load_pos_true        matlab.ui.control.Button
        edit_z_true                     matlab.ui.control.EditField
        text143                         matlab.ui.control.Label
        text_z_true                     matlab.ui.control.Label
        text141                         matlab.ui.control.Label
        text_y_true                     matlab.ui.control.Label
        edit_y_true                     matlab.ui.control.EditField
        text139                         matlab.ui.control.Label
        text_x_true                     matlab.ui.control.Label
        edit_x_true                     matlab.ui.control.EditField
        text_pos_true                   matlab.ui.control.Label
        pushbutton_check_all            matlab.ui.control.Button
        pushbutton_uncheck_all          matlab.ui.control.Button
        text_plot_path                  matlab.ui.control.Label
        pushbutton_plot_path            matlab.ui.control.Button
        edit_plot_path                  matlab.ui.control.EditField
        uibuttongroup_plot_float_fixed  matlab.ui.container.ButtonGroup
        checkbox_plot_bds               matlab.ui.control.CheckBox
        checkbox_plot_gal               matlab.ui.control.CheckBox
        checkbox_plot_glo               matlab.ui.control.CheckBox
        checkbox_plot_gps               matlab.ui.control.CheckBox
        radiobutton_plot_fixed          matlab.ui.control.RadioButton
        radiobutton_plot_float          matlab.ui.control.RadioButton
        checkbox_plot_stream_corr       matlab.ui.control.CheckBox
        checkbox_plot_cs                matlab.ui.control.CheckBox
        checkbox_plot_iono              matlab.ui.control.CheckBox
        pushbutton_plot                 matlab.ui.control.Button
        checkbox_plot_DOP               matlab.ui.control.CheckBox
        checkbox_plot_skyplot           matlab.ui.control.CheckBox
        checkbox_plot_res_sats          matlab.ui.control.CheckBox
        checkbox_plot_signal_qual       matlab.ui.control.CheckBox
        checkbox_plot_corr              matlab.ui.control.CheckBox
        checkbox_plot_clock             matlab.ui.control.CheckBox
        checkbox_plot_wet_tropo         matlab.ui.control.CheckBox
        checkbox_plot_cov_info          matlab.ui.control.CheckBox
        checkbox_plot_cov_amb           matlab.ui.control.CheckBox
        checkbox_plot_float_amb         matlab.ui.control.CheckBox
        checkbox_plot_fixed_amb         matlab.ui.control.CheckBox
        checkbox_plot_elev              matlab.ui.control.CheckBox
        checkbox_plot_sat_visibility    matlab.ui.control.CheckBox
        checkbox_plot_xyz               matlab.ui.control.CheckBox
        checkbox_plot_googlemaps        matlab.ui.control.CheckBox
        checkbox_plot_coordinate        matlab.ui.control.CheckBox
        checkbox_plot_residuals         matlab.ui.control.CheckBox
        uipanel_export                  matlab.ui.container.Panel
        checkbox_exp_satellites_D       matlab.ui.control.CheckBox
        checkbox_exp_kml                matlab.ui.control.CheckBox
        checkbox_exp_nmea               matlab.ui.control.CheckBox
        checkbox_exp_tropo_zpd          matlab.ui.control.CheckBox
        checkbox_exp_satellites         matlab.ui.control.CheckBox
        checkbox_exp_storeData          matlab.ui.control.CheckBox
        checkbox_exp_storeData_iono_mf  matlab.ui.control.CheckBox
        checkbox_exp_storeData_vtec     matlab.ui.control.CheckBox
        checkbox_exp_storeData_mp_1_2   matlab.ui.control.CheckBox
        checkbox_exp_obs_epochheader    matlab.ui.control.CheckBox
        checkbox_exp_obs_bias           matlab.ui.control.CheckBox
        text_exp_variables              matlab.ui.control.Label
        checkbox_exp_settings_summary   matlab.ui.control.CheckBox
        checkbox_exp_settings           matlab.ui.control.CheckBox
        checkbox_exp_results_fixed      matlab.ui.control.CheckBox
        checkbox_exp_results_float      matlab.ui.control.CheckBox
        checkbox_exp_data4plot          matlab.ui.control.CheckBox
        text_exp_output                 matlab.ui.control.Label
        text_exp_obs                    matlab.ui.control.Label
        checkbox_exp_model_save         matlab.ui.control.CheckBox
        uipanel_otherCorrections        matlab.ui.container.Panel
        edit_mp_cooldown                matlab.ui.control.EditField
        text_mp_cooldown                matlab.ui.control.Label
        text_mp_thresh                  matlab.ui.control.Label
        edit_mp_degree                  matlab.ui.control.EditField
        text_mp_degree                  matlab.ui.control.Label
        edit_mp_thresh                  matlab.ui.control.EditField
        checkbox_mp_detection           matlab.ui.control.CheckBox
        text321                         matlab.ui.control.Label
        text_cs_td_thresh               matlab.ui.control.Label
        edit_cs_td_degree               matlab.ui.control.EditField
        text_cs_td_degree               matlab.ui.control.Label
        edit_cs_td_thresh               matlab.ui.control.EditField
        checkbox_cs_td                  matlab.ui.control.CheckBox
        checkbox_LLI                    matlab.ui.control.CheckBox
        checkbox_eclipse                matlab.ui.control.CheckBox
        checkbox_ocean_loading          matlab.ui.control.CheckBox
        checkbox_GDV                    matlab.ui.control.CheckBox
        uibuttongroup_antex             matlab.ui.container.ButtonGroup
        checkbox_rec_antex              matlab.ui.control.CheckBox
        text_rec_ant                    matlab.ui.control.Label
        pushbutton_antex                matlab.ui.control.Button
        edit_antex                      matlab.ui.control.EditField
        radiobutton_antex_manual        matlab.ui.control.RadioButton
        radiobutton_antex_downl         matlab.ui.control.RadioButton
        radiobutton_antex_existing      matlab.ui.control.RadioButton
        checkbox_rec_pcv                matlab.ui.control.CheckBox
        checkbox_sat_pcv                matlab.ui.control.CheckBox
        edit_CycleSlip_Doppler_threshold  matlab.ui.control.EditField
        text182                         matlab.ui.control.Label
        checkbox_CycleSlip_Doppler      matlab.ui.control.CheckBox
        edit_CycleSlip_DF_threshold     matlab.ui.control.EditField
        text120                         matlab.ui.control.Label
        checkbox_CycleSlip_DF           matlab.ui.control.CheckBox
        text117                         matlab.ui.control.Label
        text116                         matlab.ui.control.Label
        edit_CycleSlip_L1C1_window      matlab.ui.control.EditField
        text115                         matlab.ui.control.Label
        edit_CycleSlip_L1C1_threshold   matlab.ui.control.EditField
        checkbox_CycleSlip_L1C1         matlab.ui.control.CheckBox
        checkbox_wind_up                matlab.ui.control.CheckBox
        checkbox_solid_tides            matlab.ui.control.CheckBox
        checkbox_rec_ARP                matlab.ui.control.CheckBox
        checkbox_rec_pco                matlab.ui.control.CheckBox
        checkbox_sat_pco                matlab.ui.control.CheckBox
        uipanel_biases                  matlab.ui.container.Panel
        buttongroup_models_biases_code_manually  matlab.ui.container.ButtonGroup
        pushbutton_dcb_P2C2             matlab.ui.control.Button
        edit_dcb_P2C2                   matlab.ui.control.EditField
        text_dcb_P2C2                   matlab.ui.control.Label
        pushbutton_bias                 matlab.ui.control.Button
        edit_bias                       matlab.ui.control.EditField
        radiobutton_models_biases_code_manually_Sinex  matlab.ui.control.RadioButton
        pushbutton_dcb_P1C1             matlab.ui.control.Button
        edit_dcb_P1C1                   matlab.ui.control.EditField
        text_dcb_P1C1                   matlab.ui.control.Label
        pushbutton_dcb_P1P2             matlab.ui.control.Button
        edit_dcb_P1P2                   matlab.ui.control.EditField
        text_dcb_P1P2                   matlab.ui.control.Label
        radiobutton_models_biases_code_manually_DCBs  matlab.ui.control.RadioButton
        buttongroup_models_biases_phase  matlab.ui.container.ButtonGroup
        radiobutton_models_biases_phase_SGG  matlab.ui.control.RadioButton
        radiobutton_models_biases_phase_off  matlab.ui.control.RadioButton
        radiobutton_models_biases_phase_manually  matlab.ui.control.RadioButton
        radiobutton_models_biases_phase_CorrectionStream  matlab.ui.control.RadioButton
        radiobutton_models_biases_phase_Wuhan  matlab.ui.control.RadioButton
        radiobutton_models_biases_phase_TUW  matlab.ui.control.RadioButton
        buttongroup_models_biases_code  matlab.ui.container.ButtonGroup
        radiobutton_models_biases_code_CNES_OSB  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_GFZ_MGEX  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_CNES_MGEX  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_WUM_MGEX  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_CAS_osb  matlab.ui.control.RadioButton
        radiobutton_brdc_tgd            matlab.ui.control.RadioButton
        radiobutton_models_biases_code_CNES_post  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_CODE_IAR  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_CODE_OSB  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_off  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_manually  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_CorrectionStream  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_CODE  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_DLR  matlab.ui.control.RadioButton
        radiobutton_models_biases_code_CAS  matlab.ui.control.RadioButton
        uipanel_setInputFile            matlab.ui.container.Panel
        text_RT_format                  matlab.ui.control.Label
        edit_RT_to                      matlab.ui.control.EditField
        text_RT_to                      matlab.ui.control.Label
        edit_RT_from                    matlab.ui.control.EditField
        text_RT_from                    matlab.ui.control.Label
        pushbutton_analyze_rinex        matlab.ui.control.Button
        edit_glo_rank                   matlab.ui.control.EditField
        popupmenu_glo_3                 matlab.ui.control.DropDown
        popupmenu_glo_2                 matlab.ui.control.DropDown
        popupmenu_glo_1                 matlab.ui.control.DropDown
        edit_bds_rank                   matlab.ui.control.EditField
        popupmenu_bds_3                 matlab.ui.control.DropDown
        popupmenu_bds_2                 matlab.ui.control.DropDown
        popupmenu_bds_1                 matlab.ui.control.DropDown
        checkbox_BDS                    matlab.ui.control.CheckBox
        checkbox_GAL                    matlab.ui.control.CheckBox
        checkbox_GPS                    matlab.ui.control.CheckBox
        checkbox_GLO                    matlab.ui.control.CheckBox
        text_rank                       matlab.ui.control.Label
        edit_gal_rank                   matlab.ui.control.EditField
        edit_gps_rank                   matlab.ui.control.EditField
        popupmenu_gal_3                 matlab.ui.control.DropDown
        popupmenu_gal_2                 matlab.ui.control.DropDown
        popupmenu_gal_1                 matlab.ui.control.DropDown
        popupmenu_gps_3                 matlab.ui.control.DropDown
        popupmenu_gps_2                 matlab.ui.control.DropDown
        popupmenu_gps_1                 matlab.ui.control.DropDown
        text_proc_freq                  matlab.ui.control.Label
        text41                          matlab.ui.control.Label
        checkbox_realtime               matlab.ui.control.CheckBox
        text42                          matlab.ui.control.Label
        text40                          matlab.ui.control.Label
        pushbutton_obs_file             matlab.ui.control.Button
        edit_z                          matlab.ui.control.EditField
        edit_y                          matlab.ui.control.EditField
        edit_x                          matlab.ui.control.EditField
        text_z                          matlab.ui.control.Label
        text_y                          matlab.ui.control.Label
        text_x                          matlab.ui.control.Label
        text_koord                      matlab.ui.control.Label
        edit_obs                        matlab.ui.control.EditField
        text_obs                        matlab.ui.control.Label
        uipanel_multi_plot              matlab.ui.container.Panel
        checkbox_multi_plot_each_station  matlab.ui.control.CheckBox
        checkbox_ztd_convergence        matlab.ui.control.CheckBox
        pushbutton_multi_plot_add_last  matlab.ui.control.Button
        checkbox_station_graph          matlab.ui.control.CheckBox
        checkbox_station_results        matlab.ui.control.CheckBox
        text_multi_manipulate           matlab.ui.control.Label
        checkbox_multi_manipulate_all   matlab.ui.control.CheckBox
        text285                         matlab.ui.control.Label
        edit_multi_plot_thresh_3D       matlab.ui.control.EditField
        text284                         matlab.ui.control.Label
        checkbox_quantile_conv          matlab.ui.control.CheckBox
        checkbox_multi_manipulate_same_station  matlab.ui.control.CheckBox
        checkbox_box_plot               matlab.ui.control.CheckBox
        checkbox_multi_plot_each_row    matlab.ui.control.CheckBox
        checkbox_pos_acc                matlab.ui.control.CheckBox
        text_conv_min                   matlab.ui.control.Label
        edit_conv_min                   matlab.ui.control.EditField
        checkbox_multi_manipulate_same_label  matlab.ui.control.CheckBox
        checkbox_multi_manipulate_identical  matlab.ui.control.CheckBox
        checkbox_ttff_plot              matlab.ui.control.CheckBox
        edit_multi_plot_thresh_hor_pos  matlab.ui.control.EditField
        edit_multi_plot_thresh_height_coord  matlab.ui.control.EditField
        text230                         matlab.ui.control.Label
        text229                         matlab.ui.control.Label
        text228                         matlab.ui.control.Label
        pushbutton_load_plot_list       matlab.ui.control.Button
        pushbutton_save_plot_list       matlab.ui.control.Button
        checkbox_pos_conv               matlab.ui.control.CheckBox
        edit_multi_plot_thresh_hor_coord  matlab.ui.control.EditField
        text225                         matlab.ui.control.Label
        checkbox_bar_conv               matlab.ui.control.CheckBox
        uibuttongroup_multi_plot_solution  matlab.ui.container.ButtonGroup
        radiobutton_multi_plot_fixed    matlab.ui.control.RadioButton
        radiobutton_multi_plot_float    matlab.ui.control.RadioButton
        pushbutton_multi_plot_plot      matlab.ui.control.Button
        checkbox_histo_conv             matlab.ui.control.CheckBox
        checkbox_coord_conv             matlab.ui.control.CheckBox
        pushbutton_multi_plot_delete    matlab.ui.control.Button
        pushbutton_multi_plot_add       matlab.ui.control.Button
        uitable_multi_plot              matlab.ui.control.Table
        axes1                           matlab.ui.control.UIAxes
    end


    methods (Access = private)
        function able_brdc_corr(app, handles, onoff)
            % disables stuff on panel Orbit/Clock Data depending on radiobuttons
            able_single_nav(app, handles, onoff)
            able_multi_nav(app, handles, onoff)
            set(handles.radiobutton_single_nav,	   'Enable', onoff);
            set(handles.radiobutton_multi_nav, 	   'Enable', onoff);
            set(handles.text_CorrectionStream,	   'Enable', onoff);
            set(handles.popupmenu_CorrectionStream,'Enable', onoff);
            set(handles.edit_corr2brdc,            'Enable', onoff);
            set(handles.text_corr2brdc_age_1,      'Enable', onoff);
            set(handles.text_corr2brdc_age_2,      'Enable', onoff);
            set(handles.edit_corr2brdc_age,        'Enable', onoff);
            set(handles.pushbutton_corr2brdc,	   'Enable', onoff);
        end

        function able_multi_nav(app, handles, onoff)
            % disables stuff on panel Orbit/Clock Data depending on radiobuttons
            set(handles.edit_nav_multi,         'Enable', onoff);
            set(handles.popupmenu_nav_multi,    'Enable', onoff);
            set(handles.pushbutton_nav_multi,   'Enable', onoff);
        end

        function able_prec_prod(app, handles, onoff)
            % disables stuff on panel Orbit/Clock Data depending on radiobuttons
            set(handles.text201,                'Enable', onoff);
            set(handles.popupmenu_prec_prod,   	'Enable', onoff);
            set(handles.checkbox_MGEX,          'Enable', onoff);
            set(handles.text_sp3,            	'Enable', onoff);
            set(handles.edit_sp3,            	'Enable', onoff);
            set(handles.pushbutton_sp3,         'Enable', onoff);
            set(handles.text_clock,             'Enable', onoff);
            set(handles.edit_clock,         	'Enable', onoff);
            set(handles.pushbutton_clock,    	'Enable', onoff);
            set(handles.text_obx,             	'Enable', onoff);
            set(handles.edit_obx,         		'Enable', onoff);
            set(handles.pushbutton_obx,    		'Enable', onoff);
            set(handles.uibuttongroup_prec_prod_type, 'Visible', onoff);
        end

        function able_single_nav(app, handles, onoff)
            % disables stuff on panel Orbit/Clock Data depending on radiobuttons
            set(handles.text191,                'Enable', onoff);
            set(handles.edit_nav_GPS,         	'Enable', onoff);
            set(handles.pushbutton_nav_GPS,  	'Enable', onoff);
            set(handles.text190,                'Enable', onoff);
            set(handles.edit_nav_GLO,          	'Enable', onoff);
            set(handles.pushbutton_nav_GLO,  	'Enable', onoff);
            set(handles.text192,                'Enable', onoff);
            set(handles.edit_nav_GAL,        	'Enable', onoff);
            set(handles.pushbutton_nav_GAL, 	'Enable', onoff);
            set(handles.text281,                'Enable', onoff);
            set(handles.edit_nav_BDS,        	'Enable', onoff);
            set(handles.pushbutton_nav_BDS, 	'Enable', onoff);
        end

        function able_version(app, handles, onoff)
            % Function to en/disable stuff on GUI depending on version of RINEX observation file
            % Panel: Input-Data
            set(handles.checkbox_GAL,    'Enable', onoff);
            set(handles.checkbox_BDS,    'Enable', onoff);
            % Panel: Processing-Options
            set(handles.text_proc_freq,  'Enable', 'On');
            set(handles.text_rank,       'Enable', onoff);
            set(handles.edit_gps_rank,   'Enable', onoff);
            set(handles.edit_glo_rank,   'Enable', onoff);
            set(handles.edit_gal_rank,   'Enable', onoff);
            set(handles.edit_bds_rank,   'Enable', onoff);
            set(handles.popupmenu_gps_1, 'Enable', 'On');
            set(handles.popupmenu_gps_2, 'Enable', 'On');
            set(handles.popupmenu_gps_3, 'Enable', onoff);
            set(handles.popupmenu_glo_1, 'Enable', 'On');
            set(handles.popupmenu_glo_2, 'Enable', 'On');
            set(handles.popupmenu_glo_3, 'Enable', onoff);
            set(handles.popupmenu_gal_1, 'Enable', onoff);
            set(handles.popupmenu_gal_2, 'Enable', onoff);
            set(handles.popupmenu_gal_3, 'Enable', onoff);
            set(handles.popupmenu_bds_1, 'Enable', onoff);
            set(handles.popupmenu_bds_2, 'Enable', onoff);
            set(handles.popupmenu_bds_3, 'Enable', onoff);
        end

        function edit_antex_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject, 'BackgroundColor'), get(0, 'defaultUicontrolBackgroundColor'))
                set(hObject, 'BackgroundColor', 'white');
            end
        end

        function folder = getFolderPath(app, default, path, folder_date)
            % get folder for uigetfile, default or the path from already entered file
            % or subfolder from date of observation file
            % default       string, path to default folder
            % path          string, path to folder were last file was
            % folder_date   string, subfolder from date of observation file

            folder = default;
            % check if subfolder exists
            if ~isempty(folder_date) && ~strcmp(folder_date, '/0000/000/') && exist([folder folder_date], 'file')
                folder = [folder folder_date];   % ... from date of observation file
            elseif ~isempty(path)
                % path from already entered file
                folder = path;
            end
        end

        function PLOT = getPlotSettingsFromGUI(app, handles)
            % PLOT.xy variables are boolean
            % which solution should be plotted?
            PLOT.float = get(handles.radiobutton_plot_float, 'Value');
            PLOT.fixed = get(handles.radiobutton_plot_fixed, 'Value');
            % which plots should be opened?
            PLOT.coordinate    = get(handles.checkbox_plot_coordinate,    'Value');
            PLOT.map       	   = get(handles.checkbox_plot_googlemaps,    'Value');
            PLOT.UTM       	   = get(handles.checkbox_plot_UTM,           'Value');
            PLOT.coordxyz      = get(handles.checkbox_plot_xyz,           'Value');
            PLOT.elevation     = get(handles.checkbox_plot_elev,          'Value');
            PLOT.satvisibility = get(handles.checkbox_plot_sat_visibility,'Value');
            PLOT.float_amb     = get(handles.checkbox_plot_float_amb,     'Value');
            PLOT.fixed_amb     = get(handles.checkbox_plot_fixed_amb,     'Value');
            PLOT.clock     	   = get(handles.checkbox_plot_clock,         'Value');
            PLOT.dcb     	   = get(handles.checkbox_plot_dcb,           'Value');
            PLOT.wet_tropo     = get(handles.checkbox_plot_wet_tropo,     'Value');
            PLOT.cov_info      = get(handles.checkbox_plot_cov_info,      'Value');
            PLOT.cov_amb       = get(handles.checkbox_plot_cov_amb,       'Value');
            PLOT.corr          = get(handles.checkbox_plot_corr,          'Value');
            PLOT.skyplot       = get(handles.checkbox_plot_skyplot,       'Value');
            PLOT.residuals     = get(handles.checkbox_plot_residuals,     'Value');
            PLOT.DOP           = get(handles.checkbox_plot_DOP,           'Value');
            % PLOT.GI            = get(handles.checkbox_plot_GI,            'Value');
            PLOT.iono          = get(handles.checkbox_plot_iono,          'Value');
            % PLOT.iono_comp     = get(handles.checkbox_plot_iono_comp,     'Value');
            PLOT.cs            = get(handles.checkbox_plot_cs,            'Value');
            PLOT.mp            = get(handles.checkbox_plot_mp,            'Value');
            PLOT.appl_biases   = get(handles.checkbox_plot_appl_biases,   'Value');
            PLOT.signal_qual   = get(handles.checkbox_plot_signal_qual,   'Value');
            PLOT.res_sats      = get(handles.checkbox_plot_res_sats,      'Value');
            PLOT.stream_corr   = get(handles.checkbox_plot_stream_corr,   'Value');

            % get true coordinates or keep filepath to reference trajectory
            if isfile([handles.edit_y_true.String handles.edit_z_true.String])
                PLOT.pos_true = [ handles.edit_y_true.String handles.edit_z_true.String ];
            else
                PLOT.pos_true = [ str2double(handles.edit_x_true.String) ; str2double(handles.edit_y_true.String) ; str2double(handles.edit_z_true.String) ];
            end
        end

        function path_full = join_path(app, path_1, path_2)
            % create full file-path and handle some special cases
            if isempty(path_1) || isempty(path_2)
                path_full = [path_1, path_2];
                return
            end
            slash_1 = [];
            if ~(path_1(end) == '/' || path_1(end) == '\')
                slash_1 = '/';
            end
            path_full = strcat(path_1, slash_1, path_2);
        end

        function [pmenu_main, pmenu_1, pmenu_2] = manipulate_proc_freq(app, pmenu_main, pmenu_1, pmenu_2, off_value)
            % function to check if other popupmenus of processed frequencies have the
            % same value. If this is the case they are set to OFF
            value_main = get(pmenu_main, 'Value');      % value of popupmenu which was changed
            if value_main == off_value; return; end
            % values of the other two popupmenues
            value_1 = get(pmenu_1, 'Value');
            value_2 = get(pmenu_2, 'Value');
            % check if they have the same value and reset if this is the case
            if value_main == value_1
                set(pmenu_1, 'Value', off_value);
            end
            if value_main == value_2
                set(pmenu_2, 'Value', off_value);
            end
        end

        function radiobutton_models_troposphere_zwd_fromInSitu_Callback(app, hObject, eventdata, handles)
            reset_path(app, hObject, handles, 'met')
            set(handles.text_temp,   'Visible','on');
            set(handles.text_feuchte,'Visible','on');
            set(handles.edit_temp,   'Visible','on');
            set(handles.edit_feuchte,'Visible','on');
            set(handles.edit_temp,   'Enable','on');
            set(handles.edit_feuchte,'Enable','on');
            set(handles.edit_temp,   'String','15.00');
            set(handles.edit_feuchte,'String','48.14');
        end

        function radiobutton_models_troposphere_zwd_tropoFile_Callback(app, hObject, eventdata, handles)
            set(handles.radiobutton_models_troposphere_zhd_tropoFile, 'Value', 1)

            set(handles.text_druck,'Visible','off');   % it is also necessary to set theis to invisible, because above only temperature and humidity are set to invisible
            set(handles.edit_druck,'Visible','off');
            set(handles.edit_druck,'String','');
        end

        function radiobutton_refSat_central_Callback(app, hObject, eventdata, handles)
            handles.text_refSatGPS.Enable = 'Off'; handles.edit_refSatGPS.Enable = 'Off';
            handles.text_refSatGAL.Enable = 'Off'; handles.edit_refSatGAL.Enable = 'Off';
            handles.text_refSatBDS.Enable = 'Off'; handles.edit_refSatBDS.Enable = 'Off';
        end

        function reset_path(app, hObject, handles, field)
            % Reset path of field to empty string
            path = handles.paths;           % ||| check this!!!!
            eval(['path.' field '_1 = '''';'])
            eval(['path.' field '_2 = '''';'])
            handles.paths = path;
            guidata(hObject,handles);
        end

        function setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_setInputFile,      'Visible',  'Off');   % Set input file
            set(handles.uipanel_batch_proc,        'Visible',  'Off');   % Batch Processing
            set(handles.uipanel_orbitClockData,    'Visible',  'Off');   % Orbit/Clock data
            set(handles.uipanel_troposphere,       'Visible',  'Off');   % Troposphere
            set(handles.uipanel_ionosphere,        'Visible',  'Off');   % Ionosphere
            set(handles.uipanel_biases,            'Visible',  'Off');   % Biases
            set(handles.uipanel_otherCorrections,  'Visible',  'Off');   % Other Corrections
            set(handles.uipanel_ambiguityFixing,   'Visible',  'Off');   % Ambiguity fixing
            set(handles.uipanel_adjustment,        'Visible',  'Off');   % Adjustment
            set(handles.uipanel_processingOptions, 'Visible',  'Off');   % Processing options
            set(handles.uipanel_export,            'Visible',  'Off');   % Export options
            set(handles.uipanel_single_plot,       'Visible',  'Off');   % Single-Plots
            set(handles.uipanel_multi_plot,        'Visible',  'Off');   % Multi-Plots
        end

        function handles = un_check_multiplot_checkboxes(app, handles, value)
            % function to check or uncheck all multi-plot checkboxes
            set(handles.checkbox_pos_conv,          'Value', value);
            set(handles.checkbox_coord_conv,        'Value', value);
            set(handles.checkbox_pos_acc,           'Value', value);
            set(handles.checkbox_box_plot,          'Value', value);
            set(handles.checkbox_ttff_plot,         'Value', value);
            set(handles.checkbox_histo_conv,        'Value', value);
            set(handles.checkbox_bar_conv,          'Value', value);
            set(handles.checkbox_quantile_conv,     'Value', value);
            set(handles.checkbox_station_results,   'Value', value);
            set(handles.checkbox_station_graph,     'Value', value);
            set(handles.checkbox_ztd_convergence,   'Value', value);
        end

        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)

            % Load data for component configuration
            componentData = load('GUI_PPP_App.mat');

            % Set component properties that require runtime configuration
            app.uitable_multi_plot.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.uitable_multi_plot.ColumnFormat = {'char' 'char' 'numeric' 'numeric' 'numeric' 'logical' 'char'};
            app.uitable_multi_plot.Data = componentData.uitable_multi_plot.Data;
            app.radiobutton_ionex_final.UserData = componentData.radiobutton_ionex_final.UserData;
            app.uitable_batch_proc.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.uitable_batch_proc.ColumnFormat = {'char' 'char' 'numeric' 'numeric' 'numeric' 'logical' 'char' 'char' 'logical' 'char' 'char' 'logical' 'char' 'char' 'logical' 'char' 'char' 'numeric' 'numeric'};
            app.uitable_batch_proc.Data = componentData.uitable_batch_proc.Data;
            app.uitable_exclude.BackgroundColor = [1 1 1];
            app.uitable_exclude.ColumnFormat = {[] 'numeric' 'numeric'};
            app.uitable_exclude.Data = componentData.uitable_exclude.Data;
            app.uitable_excl_epochs.ColumnFormat = {'numeric' 'numeric' 'logical'};
            app.uitable_excl_epochs.Data = componentData.uitable_excl_epochs.Data;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_PPP_OpeningFcn(app, varargin)
            % --- Executes just before GUI_PPP is made visible.
            cwd = pwd; 
            if ~strcmp(cwd(end-3:end), 'WORK') && strcmpi(cwd(end-2:end), 'GUI')
                addpath('./../../../WORK/');
                cd ./../../../WORK; 
            end 
           
            addpath(genpath('./../../../CODE/'));       % helpful when starting from ./GUI/
            if isfolder(Path.DATA) && isfolder(Path.RESULTS) && isfolder(Path.CODE)
                % check if all paths are correct
                addpath(pwd)                    % add current path where RUN.m and (hopefully) Path.m are located
                addpath(genpath(Path.CODE));	% add directory of source code

            end

            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);

            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_PPP (see VARARGIN)

            setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_setInputFile,      'Visible',  'On');

            % Choose default command line output for GUI_PPP
            handles.output = hObject;

            % Comment to undock Figures and GUI
            set(gcf, 'WindowStyle', 'normal')
            set(0, 'DefaultFigureWindowStyle', 'normal')

            % position of GUI on screen
            set(gca, 'Units', 'pixels', 'Position', [18 516 900 80])
            axis off

            % Set Copyright and Version in the lower right
            set(handles.text_version, 'String', ['Version 1.4a ', char(169), ' TUW & JBU 2023']);
            set(handles.checkbox_realtime, 'Visible', 'Off');

            % load default filter settings for selected filter
            handles = LoadDefaultFilterSettings(handles);

            % load default thresholds for Multi-Plot
            % radiobutton_multi_plot_float_fixed_Callback(hObject, eventdata, handles);
            app.uipanel_multi_plot;

            % Menu-Items, set data panel at the front when opening GUI
            % menu_file_setInputFile_Callback(hObject, eventdata, handles)

            app.menu_file_setInputFile = uimenu(app.menu_file);


            % Somehow necessary, does not work as default in GUIDE
            handles.radiobutton_models_ionosphere_source.Value = 1;

            % Initialising variable path
            path.obs_1      = ''; 	path.obs_2      = '';
            path.navMULTI_1 = ''; 	path.navMULTI_2 = '';
            path.navGPS_1   = ''; 	path.navGPS_2   = '';
            path.navGLO_1   = '';  	path.navGLO_2   = '';
            path.navGAL_1   = '';  	path.navGAL_2   = '';
            path.navBDS_1   = '';  	path.navBDS_2   = '';
            path.tropo_1    = ''; 	path.tropo_2    = '';
            path.ionex_1    = ''; 	path.ionex_2    = '';
            path.iono_folder= '';
            path.sp3_1      = ''; 	path.sp3_2      = '';
            path.clk_1      = ''; 	path.clk_2      = '';
            path.obx_1      = ''; 	path.obx_2      = '';
            path.corr2brdc_1 = '';	path.corr2brdc_2 = '';
            path.dcbP1P2_1  = ''; 	path.dcbP1P2_2  = '';
            path.dcbP1C1_1  = ''; 	path.dcbP1C1_2  = '';
            path.dcbP2C2_1  = ''; 	path.dcbP2C2_2  = '';
            path.bias_1     = '';	path.bias_2     = '';
            path.antex_1    = '';	path.antex_2     = '';
            path.rinex_date = '/0000/000/';
            path.plotfile = '';
            path.last_plot = [];
            path.last_multi_plot = [];
            path.lastproc = '';

            % putting struct with file-paths into handles
            handles.paths = path;

            % Update handles structure
            guidata(hObject, handles);

            % save (overwrite) the default parameters file
            defaultParamFilename='PARAMETERS/default.mat';
            settings = getSettingsFromGUI(handles);   % get input from GUI and put it into structure "settings"
            parameters = settings2parameters(settings);   % change the settings to parameters
            save(defaultParamFilename, 'parameters')    % save variable settings into file
        end

        % Selection changed function: buttongroup_models_biases_code
        function buttongroup_models_biases_code_SelectionChangeFcn(app, event)
            % --- Executes when selected object is changed in buttongroup_models_biases_code.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if get(handles.radiobutton_models_biases_code_manually, 'Value') == 0   % make sure the manual selection is invisble, unless it is selected
                set(handles.buttongroup_models_biases_code_manually,'Visible','Off');
            end
        end

        % Selection changed function: buttongroup_models_ionosphere
        function buttongroup_models_ionosphere_SelectionChangeFcn(app, event)
            % --- Executes when selected object is changed in panel_models_ionosphere.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles.buttongroup_bspline = app.buttongroup_bspline;
            handles = GUI_enable_onoff(handles);
            guidata(hObject, handles);
        end

        % Callback function: buttongroup_models_ionosphere_autodetect, 
        % ...and 1 other component
        function buttongroup_models_ionosphere_autodetect_SelectionChangeFcn(app, event)
            % --- Executes when selected object is changed in panel_models_ionosphere.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % activate Ionosphere fields in Estimation - Adjustment only if Ionosphere is estimated
            if get(handles.radiobutton_iono_folder_auto,    'Value')
                set(handles.edit_iono_folder,               'Enable', 'Off');
                set(handles.edit_iono_folder,               'Enable', 'Off');
            else
                set(handles.pushbutton_iono_folder,         'Enable', 'On');
                set(handles.pushbutton_iono_folder,       	'Enable', 'On');
            end
            guidata(hObject, handles);
        end

        % Selection changed function: buttongroup_models_ionosphere_ionex
        function buttongroup_models_ionosphere_ionex_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_enable_onoff(handles);
        end

        % Selection changed function: buttongroup_source_ionosphere
        function buttongroup_source_ionosphere_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_enable_onoff(handles);
            handles = GUI_enable_onoff(handles);
        end

        % Value changed function: checkbox_BDS
        function checkbox_BDS_Callback(app, event)
            % BeiDou Checkbox

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if get(handles.checkbox_BDS, 'Value')	% BeiDou processing enabled

                % Analyze header of RINEX file and change GUI according to it
                FileName = handles.edit_obs.String;
                PathName = getFolderPath(app, [Path.DATA '/OBS/'], handles.paths.obs_1, handles.paths.rinex_date);
                if isempty(FileName) || ~isfile([PathName,FileName]);   return;     end
                rheader = anheader_GUI([PathName,FileName]);

                bds_freq = repmat({'Off'},3,1);   % initialize with 'Off', because it can happen that there are less than 3 frequencies contained and these shall then be 'Off'
                if all(rheader.ind_bds_freq ~= 0)
                    bds_freq(1:length(rheader.ind_bds_freq)) = DEF.freq_BDS_names(rheader.ind_bds_freq);
                end

                % change popupmenues of processed frequencies
                set(handles.popupmenu_bds_1, 'Value', find(strcmpi(DEF.freq_BDS_names,bds_freq{1})));
                set(handles.popupmenu_bds_2, 'Value', find(strcmpi(DEF.freq_BDS_names,bds_freq{2})));
                set(handles.popupmenu_bds_3, 'Value', find(strcmpi(DEF.freq_BDS_names,bds_freq{3})));

                % Enable/Disable stuff on GUI depending on observation file
                % ||| check for Doppler observation and en/disable CS detection with Doppler
                if floor(rheader.version_full) >= 3
                    set(handles.text_proc_freq,  'Enable', 'On');
                    set(handles.text_rank,       'Enable', 'On');
                    set(handles.edit_bds_rank,   'Enable', 'On');
                    set(handles.popupmenu_bds_1, 'Enable', 'On');
                    set(handles.popupmenu_bds_2, 'Enable', 'On');
                    set(handles.popupmenu_bds_3, 'Enable', 'On');
                    set(handles.edit_bds_rank, 'String', rheader.bds_ranking);
                elseif floor(rheader.version_full) == 2
                    able_version(app, handles, 'Off')
                    set(handles.checkbox_BDS, 'Value', 0);
                end

                set(handles.edit_filter_beidou_offset_Q,             'Enable', 'On');
                set(handles.edit_filter_beidou_offset_sigma0,        'Enable', 'On');
                set(handles.text_bds_time_offset,                     'Enable', 'On');
                set(handles.text_bds_time_offset_m,                   'Enable', 'On');
                set(handles.popupmenu_filter_beidou_offset_dynmodel, 'Enable', 'On');

            else                                    % BeiDou processing disabled

                set(handles.popupmenu_bds_1,                          'Enable', 'Off');
                set(handles.popupmenu_bds_2,                          'Enable', 'Off');
                set(handles.popupmenu_bds_3,                          'Enable', 'Off');
                set(handles.edit_bds_rank,                            'Enable', 'Off');
                set(handles.edit_filter_beidou_offset_Q,             'Enable', 'Off');
                set(handles.edit_filter_beidou_offset_sigma0,        'Enable', 'Off');
                set(handles.text_bds_time_offset,                     'Enable', 'Off');
                set(handles.text_bds_time_offset_m,                   'Enable', 'Off');
                set(handles.popupmenu_filter_beidou_offset_dynmodel, 'Enable', 'Off');

            end
        end

        % Value changed function: checkbox_GAL
        function checkbox_GAL_Callback(app, event)
            % GALILEO Checkbox

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if get(handles.checkbox_GAL, 'Value')	% Galileo processing enabled

                % Analyze header of RINEX file and change GUI according to it
                FileName = handles.edit_obs.String;
                PathName = getFolderPath(app, [Path.DATA '/OBS/'], handles.paths.obs_1, handles.paths.rinex_date);
                if isempty(FileName) || ~isfile([PathName,FileName]);   return;     end
                rheader = anheader_GUI([PathName,FileName]);

                gal_freq = repmat({'Off'},3,1);   % initialize with 'Off', because it can happen that there are less than 3 frequencies contained and these shall then be 'Off'
                if all(rheader.ind_gal_freq ~= 0)
                    gal_freq(1:length(rheader.ind_gal_freq)) = DEF.freq_GAL_names(rheader.ind_gal_freq);
                end

                % change popupmenues of processed frequencies
                set(handles.popupmenu_gal_1, 'Value', find(strcmpi(DEF.freq_GAL_names,gal_freq{1})));
                set(handles.popupmenu_gal_2, 'Value', find(strcmpi(DEF.freq_GAL_names,gal_freq{2})));
                set(handles.popupmenu_gal_3, 'Value', find(strcmpi(DEF.freq_GAL_names,gal_freq{3})));

                % Enable/Disable stuff on GUI depending on observation file
                % ||| check for Doppler observation and en/disable CS detection with Doppler
                if floor(rheader.version_full) >= 3
                    set(handles.text_proc_freq,  'Enable', 'On');
                    set(handles.text_rank,       'Enable', 'On');
                    set(handles.edit_gal_rank,   'Enable', 'On');
                    set(handles.popupmenu_gal_1, 'Enable', 'On');
                    set(handles.popupmenu_gal_2, 'Enable', 'On');
                    set(handles.popupmenu_gal_3, 'Enable', 'On');
                    set(handles.edit_gal_rank, 'String', rheader.gal_ranking);
                elseif floor(rheader.version_full) == 2
                    able_version(app, handles, 'Off')
                    set(handles.checkbox_GAL, 'Value', 0);
                end

                set(handles.edit_filter_galileo_offset_Q,             'Enable', 'On');
                set(handles.edit_filter_galileo_offset_sigma0,        'Enable', 'On');
                set(handles.text_gal_time_offset,                     'Enable', 'On');
                set(handles.text_gal_time_offset_m,                   'Enable', 'On');
                set(handles.popupmenu_filter_galileo_offset_dynmodel, 'Enable', 'On');

            else                                    % Galileo processing disabled

                set(handles.popupmenu_gal_1,                          'Enable', 'Off');
                set(handles.popupmenu_gal_2,                          'Enable', 'Off');
                set(handles.popupmenu_gal_3,                          'Enable', 'Off');
                set(handles.edit_gal_rank,                            'Enable', 'Off');
                set(handles.edit_filter_galileo_offset_Q,             'Enable', 'Off');
                set(handles.edit_filter_galileo_offset_sigma0,        'Enable', 'Off');
                set(handles.text_gal_time_offset,                     'Enable', 'Off');
                set(handles.text_gal_time_offset_m,                   'Enable', 'Off');
                set(handles.popupmenu_filter_galileo_offset_dynmodel, 'Enable', 'Off');

            end
        end

        % Value changed function: checkbox_GLO
        function checkbox_GLO_Callback(app, event)
            % GLONASS Checkbox

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if get(handles.checkbox_GLO, 'Value')	% Glonass processing enabled

                % Analyze header of RINEX file and change GUI according to it
                FileName = handles.edit_obs.String;
                PathName = getFolderPath(app, [Path.DATA '/OBS/'], handles.paths.obs_1, handles.paths.rinex_date);
                if isempty(FileName) || ~isfile([PathName,FileName]);   return;     end
                rheader = anheader_GUI([PathName,FileName]);

                glo_freq = repmat({'Off'},3,1);   % initialize with 'Off', because it can happen that there are less than 3 frequencies contained and these shall then be 'Off'
                if all(rheader.ind_glo_freq ~= 0)
                    glo_freq(1:length(rheader.ind_glo_freq)) = DEF.freq_GLO_names(rheader.ind_glo_freq);
                end

                % change popupmenues of processed frequencies
                set(handles.popupmenu_glo_1, 'Value', find(strcmpi(DEF.freq_GLO_names,glo_freq{1})));
                set(handles.popupmenu_glo_2, 'Value', find(strcmpi(DEF.freq_GLO_names,glo_freq{2})));
                set(handles.popupmenu_glo_3, 'Value', find(strcmpi(DEF.freq_GLO_names,glo_freq{3})));

                % Enable/Disable stuff on GUI depending on observation file
                % ||| check for Doppler observation and en/disable CS detection with Doppler
                if floor(rheader.version_full) >= 3
                    set(handles.text_proc_freq,  'Enable', 'On');
                    set(handles.text_rank,       'Enable', 'On');
                    set(handles.edit_glo_rank,   'Enable', 'On');
                    set(handles.popupmenu_glo_1, 'Enable', 'On');
                    set(handles.popupmenu_glo_2, 'Enable', 'On');
                    set(handles.popupmenu_glo_3, 'Enable', 'On');
                    set(handles.edit_glo_rank, 'String', rheader.glo_ranking);
                elseif floor(rheader.version_full) == 2
                    able_version(app, handles, 'Off')
                    set(handles.popupmenu_glo_3, 'Value', 4);
                end

                set(handles.edit_filter_glonass_offset_Q,             'Enable', 'On');
                set(handles.edit_filter_glonass_offset_sigma0,        'Enable', 'On');
                set(handles.text_glo_time_offset,                     'Enable', 'On');
                set(handles.text_glo_time_offset_m,                   'Enable', 'On');
                set(handles.popupmenu_filter_glonass_offset_dynmodel, 'Enable', 'On');

            else   % Glonass processing disabled

                % Processed Frequencies and Ranking
                set(handles.popupmenu_glo_1,                     'Enable', 'Off');
                set(handles.popupmenu_glo_2,                     'Enable', 'Off');
                set(handles.popupmenu_glo_3,                     'Enable', 'Off');
                set(handles.edit_glo_rank,                       'Enable', 'Off');
                set(handles.edit_filter_glonass_offset_Q,             'Enable', 'Off');
                set(handles.edit_filter_glonass_offset_sigma0,        'Enable', 'Off');
                set(handles.text_glo_time_offset,                     'Enable', 'Off');
                set(handles.text_glo_time_offset_m,                   'Enable', 'Off');
                set(handles.popupmenu_filter_glonass_offset_dynmodel, 'Enable', 'Off');

            end
        end

        % Value changed function: checkbox_GPS
        function checkbox_GPS_Callback(app, event)
            % GPS Checkbox

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if get(handles.checkbox_GPS, 'Value')	% GPS processing enabled

                % Analyze header of RINEX file and change GUI according to it
                FileName = handles.edit_obs.String;
                PathName = getFolderPath(app, [Path.DATA '/OBS/'], handles.paths.obs_1, handles.paths.rinex_date);
                if isempty(FileName) || ~isfile([PathName,FileName]);   return;     end
                rheader = anheader_GUI([PathName,FileName]);

                gps_freq = repmat({'Off'},3,1);   % initialize with 'Off', because it can happen that there are less than 3 frequencies contained and these shall then be 'Off'
                if all(rheader.ind_gps_freq ~= 0)
                    gps_freq(1:length(rheader.ind_gps_freq)) = DEF.freq_GPS_names(rheader.ind_gps_freq);
                end

                % change popupmenues of processed frequencies
                set(handles.popupmenu_gps_1, 'Value', find(strcmpi(DEF.freq_GPS_names,gps_freq{1})));
                set(handles.popupmenu_gps_2, 'Value', find(strcmpi(DEF.freq_GPS_names,gps_freq{2})));
                set(handles.popupmenu_gps_3, 'Value', find(strcmpi(DEF.freq_GPS_names,gps_freq{3})));

                % Enable/Disable stuff on GUI depending on observation file
                % ||| check for Doppler observation and en/disable CS detection with Doppler
                if floor(rheader.version_full) >= 3
                    set(handles.text_proc_freq,  'Enable', 'On');
                    set(handles.text_rank,       'Enable', 'On');
                    set(handles.edit_gps_rank,   'Enable', 'On');
                    set(handles.popupmenu_gps_1, 'Enable', 'On');
                    set(handles.popupmenu_gps_2, 'Enable', 'On');
                    set(handles.popupmenu_gps_3, 'Enable', 'On');
                    set(handles.edit_gps_rank, 'String', rheader.gps_ranking);
                elseif floor(rheader.version_full) == 2
                    able_version(app, handles, 'Off')
                    set(handles.popupmenu_gps_3, 'Value', 4);
                end

                set(handles.edit_filter_rec_clock_Q,             'Enable', 'On');
                set(handles.edit_filter_rec_clock_sigma0,        'Enable', 'On');
                set(handles.text_gps_time_offset,                'Enable', 'On');
                set(handles.text_gps_time_offset_m,              'Enable', 'On');
                set(handles.popupmenu_filter_rec_clock_dynmodel, 'Enable', 'On');

            else   % GPS processing disabled

                % Processed Frequencies and Ranking
                set(handles.popupmenu_gps_1,                     'Enable', 'Off');
                set(handles.popupmenu_gps_2,                     'Enable', 'Off');
                set(handles.popupmenu_gps_3,                     'Enable', 'Off');
                set(handles.edit_gps_rank,                       'Enable', 'Off');
                set(handles.edit_filter_rec_clock_Q,             'Enable', 'Off');
                set(handles.edit_filter_rec_clock_sigma0,        'Enable', 'Off');
                set(handles.text_gps_time_offset,                'Enable', 'Off');
                set(handles.text_gps_time_offset_m,              'Enable', 'Off');
                set(handles.popupmenu_filter_rec_clock_dynmodel, 'Enable', 'Off');

            end
        end

        % Value changed function: checkbox_bar_conv
        function checkbox_bar_conv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if handles.checkbox_histo_conv.Value || handles.checkbox_bar_conv.Value
                handles.edit_conv_min.Enable = 'on';
                handles.text_conv_min.Enable = 'on';
            else
                handles.edit_conv_min.Enable = 'off';
                handles.text_conv_min.Enable = 'off';
            end
        end

        % Value changed function: checkbox_batch_manipulate_identical
        function checkbox_batch_manipulate_identical_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles.checkbox_manipulate_all.Value = 0;
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_batch_proc
        function checkbox_batch_proc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_enable_onoff(handles);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_check_omc
        function checkbox_check_omc_Callback(app, event)
            % Check observed minus computed

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_enable_onoff(handles);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_estimate_rec_dcbs
        function checkbox_estimate_rec_dcbs_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value = 'Off';
            if get(handles.checkbox_estimate_rec_dcbs, 'Value')
                value = 'On';
            end
            set(handles.text_rec_dcbs, 'Enable', value);
            set(handles.edit_filter_dcbs_sigma0, 'Enable', value);
            set(handles.edit_filter_dcbs_Q, 'Enable', value);
            set(handles.text_dcbs_m, 'Enable', value);
            set(handles.popupmenu_filter_dcbs_dynmodel, 'Enable', value);
        end

        % Value changed function: checkbox_fixing
        function checkbox_fixing_Callback(app, event)
            % Ambiguity fixing

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_enable_onoff(handles);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_histo_conv
        function checkbox_histo_conv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if handles.checkbox_histo_conv.Value || handles.checkbox_bar_conv.Value
                handles.edit_conv_min.Enable = 'on';
                handles.text_conv_min.Enable = 'on';
            else
                handles.edit_conv_min.Enable = 'off';
                handles.text_conv_min.Enable = 'off';
            end
        end

        % Value changed function: checkbox_manipulate_all
        function checkbox_manipulate_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles.checkbox_batch_manipulate_identical.Value = 0;
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_multi_plot_each_row
        function checkbox_multi_plot_each_row_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            TABLE = handles.uitable_multi_plot.Data;  	% Data of table [cell]
            TABLE = TABLE(~cellfun(@isempty,TABLE(:,1)),:);     % remove empty rows
            labels = TABLE(:,7);    % get column of label
            labels_new = labels;
            if all(cellfun(@isempty,labels))
                return
            end
            if handles.checkbox_multi_plot_each_row.Value       % add number of row
                add = sprintfc('%02.0f', 1:numel(labels));      % create number of row
                add = add';
                labels_new = strcat(add, '|', labels);          % add number of row
            else
                check = labels{1};      % check if number of row exists and remove
                if strcmp(check(3),'|') && ~isnan(str2double(check(1:2)))
                    labels_new = cellfun(@(x) x(4:end), labels, 'un', 0);   % remove number of row
                end
            end
            TABLE(:,7) = labels_new;    % save manipulated labels
            handles.uitable_multi_plot.Data = TABLE;
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_multi_plot_each_station
        function checkbox_multi_plot_each_station_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            TABLE = handles.uitable_multi_plot.Data;  	% Data of table [cell]
            TABLE = TABLE(~cellfun(@isempty,TABLE(:,1)),:);     % remove empty rows
            labels = TABLE(:,7);    % get column of label
            labels_new = labels;
            if all(cellfun(@isempty,labels))
                return
            end
            if handles.checkbox_multi_plot_each_station.Value   % add station to label
                add = TABLE(:,2);      % get stations to add
                labels_new = strcat(labels, '|', add);          % add station to label
            else                        % remove station from label
                check = labels{1};              % check label of first row
                station_1st_row = TABLE{1,2};   % station of first row
                if strcmp(check(end-4:end), ['|' station_1st_row])
                    labels_new = cellfun(@(x) x(1:end-5), labels, 'un', 0);   % remove station from label
                end
            end
            TABLE(:,7) = labels_new;    % save manipulated labels
            handles.uitable_multi_plot.Data = TABLE;    % write manipulated table to GUI
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_obx
        function checkbox_obx_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_enable_onoff(handles);
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_rec_pco
        function checkbox_rec_pco_Callback(app, event)
            % Receiver Phase Center Offset

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if ~handles.checkbox_sat_pco.Value && ~handles.checkbox_sat_pcv.Value && ...
                    ~handles.checkbox_rec_pco.Value && ~handles.checkbox_rec_pcv.Value
                handles.uibuttongroup_antex.Visible = 'off';
            else
                handles.uibuttongroup_antex.Visible = 'on';
            end
        end

        % Value changed function: checkbox_reset_fixed
        function checkbox_reset_fixed_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            onoff = 'Off';
            if handles.checkbox_reset_float.Value || handles.checkbox_reset_fixed.Value
                onoff = 'On';
            end
            handles.radiobutton_reset_epoch.Enable = onoff;
            handles.radiobutton_reset_min.Enable = onoff;
            handles.text_reset_epoch.Enable = onoff;
            handles.edit_reset_epoch.Enable = onoff;
        end

        % Value changed function: checkbox_reset_float
        function checkbox_reset_float_Callback(app, event)
            % Reset

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles.checkbox_reset_fixed.Value = 0;
            handles.checkbox_reset_fixed.Enable = 'Off';
            if handles.checkbox_reset_float.Value
                handles.checkbox_reset_fixed.Value = 1;
                handles.checkbox_reset_fixed.Enable = 'On';
            end
            onoff = 'Off';
            if handles.checkbox_reset_float.Value || handles.checkbox_reset_fixed.Value
                onoff = 'On';
            end
            handles.radiobutton_reset_epoch.Enable = onoff;
            handles.radiobutton_reset_min.Enable = onoff;
            handles.text_reset_epoch.Enable = onoff;
            handles.edit_reset_epoch.Enable = onoff;
        end

        % Value changed function: checkbox_sat_pco
        function checkbox_sat_pco_Callback(app, event)
            % Satellite Phase Center Offset

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if ~handles.checkbox_sat_pco.Value && ~handles.checkbox_sat_pcv.Value && ...
                    ~handles.checkbox_rec_pco.Value && ~handles.checkbox_rec_pcv.Value
                handles.uibuttongroup_antex.Visible = 'off';
            else
                handles.uibuttongroup_antex.Visible = 'on';
            end
        end

        % Value changed function: edit_bias
        function edit_bias_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            bias_string = handles.edit_bias.String;
            if isempty(bias_string)
                % textfield is empty
                handles.paths.bias_1 = '';
                handles.paths.bias_2 = '';
            else
                % save new file name
                handles.paths.bias_2 = bias_string;
                if contains(bias_string, '$')
                    % auto-detection, reset folder path
                    handles.paths.bias_1 = '';
                else
                    % check if file exists
                    if ~exist([handles.paths.bias_1, handles.paths.bias_2], 'file')     % entered path does not exist
                        errordlg('Invalid Filename! Change Filename.', 'File Error');
                    end
                end
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_clock
        function edit_clock_Callback(app, event)
            % Precise Clocks

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if isempty(handles.edit_clock.String)
                % textfield is empty
                handles.paths.clk_1 = '';
                handles.paths.clk_2 = '';
            else
                % save new file name
                handles.paths.clk_2 = handles.edit_clock.String;
                if contains(handles.edit_clock.String, '$')
                    % auto-detection, reset folder path
                    handles.paths.clk_1 = '';
                else
                    % check if file exists
                    if ~exist([handles.paths.clk_1, handles.paths.clk_2], 'file')     % entered path does not exist
                        errordlg('Invalid Filename! Change Filename.', 'File Error');
                    end
                end
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_corr2brdc
        function edit_corr2brdc_Callback(app, event)
            % Correction-Stream

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            string_corr2brdc = get(hObject, 'String');

            if isempty(string_corr2brdc)
                % textfield is empty
                handles.paths.corr2brdc_1 = '';
                handles.paths.corr2brdc_2 = '';
            else
                % save new file name
                handles.paths.corr2brdc_2 = string_corr2brdc;
                if contains(string_corr2brdc, '$')
                    % auto-detection, reset folder path
                    handles.paths.corr2brdc_1 = '';
                else
                    % check if file exists
                    if ~exist([handles.paths.corr2brdc_1, handles.paths.corr2brdc_2], 'file')     % entered path does not exist
                        errordlg('Invalid Filename! Change Filename.', 'File Error');
                    end
                end
            end

            guidata(hObject, handles);
        end

        % Value changed function: edit_dcb_P1C1
        function edit_dcb_P1C1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            dcbP1C1_string = handles.edit_dcb_P1C1.String;
            if isempty(dcbP1C1_string)
                % textfield is empty
                handles.paths.dcbP1C1_1 = '';
                handles.paths.dcbP1C1_2 = '';
            else
                % save new file name
                handles.paths.dcbP1C1_2 = dcbP1C1_string;
                if contains(dcbP1C1_string, '$')
                    % auto-detection, reset folder path
                    handles.paths.dcbP1C1_1 = '';
                else
                    % check if file exists
                    if ~exist([handles.paths.dcbP1C1_1, handles.paths.dcbP1C1_2], 'file')     % entered path does not exist
                        errordlg('Invalid Filename! Change Filename.', 'File Error');
                    end
                end
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_dcb_P1P2
        function edit_dcb_P1P2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            dcbP1P2_string = handles.edit_dcb_P1P2.String;
            if isempty(dcbP1P2_string)
                % textfield is empty
                handles.paths.dcbP1P2_1 = '';
                handles.paths.dcbP1P2_2 = '';
            else
                % save new file name
                handles.paths.dcbP1P2_2 = dcbP1P2_string;
                if contains(dcbP1P2_string, '$')
                    % auto-detection, reset folder path
                    handles.paths.dcbP1P2_1 = '';
                else
                    % check if file exists
                    if ~exist([handles.paths.dcbP1P2_1, handles.paths.dcbP1P2_2], 'file')     % entered path does not exist
                        errordlg('Invalid Filename! Change Filename.', 'File Error');
                    end
                end
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_dcb_P2C2
        function edit_dcb_P2C2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            dcbP2C2_string = handles.edit_dcb_P2C2.String;
            if isempty(dcbP2C2_string)
                % textfield is empty
                handles.paths.dcbP2C2_1 = '';
                handles.paths.dcbP2C2_2 = '';
            else
                % save new file name
                handles.paths.dcbP2C2_2 = dcbP2C2_string;
                if contains(dcbP2C2_string, '$')
                    % auto-detection, reset folder path
                    handles.paths.dcbP2C2_1 = '';
                else
                    % check if file exists
                    if ~exist([handles.paths.dcbP2C2_1, handles.paths.dcbP2C2_2], 'file')     % entered path does not exist
                        errordlg('Invalid Filename! Change Filename.', 'File Error');
                    end
                end
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_druck
        function edit_druck_Callback(app, event)
            % Pressure

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value_druck = sscanf(get(hObject, 'String'), '%f');
            if isnan(value_druck) == 1 || value_druck <= 0
                errordlg('Invalid value.', 'Error');
                set(hObject, 'String', '1013.25');
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_feuchte
        function edit_feuchte_Callback(app, event)
            % Humidity

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value_feuchte = sscanf(get(hObject, 'String'), '%f');
            if isnan(value_feuchte) == 1 || value_feuchte <= 0 || value_feuchte >= 100
                errordlg('Invalid value.', 'Error');
                set(hObject, 'String', '48.14');
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_ionex
        function edit_ionex_Callback(app, event)
            % Ionex-file

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value_ionex = get(hObject, 'String');

            if exist([handles.paths.ionex_1 value_ionex], 'file')
                handles.paths.ionex_2 = value_ionex;

            elseif ~exist(value_ionex, 'file')            % entered path does not exist
                errordlg('Invalid Filename! Change Filename.', 'File Error');

            else
                handles.paths.ionex_1 = [];
                handles.paths.ionex_2 = value_ionex;
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_nav_BDS
        function edit_nav_BDS_Callback(app, event)
            % Navigation-File BEIDOU (.c)?

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value_nav = get(hObject, 'String');

            if exist([handles.paths.navBDS_1 value_nav], 'file')
                handles.paths.navBDS_2 = value_nav;

            elseif ~exist(value_nav, 'file')            % entered path does not exist
                handles.paths.navBDS_1 = [];                  % clear path
                handles.paths.navBDS_2 = [];
                if ~isempty(value_nav)
                    errordlg('Invalid Filename! Change Filename.', 'File Error');
                end
                set(handles.edit_nav_BDS,   'String', '');      % clear field

            else
                handles.paths.navBDS_1 = [];
                handles.paths.navBDS_2 = value_nav;
                set(handles.edit_nav_multi,'String','');
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_nav_GAL
        function edit_nav_GAL_Callback(app, event)
            % Navigation-File GALILEO (.l)

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value_nav = get(hObject, 'String');

            if exist([handles.paths.navGAL_1 value_nav], 'file')
                handles.paths.navGAL_2 = value_nav;

            elseif ~exist(value_nav, 'file')            % entered path does not exist
                handles.paths.navGAL_1 = [];                  % clear path
                handles.paths.navGAL_2 = [];
                if ~isempty(value_nav)
                    errordlg('Invalid Filename! Change Filename.', 'File Error');
                end
                set(handles.edit_nav_GAL,   'String', '');      % clear field

            else
                handles.paths.navGAL_1 = [];
                handles.paths.navGAL_2 = value_nav;
                set(handles.edit_nav_multi,'String','');
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_nav_GLO
        function edit_nav_GLO_Callback(app, event)
            % Navigation-File GLONASS (.g)

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value_nav = get(hObject, 'String');

            if exist([handles.paths.navGAL_1 value_nav], 'file')
                handles.paths.navGAL_2 = value_nav;

            elseif ~exist(value_nav, 'file')            % entered path does not exist
                handles.paths.navGLO_1 = [];                  % clear path
                handles.paths.navGLO_2 = [];
                if ~isempty(value_nav)
                    errordlg('Invalid Filename! Change Filename.', 'File Error');
                end
                set(handles.edit_nav_GLO,   'String', '');      % clear field

            else
                handles.paths.navGLO_1 = [];
                handles.paths.navGLO_2 = value_nav;
                set(handles.edit_nav_multi,   'String', '');
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_nav_GPS
        function edit_nav_GPS_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value_nav = get(hObject, 'String');

            if exist([handles.paths.navGPS_1 value_nav], 'file')
                handles.paths.navGPS_2 = value_nav;

            elseif ~exist(value_nav, 'file')            % file of entered path does not exist
                handles.paths.navGPS_1 = [];                  % clear path
                handles.paths.navGPS_2 = [];
                if ~isempty(value_nav)
                    errordlg('Invalid Filename! Change Filename.', 'File Error');
                end
                set(handles.edit_nav_GPS,   'String', '');  % clear field
            else
                handles.paths.navGPS_1 = [];
                handles.paths.navGPS_2 = value_nav;
                set(handles.edit_nav_multi,   'String', '');
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_nav_multi
        function edit_nav_multi_Callback(app, event)
            % Navigation-File Multi

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value_nav = get(hObject, 'String');

            if exist([handles.paths.navMULTI_1 value_nav], 'file')
                handles.paths.navMULTI_2 = value_nav;

            elseif ~exist(value_nav, 'file')            % entered path does not exist
                handles.paths.navMULTI_1 = [];                % clear struct path
                handles.paths.navMULTI_2 = [];
                if ~isempty(value_nav)
                    errordlg('Invalid Filename! Change Filename.', 'File Error');
                end
                set(handles.edit_nav_multi,   'String', '');        % clear field
            else
                handles.paths.navMULTI_1 = [];
                handles.paths.navMULTI_2 = value_nav;
                set(handles.edit_nav_GPS,   'String', '');
                set(handles.edit_nav_GLO,   'String', '');
                set(handles.edit_nav_GAL,   'String', '');
                set(handles.edit_nav_BDS,   'String', '');
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_obs
        function edit_obs_Callback(app, event)
            % Observation-File (.o) Text-Window

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value_obs = get(hObject, 'String');

            if exist([handles.paths.obs_1 value_obs], 'file')
                handles.paths.obs_2 = value_obs;

                % enable download button & GPS/Galileo checkboxes
                set(handles.pushbutton_download,'Enable','On');
                set(handles.checkbox_GPS,       'Enable','On');
                set(handles.checkbox_GLO,       'Enable','On');
                set(handles.checkbox_GAL,       'Enable','On');
                set(handles.checkbox_BDS,       'Enable','On');

            elseif ~exist(value_obs, 'file')            % entered path does not exist
                errordlg('Invalid observation file name!', 'File Error');

                % disable download button & GPS/Galileo checkboxes
                set(handles.pushbutton_download,'Enable','Off');
                set(handles.checkbox_GPS,       'Enable','Off');
                set(handles.checkbox_GLO,       'Enable','Off');
                set(handles.checkbox_GAL,       'Enable','Off');
                set(handles.checkbox_BDS,       'Enable','Off');

            else
                handles.paths.obs_1 = [];
                handles.paths.obs_2 = value_obs;

                % enable download button & GPS/Galileo checkboxes
                set(handles.pushbutton_download,'Enable','On');
                set(handles.checkbox_GPS,       'Enable','On');
                set(handles.checkbox_GLO,       'Enable','On');
                set(handles.checkbox_GAL,       'Enable','On');
                set(handles.checkbox_BDS,       'Enable','On');
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_obx
        function edit_obx_Callback(app, event)
            % ORBEX File

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if isempty(handles.edit_obx.String)
                % textfield is empty
                handles.paths.obx_1 = '';
                handles.paths.obx_2 = '';
            else
                % save new file name
                handles.paths.obx_2 = handles.edit_obx.String;
                if contains(handles.edit_obx.String, '$')
                    % auto-detection, reset folder path
                    handles.paths.obx_1 = '';
                else
                    % check if file exists
                    if ~exist([handles.paths.obx_1, handles.paths.obx_2], 'file')     % entered path does not exist
                        errordlg('Invalid Filename! Change Filename.', 'File Error');
                        handles.edit_obx.String = '';
                        handles.paths.obx_1 = '';
                        handles.paths.obx_2 = '';
                    end
                end
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_output
        function edit_output_Callback(app, event)
            % Output Directory

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            guidata(hObject, handles);
        end

        % Value changed function: edit_plot_path
        function edit_plot_path_Callback(app, event)
            % Textfield of path to data4plot.mat

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % clear text-field and path to data4plot.mat in handles
            set(handles.edit_plot_path,'String', '');
            handles.paths.plotfile = '';

            % enable/disable "Load from IGS" pushbutton
            value_obs = get(hObject, 'String');
            if ~exist(value_obs, 'file')
                set(handles.pushbutton_load_pos_true, 'Enable','Off');
                handles.edit_x_true.String = '';
                handles.edit_y_true.String = '';
                handles.edit_z_true.String = '';
            else
                set(handles.pushbutton_load_pos_true, 'Enable','On');
            end

            % uncheck all plot checkboxes
            un_check_plot_checkboxes(handles, 0);
            % ||| dis/enable all checkboxes?
            guidata(hObject, handles);
        end

        % Value changed function: edit_sp3
        function edit_sp3_Callback(app, event)
            % Precise Ephemerides

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if isempty(handles.edit_sp3.String)
                % textfield is empty
                handles.paths.sp3_1 = '';
                handles.paths.sp3_2 = '';
            else
                % save new file name
                handles.paths.sp3_2 = handles.edit_sp3.String;
                if contains(handles.edit_sp3.String, '$')
                    % auto-detection, reset folder path
                    handles.paths.sp3_1 = '';
                else
                    % check if file exists
                    if ~exist([handles.paths.sp3_1, handles.paths.sp3_2], 'file')     % entered path does not exist
                        errordlg('Invalid Filename! Change Filename.', 'File Error');
                    end
                end
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_temp
        function edit_temp_Callback(app, event)
            % Temperature

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value_temp = sscanf(get(hObject, 'String'), '%f');
            if isnan(value_temp) == 1
                errordlg('Invalid value.', 'Error');
                set(hObject, 'String', '0.00');
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_tropo_file
        function edit_tropo_file_Callback(app, event)
            % Tropo File selection

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value_tropo = get(hObject, 'String');
            if exist([handles.paths.tropo_1 value_tropo], 'file')
                handles.paths.tropo_2 = value_tropo;
            elseif ~exist(value_tropo, 'file')            % entered path does not exist
                errordlg('Invalid Filename! Change Filename.', 'File Error');
            else
                handles.paths.tropo_1 = [];
                handles.paths.tropo_2 = value_tropo;
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_x
        function edit_x_Callback(app, event)
            % Approximate position

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if isnan(sscanf(get(hObject, 'String'), '%f'))
                errordlg('Invalid coordinate.', 'Error');
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_y
        function edit_y_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if isnan(sscanf(get(hObject, 'String'), '%f'))
                errordlg('Invalid coordinate.', 'Error');
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit_z
        function edit_z_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if isnan(sscanf(get(hObject, 'String'), '%f'))
                errordlg('Invalid coordinate.', 'Error');
            end
            guidata(hObject, handles);
        end

        % Menu selected function: menu_estimation_adjustment
        function menu_estimation_adjustment_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_adjustment, 'Visible', 'On');
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_estimation_ambiguityFixing
        function menu_estimation_ambiguityFixing_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_ambiguityFixing, 'Visible', 'On');
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_file_batch_proc
        function menu_file_batch_proc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)   % first set all panels to invisible...
            set(handles.uipanel_batch_proc, 'Visible', 'On');   %...then set the selected panel to visible
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_file_exit
        function menu_file_exit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            choice = questdlg('Do you want to close raPPPid?', ...
                'Are you sure?', 'Yes', 'No', 'No');
            if strcmp(choice, 'Yes')
                close(handles.figure1);
            end
        end

        % Menu selected function: 
        % menu_file_parametersFiles_loadDefaultParameters
        function menu_file_parametersFiles_loadDefaultParameters_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            load('PARAMETERS/default.mat', 'parameters');
            [handles] = setSettingsToGUI(parameters, handles, false);
            guidata(hObject, handles)

            msgbox('Default parameters have been successfully loaded', 'Done', 'help');
        end

        % Menu selected function: menu_file_parametersFiles_loadParameters
        function menu_file_parametersFiles_loadParameters_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            [FileName, PathName] = uigetfile('*.mat', 'Select a parameters file (*.mat)', 'PARAMETERS/');
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if FileName
                load([PathName, FileName], 'parameters');
                if exist('parameters','var')
                    [handles] = setSettingsToGUI(parameters, handles, false);
                    % write message box for information for user
                    msgbox('Parameters file successfully loaded.', 'Load parameter file', 'help')
                else
                    load([PathName, FileName], 'settings');
                    [handles] = setSettingsToGUI(settings, handles, false);
                    % write message box for information for user
                    msgbox('Parameters from settings file successfully loaded.', 'Load parameter file', 'help')
                end

                handles = GUI_enable_onoff(handles);    % update the visible items


            end
            guidata(hObject, handles)
        end

        % Menu selected function: menu_file_parametersFiles_saveParameters
        function menu_file_parametersFiles_saveParameters_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            [filename, PathName] = uiputfile('*.mat', 'Save GUI Settings', ['PARAMETERS/', '']);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if filename
                settings = getSettingsFromGUI(handles);   % get input from GUI and put it into structure "settings"

                % change the settings to parameters
                parameters = settings2parameters(settings);

                save([PathName, filename(1:end-4), '.mat'], 'parameters')    % save variable settings into file

                % write message box
                msgbox('Parameters file successfully saved.', 'Save parameter file', 'help');
            end
            guidata(hObject, handles);
        end

        % Menu selected function: menu_file_setInputFile
        function menu_file_setInputFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)        % first set all panels to invisible...
            set(handles.uipanel_setInputFile, 'Visible', 'On');   %...then set the selected panel to visible
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_file_settingsFiles_loadSettings
        function menu_file_settingsFiles_loadSettings_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            [FileName, PathName] = uigetfile('*.mat', 'Select a settings file (*.mat) to load', Path.RESULTS);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if FileName
                load([PathName, FileName], 'settings');
                [handles] = setSettingsToGUI(settings, handles, true);
                handles = GUI_enable_onoff(handles);

                % write message box for information for user
                msgbox('Settings file successfully loaded.', 'Load settings file', 'help')
            end
            guidata(hObject, handles);
        end

        % Menu selected function: menu_file_settingsFiles_saveSettings
        function menu_file_settingsFiles_saveSettings_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            guidata(hObject, handles);
            [filename, PathName] = uiputfile('*.mat', 'Save GUI Settings', [Path.RESULTS, 'settings_']);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if filename
                settings = getSettingsFromGUI(handles);   % get input from GUI and put it into structure "settings"

                save([PathName, filename(1:end-4), '.mat'], 'settings')    % save variable settings into file

                % write message box
                msgbox('Settings file successfully saved.', 'Save settings file', 'help');
            end
        end

        % Menu selected function: menu_models_biases
        function menu_models_biases_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_biases, 'Visible', 'On');
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_models_ionosphere
        function menu_models_ionosphere_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_ionosphere, 'Visible', 'On');
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_models_orbitClockData
        function menu_models_orbitClockData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_orbitClockData, 'Visible', 'On');
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_models_otherCorrections
        function menu_models_otherCorrections_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_otherCorrections, 'Visible', 'On');
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_models_troposphere
        function menu_models_troposphere_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_troposphere, 'Visible', 'On');
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_multi_plot
        function menu_multi_plot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)   % first set all panels to invisible...
            set(handles.uipanel_multi_plot, 'Visible', 'On');   %...then set the selected panel to visible
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_run_Export
        function menu_run_export_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_export, 'Visible', 'On');
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_run_processingOptions
        function menu_run_processingOptions_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_processingOptions, 'Visible', 'On');
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Menu selected function: menu_single_plot
        function menu_single_plot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            setAllPanelsToInvisible(app, handles)
            set(handles.uipanel_single_plot, 'Visible', 'On');
            handles = GUI_enable_onoff(handles);    % update the visible items
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_CorrectionStream
        function popupmenu_CorrectionStream_Callback(app, event)
            % Popup/Dropdownmenu Correction Stream

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value = get(hObject, 'Value');
            string_all = get(hObject,'String');

            if strcmpi(string_all{value},'manually')
                set(handles.edit_corr2brdc,'Visible','on');
                set(handles.pushbutton_corr2brdc,'Visible','on');
                set(handles.text_corr2brdc_age_1,'Visible','on');
                set(handles.text_corr2brdc_age_2,'Visible','on');
                set(handles.edit_corr2brdc_age,'Visible','on');
            else
                set(handles.edit_corr2brdc,'Visible','off');
                set(handles.pushbutton_corr2brdc,'Visible','off');
                set(handles.text_corr2brdc_age_1,'Visible','off');
                set(handles.text_corr2brdc_age_2,'Visible','off');
                set(handles.edit_corr2brdc_age,'Visible','off');
            end

            if strcmpi(string_all{value},'CNES Archive')
                set(handles.radiobutton_models_biases_code_CorrectionStream,'Value',1)
                set(handles.radiobutton_models_biases_phase_CorrectionStream,'Value',1)
            end

            % if the correction stream is turned off, then disable the respective
            % buttons in Biases and Ionosphere and set the biases to OFF
            if strcmpi(string_all{value},'off')
                % set(handles.radiobutton_models_biases_code_CorrectionStream, 'Enable', 'Off');
                % set(handles.radiobutton_models_biases_phase_CorrectionStream,'Enable', 'Off');
                set(handles.radiobutton_models_biases_code_off, 'Value', 1);
                set(handles.radiobutton_models_biases_phase_off, 'Value', 1);
            else
                % set(handles.radiobutton_models_biases_code_CorrectionStream, 'Enable', 'On');
                % set(handles.radiobutton_models_biases_phase_CorrectionStream,'Enable', 'On');
            end
        end

        % Value changed function: popupmenu_bds_1
        function popupmenu_bds_1_Callback(app, event)
            % Processed frequencies - BeiDou

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_BDS) + 1;
            [handles.popupmenu_bds_1, handles.popupmenu_bds_2, handles.popupmenu_bds_3] = ...
                manipulate_proc_freq(app, handles.popupmenu_bds_1, handles.popupmenu_bds_2, handles.popupmenu_bds_3, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_bds_2
        function popupmenu_bds_2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_BDS) + 1;
            [handles.popupmenu_bds_2, handles.popupmenu_bds_1, handles.popupmenu_bds_3] = ...
                manipulate_proc_freq(app, handles.popupmenu_bds_2, handles.popupmenu_bds_1, handles.popupmenu_bds_3, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_bds_3
        function popupmenu_bds_3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_BDS) + 1;
            [handles.popupmenu_bds_3, handles.popupmenu_bds_1, handles.popupmenu_bds_2] = ...
                manipulate_proc_freq(app, handles.popupmenu_bds_3, handles.popupmenu_bds_1, handles.popupmenu_bds_2, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_filter
        function popupmenu_filter_Callback(app, event)
            % Filter popupmenu (No Filter / Kalman Filter Iterative / Kalman Filter)

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_enable_onoff(handles);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_gal_1
        function popupmenu_gal_1_Callback(app, event)
            % Processed frequencies - Galileo

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_GAL) + 1;
            [handles.popupmenu_gal_1, handles.popupmenu_gal_2, handles.popupmenu_gal_3] = ...
                manipulate_proc_freq(app, handles.popupmenu_gal_1, handles.popupmenu_gal_2, handles.popupmenu_gal_3, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_gal_2
        function popupmenu_gal_2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_GAL) + 1;
            [handles.popupmenu_gal_2, handles.popupmenu_gal_1, handles.popupmenu_gal_3] = ...
                manipulate_proc_freq(app, handles.popupmenu_gal_2, handles.popupmenu_gal_1, handles.popupmenu_gal_3, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_gal_3
        function popupmenu_gal_3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_GAL) + 1;
            [handles.popupmenu_gal_3, handles.popupmenu_gal_1, handles.popupmenu_gal_2] = ...
                manipulate_proc_freq(app, handles.popupmenu_gal_3, handles.popupmenu_gal_1, handles.popupmenu_gal_2, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_glo_1
        function popupmenu_glo_1_Callback(app, event)
            % Processed frequencies - Glonass

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_GLO) + 1;
            [handles.popupmenu_glo_1, handles.popupmenu_glo_2, handles.popupmenu_glo_3] = ...
                manipulate_proc_freq(app, handles.popupmenu_glo_1, handles.popupmenu_glo_2, handles.popupmenu_glo_3, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_glo_2
        function popupmenu_glo_2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_GLO) + 1;
            [handles.popupmenu_glo_2, handles.popupmenu_glo_1, handles.popupmenu_glo_3] = ...
                manipulate_proc_freq(app, handles.popupmenu_glo_2, handles.popupmenu_glo_1, handles.popupmenu_glo_3, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_glo_3
        function popupmenu_glo_3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_GLO) + 1;
            [handles.popupmenu_glo_3, handles.popupmenu_glo_1, handles.popupmenu_glo_2] = ...
                manipulate_proc_freq(app, handles.popupmenu_glo_3, handles.popupmenu_glo_1, handles.popupmenu_glo_2, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_gps_1
        function popupmenu_gps_1_Callback(app, event)
            % Processed frequencies - GPS

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_GPS) + 1;
            [handles.popupmenu_gps_1, handles.popupmenu_gps_2, handles.popupmenu_gps_3] = ...
                manipulate_proc_freq(app, handles.popupmenu_gps_1, handles.popupmenu_gps_2, handles.popupmenu_gps_3, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_gps_2
        function popupmenu_gps_2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_GPS) + 1;
            [handles.popupmenu_gps_2, handles.popupmenu_gps_1, handles.popupmenu_gps_3] = ...
                manipulate_proc_freq(app, handles.popupmenu_gps_2, handles.popupmenu_gps_1, handles.popupmenu_gps_3, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_gps_3
        function popupmenu_gps_3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            off_value = max(DEF.freq_GPS) + 1;
            [handles.popupmenu_gps_3, handles.popupmenu_gps_1, handles.popupmenu_gps_2] = ...
                manipulate_proc_freq(app, handles.popupmenu_gps_3, handles.popupmenu_gps_1, handles.popupmenu_gps_2, off_value);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_nav_multi
        function popupmenu_multi_nav_Callback(app, event)
            % Popup/Dropdownmenu Multi-GNSS Navigation File

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value = get(hObject, 'Value');
            string_all = get(hObject,'String');
            if strcmpi(string_all{value},'manually')
                set(handles.edit_nav_multi,'Visible','on');
                set(handles.pushbutton_nav_multi,'Visible','on');
            else
                set(handles.edit_nav_multi,'Visible','off');
                set(handles.pushbutton_nav_multi,'Visible','off');
            end
        end

        % Value changed function: popupmenu_prec_prod
        function popupmenu_prec_prod_Callback(app, event)
            % Popup/Dropdownmenu precise products

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_enable_onoff(handles);
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_process
        function popupmenu_process_Callback(app, event)
            % Processing Method

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_enable_onoff(handles);
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_add_files
        function pushbutton_add_files_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if ~InWorkFolder();     return;     end         % check if in WORK folder
            TABLE = handles.uitable_batch_proc.Data;        % Data of table [cell]
            [rows, ~] = size(TABLE);                     % number of rows and columns
            % find index of next empty row
            row_idx = rows + 1;
            for i = 1:rows
                if isempty(TABLE{i,1})
                    row_idx = i;        % index of first empty row
                    break
                end
            end
            startfolder = [Path.DATA, '/OBS'];
            while true      % loop to add multiple files
                [files, fpath] = uigetfile({'*.*o;*.rnx;*.obs'}, 'Select (multiple) RINEX-Files for Batch-Processing', startfolder, 'MultiSelect', 'on');
                fpath = relativepath(fpath);   % convert absolute path to relative path
                if isempty(files) || isnumeric(files)
                    return       % no files selected, stopp adding files in table
                end
                startfolder = fpath;
                files = cellstr(files);     % necessary if only one file was selected
                % creating waitbar
                WBAR = waitbar(0, 'Preparing file-loading...', 'Name','Progress of writing Batch-Processing table');
                % loop over selected files to fill uitable_batch_proc
                no_files = numel(files);
                for i = 1:no_files

                    rheader = anheader_GUI([fpath,files{i}]);
                    gps_ranking = rheader.gps_ranking;
                    glo_ranking = rheader.glo_ranking;
                    gal_ranking = rheader.gal_ranking;
                    bds_ranking = rheader.bds_ranking;

                    % check for GNSS and their frequencies
                    % GPS
                    bool_gps = true;
                    if isempty(rheader.ind_gps_freq)    % no gps observations
                        bool_gps = false;       % numerical value (one or zero) does not work!
                        gps_ranking = '';
                    end
                    gps_freq = DEF.freq_GPS_names(rheader.ind_gps_freq);
                    gps_freq_str = sprintf('%s;', gps_freq{:});
                    % Glonass
                    bool_glo = true;
                    if isempty(rheader.ind_glo_freq)    % no glonass observations
                        bool_glo = false;       % numerical value (one or zero) does not work!
                        glo_ranking = '';
                    end
                    glo_freq = DEF.freq_GLO_names(rheader.ind_glo_freq);
                    glo_freq_str = sprintf('%s;', glo_freq{:});
                    % Galileo
                    bool_gal = true;
                    if isempty(rheader.ind_gal_freq)    % no galileo observations
                        bool_gal = false;
                        gal_ranking = '';
                    end
                    gal_freq = DEF.freq_GAL_names(rheader.ind_gal_freq);
                    gal_freq_str = sprintf('%s;', gal_freq{:});
                    % BeiDou
                    bool_bds = true;
                    if isempty(rheader.ind_bds_freq)    % no galileo observations
                        bool_bds = false;
                        bds_ranking = '';
                    end
                    bds_freq = DEF.freq_BDS_names(rheader.ind_bds_freq);
                    bds_freq_str = sprintf('%s;', bds_freq{:});

                    % check for observation ranking
                    if floor(rheader.version_full) == 2
                        gps_ranking = '';
                        glo_ranking = '';
                        gal_ranking = '';
                        bds_ranking = '';
                    end

                    % write everything into table
                    TABLE{row_idx, 1} = fpath;
                    TABLE{row_idx, 2} = files{i};
                    TABLE{row_idx, 3} = rheader.pos_approx(1);
                    TABLE{row_idx, 4} = rheader.pos_approx(2);
                    TABLE{row_idx, 5} = rheader.pos_approx(3);
                    TABLE{row_idx, 6} = bool_gps;
                    TABLE{row_idx, 7} = gps_freq_str;
                    TABLE{row_idx, 8} = gps_ranking;
                    TABLE{row_idx, 9} = bool_glo;
                    TABLE{row_idx,10} = glo_freq_str;
                    TABLE{row_idx,11} = glo_ranking;
                    TABLE{row_idx,12} = bool_gal;
                    TABLE{row_idx,13} = gal_freq_str;
                    TABLE{row_idx,14} = gal_ranking;
                    TABLE{row_idx,15} = bool_bds;
                    TABLE{row_idx,16} = bds_freq_str;
                    TABLE{row_idx,17} = bds_ranking;
                    TABLE{row_idx,18} = 1;
                    TABLE{row_idx,19} = 999999;

                    row_idx = row_idx + 1;

                    % update waitbar
                    if ishandle(WBAR)
                        progress = i/no_files;      % 1/100 [%]
                        mess = sprintf('%02.2f%s', progress*100, '% of the Batch-Processing table are finished.');
                        waitbar(progress, WBAR, mess)
                    end

                end
                % close waitbar
                if ishandle(WBAR);        close(WBAR);    end
                % save manipulated table
                handles.uitable_batch_proc.Data = TABLE;
                guidata(hObject, handles);
            end
        end

        % Button pushed function: pushbutton_add_folder
        function pushbutton_add_folder_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if ~InWorkFolder();     return;     end         % check if in WORK folder
            TABLE = handles.uitable_batch_proc.Data;        % Data of table [cell]
            [rows, ~] = size(TABLE);                     % number of rows and columns
            % find index of next empty row
            row_idx = rows + 1;
            for i = 1:rows
                if isempty(TABLE{i,1})
                    row_idx = i;        % index of first empty row
                    break
                end
            end

            startfolder = [Path.DATA, '/OBS'];
            while true      % loop to add multiple files
                PathName = uigetdir(startfolder, 'Select folder to process');
                if PathName == 0
                    return       % no files selected, stopp adding files in table
                end
                [startfolder,~,~] = fileparts(PathName);    % to start next selection in the same folder
                PathName = relativepath(PathName);      	% convert absolute path to relative path

                % creating waitbar
                WBAR = waitbar(0, 'Loading Rinex-Files...', 'Name','Writing Batch-Processing table');

                % search all Rinex Files with extension *.rnx, *.*o, *.obs
                AllFiles = [dir([PathName '/**/*.rnx']); dir([PathName '/**/*.*o']); dir([PathName '/**/*.obs'])];
                n = length(AllFiles);

                % initialize some variables
                for i = 1:n         % loop over all detected Rinex-files
                    % read header of current Rinex file
                    path_rinex = [AllFiles(i).folder '/' AllFiles(i).name];
                    rheader = anheader_GUI(path_rinex);
                    gps_ranking = rheader.gps_ranking;
                    glo_ranking = rheader.glo_ranking;
                    gal_ranking = rheader.gal_ranking;
                    bds_ranking = rheader.bds_ranking;

                    % check for GNSS and their frequencies
                    % GPS
                    bool_gps = true;
                    if isempty(rheader.ind_gps_freq)    % no gps observations
                        bool_gps = false;       % numerical value (one or zero) does not work!
                        gps_ranking = '';
                    end
                    gps_freq = DEF.freq_GPS_names(rheader.ind_gps_freq);
                    gps_freq_str = sprintf('%s;', gps_freq{:});
                    % Glonass
                    bool_glo = true;
                    if isempty(rheader.ind_glo_freq)    % no glonass observations
                        bool_glo = false;       % numerical value (one or zero) does not work!
                        glo_ranking = '';
                    end
                    glo_freq = DEF.freq_GLO_names(rheader.ind_glo_freq);
                    glo_freq_str = sprintf('%s;', glo_freq{:});
                    % Galileo
                    bool_gal = true;
                    if isempty(rheader.ind_gal_freq)    % no galileo observations
                        bool_gal = false;
                        gal_ranking = '';
                    end
                    gal_freq = DEF.freq_GAL_names(rheader.ind_gal_freq);
                    gal_freq_str = sprintf('%s;', gal_freq{:});
                    % BeiDou
                    bool_bds = true;
                    if isempty(rheader.ind_bds_freq)    % no galileo observations
                        bool_bds = false;
                        bds_ranking = '';
                    end
                    bds_freq = DEF.freq_BDS_names(rheader.ind_bds_freq);
                    bds_freq_str = sprintf('%s;', bds_freq{:});

                    % check for observation ranking
                    if floor(rheader.version_full) == 2
                        gps_ranking = '';
                        glo_ranking = '';
                        gal_ranking = '';
                        bds_ranking = '';
                    end

                    % write everything into table
                    TABLE{row_idx, 1} = relativepath(AllFiles(i).folder);
                    TABLE{row_idx, 2} = AllFiles(i).name;
                    TABLE{row_idx, 3} = rheader.pos_approx(1);
                    TABLE{row_idx, 4} = rheader.pos_approx(2);
                    TABLE{row_idx, 5} = rheader.pos_approx(3);
                    TABLE{row_idx, 6} = bool_gps;
                    TABLE{row_idx, 7} = gps_freq_str;
                    TABLE{row_idx, 8} = gps_ranking;
                    TABLE{row_idx, 9} = bool_glo;
                    TABLE{row_idx,10} = glo_freq_str;
                    TABLE{row_idx,11} = glo_ranking;
                    TABLE{row_idx,12} = bool_gal;
                    TABLE{row_idx,13} = gal_freq_str;
                    TABLE{row_idx,14} = gal_ranking;
                    TABLE{row_idx,15} = bool_bds;
                    TABLE{row_idx,16} = bds_freq_str;
                    TABLE{row_idx,17} = bds_ranking;
                    TABLE{row_idx,18} = 1;
                    TABLE{row_idx,19} = 999999;

                    row_idx = row_idx + 1;
                    % update waitbar
                    if ishandle(WBAR)
                        progress = i/n;      % 1/100 [%]
                        mess = sprintf('%02.2f%s', progress*100, '% of the Batch Processing Table are finished.');
                        waitbar(progress, WBAR, mess)
                    end
                end
                % close waitbar
                if ishandle(WBAR);        close(WBAR);    end

                % save manipulated table before the next folder is selected
                handles.uitable_batch_proc.Data = TABLE;
                guidata(hObject, handles);
            end
        end

        % Button pushed function: pushbutton_analyze_rinex
        function pushbutton_analyze_rinex_Callback(app, event)
            % Analyze RINEX Pushbutton

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            settings = getSettingsFromGUI(handles);
            if ~isfile(settings.INPUT.file_obs); return; end
            AnalyzeRINEX(settings)
        end

        % Button pushed function: pushbutton_antex
        function pushbutton_antex_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = [Path.DATA '/ANTEX/'];
            [FileName, PathName] = uigetfile('*.atx*', 'Select the ANTEX-File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_antex, 'String', FileName);
            handles.paths.antex_1 = PathName;
            handles.paths.antex_2 = FileName;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_bias
        function pushbutton_bias_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/BIASES/'], handles.paths.bias_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile({'*.bia;*.bsx;*.mat'}, 'Select a Sinex BIAS File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_bias, 'String', FileName);
            handles.paths.bias_1 = PathName;
            handles.paths.bias_2 = FileName;
            if sscanf(get(handles.edit_bias, 'String'), '%f') == 0
                errordlg('Choose a File.', 'File Error');
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_check_all
        function pushbutton_check_all_Callback(app, event)
            % pushbutton to check all plot checkboxes

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = un_check_plot_checkboxes(handles, 1);
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_clock
        function pushbutton_clock_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/CLOCK/'], handles.paths.clk_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile('*.*clk*', 'Select the *.clk File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_clock, 'String', FileName);

            handles.paths.clk_1 = PathName;
            handles.paths.clk_2 = FileName;
            if sscanf(get(handles.edit_clock, 'String'), '%f') == 0
                errordlg('Choose a File.', 'File Error');
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_corr2brdc
        function pushbutton_corr2brdc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/STREAM/'], handles.paths.corr2brdc_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile({'*.*c;CLK*.mat'}, 'Select the Correction File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_corr2brdc, 'String', FileName);

            handles.paths.corr2brdc_1 = PathName;
            handles.paths.corr2brdc_2 = FileName;
            if sscanf(get(handles.edit_corr2brdc, 'String'), '%f') == 0
                errordlg('Choose a File.', 'File Error');
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_dcb_P1C1
        function pushbutton_dcb_P1C1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/BIASES/'], handles.paths.dcbP1C1_1, handles.paths.rinex_date(2:5));
            [FileName, PathName] = uigetfile({'P1C1*.dcb'}, 'Select the P1C1 DCB File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_dcb_P1C1, 'String',FileName);

            handles.paths.dcbP1C1_1 = PathName;
            handles.paths.dcbP1C1_2 = FileName;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_dcb_P1P2
        function pushbutton_dcb_P1P2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/BIASES/'], handles.paths.dcbP1P2_1, handles.paths.rinex_date(2:5));
            [FileName, PathName] = uigetfile('P1P2*.dcb', 'Select the DCB (P1P2) File for', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_dcb_P1P2, 'String', FileName);

            handles.paths.dcbP1P2_1 = PathName;
            handles.paths.dcbP1P2_2 = FileName;
            if sscanf(get(handles.edit_dcb_P1P2, 'String'), '%f') == 0
                errordlg('Choose a File.', 'File Error');
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_dcb_P2C2
        function pushbutton_dcb_P2C2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/BIASES/'], handles.paths.dcbP2C2_1, handles.paths.rinex_date(2:5));
            [FileName, PathName] = uigetfile({'P2C2*.dcb'}, 'Select the P2C2 DCB File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_dcb_P2C2, 'String',FileName);

            handles.paths.dcbP2C2_1 = PathName;
            handles.paths.dcbP2C2_2 = FileName;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_def_filter
        function pushbutton_def_filter_Callback(app, event)
            % Load default filter settings

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = LoadDefaultFilterSettings(handles);
            handles.radiobutton_Elevation_Dependency.Value = 1;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_delete
        function pushbutton_delete_Callback(app, event)
            % Delete last processing

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_delete(handles);
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton_delete_all_file
        function pushbutton_delete_all_files_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            [rows, cols] = size(handles.uitable_batch_proc.Data);   % size of table [cell]
            TABLE = cell(rows,cols);                    % create new empty cell
            handles.uitable_batch_proc.Data = TABLE;    % save cleared table
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_download
        function pushbutton_download_Callback(app, event)
            % Download

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if handles.checkbox_batch_proc.Value
                % download data for batch processing table
                % get data of batch processing table [cell]
                TABLE = GetTableData(handles.uitable_batch_proc.Data, 2, [6,9,12,15], 2, []);
                n = size(TABLE,1);        	% number of rows
                % get processing settings from GUI
                settings = getSettingsFromGUI(handles);
                % check if processing settings are valid in general or table is empty
                valid_settings = checkProcessingSettings(settings, true);
                if ~valid_settings; return; end
                if isempty(TABLE)
                    errordlg({'No Download possible:'; 'Batch-Processing table is empty.'}, 'Fail')
                    return
                end

                fprintf('Starting Download for Batch Processing Table....\n')
                WBAR = waitbar(0, 'Download Data, please wait...', 'Name', 'Downloading...');
                for i = 1:n
                    path_obs_file = [TABLE{i,1} TABLE{i,2}];
                    rheader = anheader_GUI(path_obs_file);
                    % download the input files which are currently selected in GUI
                    settings.INPUT.bool_parfor = true;      % to avoid unecessary output to command window
                    [~] = downloadInputFiles(settings, rheader.first_obs, false);
                    % Update Waitbar
                    if mod(i,3) == 0 && ishandle(WBAR)
                        progress = i/n;
                        mess = sprintf('%s%02.0f%s', 'Download progress: ', progress*100, '%' );
                        waitbar(progress, WBAR, mess)
                    end
                end
                % print end of download
                fprintf('Finished Download for Batch Processing Table....\n\n')
                if ishandle(WBAR);	close(WBAR);	end

            else            % download data for single file
                if contains(handles.paths.rinex_date, '0000/000')
                    errordlg('Please (re)load observation file to enable download.', 'Download not possible');
                    return
                end

                % create temporary settings file for the function downloadInputFiles.m
                settings_temp = getSettingsFromGUI(handles);

                % check if settings for processing are valid, but download anyway
                checkProcessingSettings(settings_temp, false);

                % get observation date
                rheader = anheader_GUI(settings_temp.INPUT.file_obs);

                % download the input files which are currently selected in GUI
                [~] = downloadInputFiles(settings_temp, rheader.first_obs, false);
            end
        end

        % Button pushed function: pushbutton_ionex
        function pushbutton_ionex_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/IONO/'], handles.paths.ionex_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile({'*.*i;*.inx'}, 'Select the Ionex File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName        % uigetfile cancelled
                return;
            end

            handles.paths.ionex_1 = PathName;
            handles.paths.ionex_2 = FileName;
            set(handles.edit_ionex, 'String', FileName);

            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_iono_folder
        function pushbutton_iono_folder_Callback(app, event)
            % Ionsphere Auto-Detection: Folder

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            PathName = uigetdir(Path.DATA, 'Select folder with Ionex Files for auto-detection.');
            Pathname = relativepath(PathName);
            if ~PathName        % uigetdir cancelled
                return;
            end
            set(handles.edit_iono_folder, 'String', PathName);
            handles.paths.iono_folder = PathName;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_load_filter_settings
        function pushbutton_load_filter_settings_Callback(app, event)
            % Load saved filter settings from a file

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if ~InWorkFolder();     return;     end         % check if in WORK folder
            [FileName, PathName] = uigetfile('*.mat', 'Select a filter settings file (*.mat)', [pwd '/PARAMETERS/']);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if FileName
                load([PathName, FileName], 'filtersetts');
                [handles] = setFilterSettingsToGUI(filtersetts, handles);
                handles = GUI_enable_onoff(handles);

                % write message box for information for user
                msgbox('Filter settings successfully loaded.', 'Load settings file', 'help')
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_load_plot_list
        function pushbutton_load_plot_list_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if handles.checkbox_multi_plot_each_row.Value || handles.checkbox_multi_plot_each_station.Value
                % plot each row or station is enabled
                choice = questdlg('Plot each row or station is enabled.', 'Attention!', ...
                    'Stop', 'Disable', 'Continue', 'Stop');
                if strcmp(choice, 'Stop');    return; end
                if strcmp(choice, 'Disable')
                    handles.checkbox_multi_plot_each_row.Value = 0;
                    handles.checkbox_multi_plot_each_station.Value = 0;
                end
            end

            [FileName, PathName] = uigetfile('*.mat','Select plot list(s)', './PLOTLIST/', 'multiselect', 'on');
            PathName = relativepath(PathName);

            fileChosen = 1;
            if isempty(FileName)
                fileChosen = 0;
            elseif ~iscell(FileName)
                if FileName == 0
                    fileChosen = 0;
                end
            end

            if fileChosen
                % get number of files
                if iscell(FileName)
                    num_files = size(FileName,2);
                else
                    num_files = 1;
                end

                % for all files
                for i_file = 1:num_files
                    % load plot list

                    % if we have a cell == if we have more than 1 plot selected
                    if iscell(FileName)
                        load([PathName, FileName{i_file}])
                    else
                        load([PathName, FileName])
                    end

                    % if we have now the plot_list variable
                    if exist('plot_list', 'var')
                        % get current listbox entries
                        curContent = handles.uitable_multi_plot.Data;

                        % delete all lines which do not contain data
                        ind = cellfun('isempty',curContent);
                        % consider the first column (path to data4plot name) to decide whether there is data or not
                        curContent(ind(:,1),:) = [];

                        % define the new table
                        newContent = [curContent; plot_list];

                        % update listbox
                        handles.uitable_multi_plot.Data = newContent;
                    end

                end

                % save all selected sessions to handles struct
                handles.allSelectedFiles = newContent;

                % save changes to handles struct
                guidata(hObject, handles);
            end
        end

        % Button pushed function: pushbutton_load_pos_true
        function pushbutton_load_pos_true_Callback(app, event)
            % --- Executes on button press in pushbutton_load_pos_true.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_load_pos_true (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % load static coordinates for single plotting
            if isempty(handles.edit_plot_path.String)
                return
            end

            if ~exist(handles.edit_plot_path.String, 'file')
                errordlg('data4plot.mat not found (try reloading).', 'Error')
                return
            end
            % corresponding settings.mat:
            % [fileparts(handles.edit_plot_path.String) '/settings.mat']

            try
                load([fileparts(handles.edit_plot_path.String) '/data4plot.mat'], 'obs');
                if ~isfield(obs, 'coordsyst'); obs.coordsyst = ''; end      % old processings
                pos_true = getCoordinates(obs.stationname, obs.startdate(1:3), obs.coordsyst);
            catch
                pos_true = [0; 0; 0];
                errordlg('True coordinates could not be found.', 'Error');
            end

            % if exists: write the detected true position into textfields
            if sum(pos_true) ~= 0
                set(handles.edit_x_true, 'String', num2str(pos_true(1)));
                set(handles.edit_y_true, 'String', num2str(pos_true(2)));
                set(handles.edit_z_true, 'String', num2str(pos_true(3)));
            end
        end

        % Button pushed function: load_process_list
        function pushbutton_load_process_list_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            [FileName, PathName] = uigetfile('*.mat','Select process list(s)', './PROCESSLIST/', 'multiselect', 'on');
            PathName = relativepath(PathName);

            fileChosen = 1;
            if isempty(FileName)
                fileChosen = 0;
            elseif ~iscell(FileName)
                if FileName == 0
                    fileChosen = 0;
                end
            end

            if fileChosen
                % get number of files
                if iscell(FileName)
                    num_files = size(FileName,2);
                else
                    num_files = 1;
                end

                % for all files
                for i_file = 1:num_files
                    % load process list

                    % if we have a cell == if we have more than 1 process_list selected
                    if iscell(FileName)
                        load([PathName, FileName{i_file}])
                    else
                        load([PathName, FileName])
                    end

                    % if we have now the process_list variable
                    if exist('process_list', 'var')
                        % get current listbox entries
                        curContent = handles.uitable_batch_proc.Data;

                        % delete all lines which do not contain data
                        ind = cellfun('isempty',curContent);
                        curContent(ind(:,2),:) = [];   % consider the second column (file name) to decide whether there is data or not

                        % define the new table
                        newContent = [curContent; process_list];

                        % update listbox
                        handles.uitable_batch_proc.Data = newContent;
                    end

                end

                % save all selected sessions to handles struct
                handles.allSelectedFiles = newContent;

                % save changes to handles struct
                guidata(hObject, handles);
            end
        end

        % Button pushed function: pushbutton_load_true_kinematic
        function pushbutton_load_true_kinematic_Callback(app, event)
            % --- Executes on button press in pushbutton_load_true_kinematic.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if isempty(handles.edit_plot_path.String);    return;   end
            [FileName, PathName] = uigetfile({'*.*'}, 'Select the reference trajectory', [Path.DATA 'COORDS/']);
            if FileName == 0;    return;    end
            PathName = relativepath(PathName);   % convert absolute path to relative path
            % write into textfields
            set(handles.edit_x_true, 'String', 'Reference Trajectory:');
            set(handles.edit_y_true, 'String', PathName);
            set(handles.edit_z_true, 'String', FileName);
        end

        % Button pushed function: pushbutton_menu_close_figures
        function pushbutton_menu_close_figures_Callback(app, event)
            % Close all figures (Single-Plot)

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            choice = questdlg('Do you want to close all open figures?', ...
                'Close Figures?', ...
                'Yes', 'No', 'No');
            switch choice
                case 'Yes'
                    all_figs = findobj(0, 'type', 'figure');
                    for i=1:length(all_figs)
                        if all_figs(i) ~= gcf
                            close(all_figs(i));
                        end
                    end
                case 'No'
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_multi_plot_add
        function pushbutton_multi_plot_add_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if handles.checkbox_multi_plot_each_row.Value || handles.checkbox_multi_plot_each_station.Value
                % plot each row or station is enabled
                choice = questdlg('Plot each row or station is enabled.', 'Attention!', ...
                    'Stop', 'Continue', 'Disable', 'Stop');
                if strcmp(choice, 'Stop');    return; end
                if strcmp(choice, 'Disable')
                    handles.checkbox_multi_plot_each_row.Value = 0;
                    handles.checkbox_multi_plot_each_station.Value = 0;
                end
            end
            if ~InWorkFolder();     return;     end         % check if in WORK folder
            TABLE = handles.uitable_multi_plot.Data;        % Data of table [cell]
            [rows, cols] = size(TABLE);                     % number of rows and columns
            % find index of next empty row
            row_idx = rows + 1;         % initialize
            for i = 1:rows
                if isempty(TABLE{i,1})
                    row_idx = i;        % index of first empty row
                    break
                end
            end
            startfolder = Path.RESULTS;
            global STOP_CALC;   STOP_CALC = 0;
            while true      % loop to add multiple files
                PathName = uigetdir(startfolder, 'Select result folder(s) of processing(s)');
                if PathName == 0
                    return       % no files selected, stopp adding files in table
                end
                [startfolder,~,~] = fileparts(PathName);    % to start next selection in the same folder
                PathName = relativepath(PathName);      	% convert absolute path to relative path

                % search all data4plot.mat and add it to table
                AllFiles = dir([PathName '/**/data4plot.mat']);     % get all data4plot.files in folder and subfolders
                n = length(AllFiles);

                if n < 1; continue; end         % nothing found

                % creating waitbar
                WBAR = waitbar(0, 'Loading Files...', 'Name','Writing table of Multi-Plot');

                % initialize some variables
                stations = cell(1,n);
                startdates = zeros(n,3);
                path_column = cell(1,n);
                row_label = cell(1,n);
                coordsyst = cell(1,n);
                i_fail = [];
                for i = 1:n         % loop over all detected data4plot.mat-files to load date and stationname
                    % load in current file
                    path_folder = AllFiles(i).folder;
                    path_data4plot = [path_folder '/data4plot.mat'];
                    try
                        load(path_data4plot, 'obs', 'settings')
                    catch       % loading failed
                        errordlg({'Loading failed (corrupt file):', relativepath(path_data4plot)}, 'Error')
                        i_fail = [i_fail, i];
                        continue
                    end
                    stations{i} = obs.stationname;
                    startdates(i,1:3) = obs.startdate(1:3);
                    if ~isfield(obs, 'coordsyst'); obs.coordsyst = ''; end      % old processings
                    coordsyst{i} = obs.coordsyst;
                    % create content for table
                    path_column{i} = relativepath(path_folder);
                    row_label{i} = sprintf('label%02.0f',row_idx);
                    if ~isempty(settings.PROC.name)
                        row_label{i} = settings.PROC.name;
                    end
                    if STOP_CALC    % STOP button was pushed, stop adding, enter already filled table in GUI
                        i_fail = [i_fail, i:n];
                        break
                    end
                    % update waitbar
                    if ishandle(WBAR)
                        progress = i/n;      % 1/100 [%]
                        mess = sprintf('%02.2f%s', progress*100, '% of the Multi-Plot table are finished.');
                        waitbar(progress, WBAR, mess)
                    end
                end

                % remove entries of files where loading failed
                if ~isempty(i_fail)
                    stations(i_fail) = '';
                    startdates(i_fail,:) = '';
                    coordsyst(i_fail,:) = '';
                    path_column(i_fail) = '';
                    row_label(i_fail) = '';
                    n = n - numel(i_fail);      % reduce number of files/rows
                end

                % get true coordinates for all stations
                if ishandle(WBAR); waitbar(progress, WBAR, 'Loading station coordinates...'); end

                true_coords = getCoordinates(stations, startdates, coordsyst);

                % write everything into table
                idx_cell = row_idx:row_idx+n-1;
                TABLE(idx_cell, 1) = path_column(1:n);
                TABLE(idx_cell, 2) = stations;
                TABLE(idx_cell, 3:5) = num2cell(true_coords);
                TABLE(idx_cell, 6) = num2cell(true);
                TABLE(idx_cell, 7) = row_label;
                % go to next row and start search for next file at the current place
                row_idx = idx_cell(end) + 1;

                % close waitbar
                if ishandle(WBAR);        close(WBAR);    end

                % save manipulated table before the next folder is selected
                handles.uitable_multi_plot.Data = TABLE;
                guidata(hObject, handles);
            end
        end

        % Button pushed function: pushbutton_multi_plot_add_last
        function pushbutton_multi_plot_add_last_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if handles.checkbox_multi_plot_each_row.Value || handles.checkbox_multi_plot_each_station.Value
                % plot each row or station is enabled
                choice = questdlg('Plot each row or station is enabled.', 'Attention!', ...
                    'Stop', 'Continue', 'Disable', 'Stop');
                if strcmp(choice, 'Stop');    return; end
                if strcmp(choice, 'Disable')
                    handles.checkbox_multi_plot_each_row.Value = 0;
                    handles.checkbox_multi_plot_each_station.Value = 0;
                end
            end
            % Adds last finished processing to Multi-Plot table
            folder_last_proc = handles.paths.lastproc;      % relative path
            if isempty(folder_last_proc)
                return      % no last processing
            end
            TABLE = handles.uitable_multi_plot.Data;        % Data of table [cell]
            [rows, cols] = size(TABLE);                     % number of rows and columns
            % find index of next empty row
            row_idx = rows + 1;         % initialize
            for i = 1:rows
                if isempty(TABLE{i,1})
                    row_idx = i;        % index of first empty row
                    break
                end
            end
            path_data4plot = [folder_last_proc '/data4plot.mat'];
            try
                load(path_data4plot, 'obs', 'settings')
            catch
                return                  % loading of last processing failed
            end

            % get some information about the last processing
            row_label = sprintf('label%02.0f',row_idx);
            if ~isempty(settings.PROC.name)
                row_label = settings.PROC.name;
            end
            if ~isfield(obs, 'coordsyst'); obs.coordsyst = ''; end
            true_coord = getCoordinates(obs.stationname, obs.startdate, obs.coordsyst);

            % write everything into table
            TABLE(row_idx, 1) = {[folder_last_proc '/']};
            TABLE(row_idx, 2) = {obs.stationname};
            TABLE(row_idx, 3:5) = num2cell(true_coord);
            TABLE(row_idx, 6) = num2cell(true);
            TABLE(row_idx, 7) = {row_label};

            % save manipulated table
            handles.uitable_multi_plot.Data = TABLE;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_multi_plot_delete
        function pushbutton_multi_plot_delete_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            [~, cols] = size(handles.uitable_multi_plot.Data);   %  size of table [cell]
            TABLE = cell(4,cols);                       % create new empty cell
            handles.uitable_multi_plot.Data = TABLE;    % save cleared table
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_multi_plot_plot
        function pushbutton_multi_plot_plot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if ~InWorkFolder();     return;     end         % check if in WORK folder
            fprintf('\n');
            TABLE_use = GetTableData(handles.uitable_multi_plot.Data, 6, 6, [1 7], 1);        % Data of multi plot table [cell])
            if isempty(TABLE_use)
                msgbox('No plotable files in Multi-Plot table.', 'Empty table', 'help')
                return
            end
            PATHS = TABLE_use(:,1);                	% paths to result-folders
            XYZ_true = cell2mat(TABLE_use(:,3:5));  % true coordinates of these processings
            LABELS = TABLE_use(:,7);                % labels for plotting
            MultiPlotStruct = getMultiPlotSelection(handles);
            [boolean, MultiPlotStruct] = checkMultiPlotSelection(MultiPlotStruct, handles);
            if boolean
                if MultiPlotStruct.graph
                    StationResultPlot(TABLE_use, MultiPlotStruct)
                end
                if MultiPlotStruct.coord_conv || MultiPlotStruct.histo_conv || MultiPlotStruct.bar || MultiPlotStruct.box || MultiPlotStruct.pos_conv || MultiPlotStruct.ttff || MultiPlotStruct.pos_acc || MultiPlotStruct.quant_conv || MultiPlotStruct.tropo
                    MultiPlot(PATHS, XYZ_true, LABELS, MultiPlotStruct);
                end
                handles = un_check_multiplot_checkboxes(app, handles, 0);
                handles.paths.last_multi_plot = MultiPlotStruct;
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_nav_BDS
        function pushbutton_nav_BDS_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/BROADCAST/'], handles.paths.navBDS_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile({'*.*c;*.rnx'}, 'Select the BeiDou Navigation File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_nav_BDS, 'String',FileName);

            handles.paths.navBDS_1 = PathName;
            handles.paths.navBDS_2 = FileName;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_nav_GAL
        function pushbutton_nav_GAL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/BROADCAST/'], handles.paths.navGAL_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile({'*.*l;*.rnx'}, 'Select the Navigation File (GALILEO)', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_nav_GAL, 'String',FileName);

            handles.paths.navGAL_1 = PathName;
            handles.paths.navGAL_2 = FileName;
            if sscanf(get(handles.edit_nav_GAL, 'String'), '%f') == 0
                errordlg('Choose a File.', 'File Error');
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_nav_GLO
        function pushbutton_nav_GLO_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/BROADCAST/'], handles.paths.navGLO_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile({'*.*g;*.rnx'}, 'Select the Navigation File (GLONASS)', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName        % uigetfile cancelled
                return;
            end
            set(handles.edit_nav_GLO, 'String',FileName);
            handles.paths.navGLO_1 = PathName;
            handles.paths.navGLO_2 = FileName;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_nav_GPS
        function pushbutton_nav_GPS_Callback(app, event)
            % Navigation-File GPS (.n)

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/BROADCAST/'], handles.paths.navGPS_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile({'*.*n;*.rnx'}, 'Select the Navigation File (GPS)', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_nav_GPS,   'String', FileName);
            set(handles.edit_nav_multi, 'String', '');

            handles.paths.navGPS_1 = PathName;
            handles.paths.navGPS_2 = FileName;
            if sscanf(get(handles.edit_nav_GPS, 'String'), '%f') == 0
                errordlg('Choose a File.', 'File Error');
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_nav_multi
        function pushbutton_nav_multi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/BROADCAST/'], handles.paths.navMULTI_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile({'*MN.rnx;*.*n'}, 'Select the Multi-GNSS Navigation File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName        % uigetfile cancelled
                return;
            end
            set(handles.edit_nav_multi, 'String', FileName);
            set(handles.edit_nav_GPS,   'String', '');
            set(handles.edit_nav_GLO,   'String', '');
            set(handles.edit_nav_GAL,   'String', '');

            handles.paths.navMULTI_1 = PathName;
            handles.paths.navMULTI_2 = FileName;
            if sscanf(get(handles.edit_nav_multi, 'String'), '%f') == 0
                errordlg('Choose a File.', 'File Error');
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_obs_file
        function pushbutton_obs_file_Callback(app, event)
            % Observation-File (*.yyo) Pushbutton

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/OBS/'], handles.paths.obs_1, handles.paths.rinex_date);
            if ~exist(folder, 'dir'); folder = [Path.DATA '/OBS/']; end     % e.g., data folder was deleted
            [FileName, PathName] = uigetfile({'*.*o;*.rnx;*.obs'}, 'Select the Observation File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName        % uigetfile cancelled
                return;
            end
            set(handles.edit_obs, 'String',FileName);
            handles.paths.obs_1 = PathName;
            handles.paths.obs_2 = FileName;

            % Analyze header of RINEX file and change GUI according to it
            rheader = anheader_GUI([PathName,FileName]);

            % create messagebox which frequencies are observed in RINEX File
            gps_freq = repmat({'Off'},3,1);   % initialize with 'Off', because it can happen that there are less than 3 frequencies contained and these shall then be 'Off'
            glo_freq = repmat({'Off'},3,1);
            gal_freq = repmat({'Off'},3,1);
            bds_freq = repmat({'Off'},3,1);
            if all(rheader.ind_gps_freq ~= 0)
                gps_freq(1:length(rheader.ind_gps_freq)) = DEF.freq_GPS_names(rheader.ind_gps_freq);
            end
            if all(rheader.ind_glo_freq ~= 0)
                glo_freq(1:length(rheader.ind_glo_freq)) = DEF.freq_GLO_names(rheader.ind_glo_freq);
            end
            if all(rheader.ind_gal_freq ~= 0)
                gal_freq(1:length(rheader.ind_gal_freq)) = DEF.freq_GAL_names(rheader.ind_gal_freq);
            end
            if all(rheader.ind_bds_freq ~= 0)
                bds_freq(1:length(rheader.ind_bds_freq)) = DEF.freq_BDS_names(rheader.ind_bds_freq);
            end

            % print messagebox
            gps_freq_temp = sprintf('%s - ', gps_freq{:});
            glo_freq_temp = sprintf('%s - ', glo_freq{:});
            gal_freq_temp = sprintf('%s - ', gal_freq{:});
            bds_freq_temp = sprintf('%s - ', bds_freq{:});
            msgbox({...
                ['GPS Frequencies: ', gps_freq_temp(1:end-3)], ...
                ['Glonass Frequencies: ', glo_freq_temp(1:end-3)], ...
                ['Galileo Frequencies: ', gal_freq_temp(1:end-3)], ...
                ['BeiDou Frequencies: ', bds_freq_temp(1:end-3)]}, ...
                'Frequencies', 'help')   % (1:end-3) in order to cut the needless ' - ' at the end of the string


            % change popupmenues of processed frequencies
            set(handles.popupmenu_gps_1, 'Value', find(strcmpi(DEF.freq_GPS_names,gps_freq{1})));
            set(handles.popupmenu_gps_2, 'Value', find(strcmpi(DEF.freq_GPS_names,gps_freq{2})));
            set(handles.popupmenu_gps_3, 'Value', find(strcmpi(DEF.freq_GPS_names,gps_freq{3})));
            set(handles.popupmenu_glo_1, 'Value', find(strcmpi(DEF.freq_GLO_names,glo_freq{1})));
            set(handles.popupmenu_glo_2, 'Value', find(strcmpi(DEF.freq_GLO_names,glo_freq{2})));
            set(handles.popupmenu_glo_3, 'Value', find(strcmpi(DEF.freq_GLO_names,glo_freq{3})));
            set(handles.popupmenu_gal_1, 'Value', find(strcmpi(DEF.freq_GAL_names,gal_freq{1})));
            set(handles.popupmenu_gal_2, 'Value', find(strcmpi(DEF.freq_GAL_names,gal_freq{2})));
            set(handles.popupmenu_gal_3, 'Value', find(strcmpi(DEF.freq_GAL_names,gal_freq{3})));
            set(handles.popupmenu_bds_1, 'Value', find(strcmpi(DEF.freq_BDS_names,bds_freq{1})));
            set(handles.popupmenu_bds_2, 'Value', find(strcmpi(DEF.freq_BDS_names,bds_freq{2})));
            set(handles.popupmenu_bds_3, 'Value', find(strcmpi(DEF.freq_BDS_names,bds_freq{3})));

            % set approximate position into GUI
            pos_approx = num2str(rheader.pos_approx, '%.4f');
            set(handles.edit_x, 'String', pos_approx(1,:));
            set(handles.edit_y, 'String', pos_approx(2,:));
            set(handles.edit_z, 'String', pos_approx(3,:));

            % convert from date of first RINEX observation into path of folder structure
            dd = rheader.first_obs(3);
            mm = rheader.first_obs(2);
            yyyy = rheader.first_obs(1);
            jd = cal2jd_GT(yyyy,mm,dd);
            [doy, yyyy] = jd2doy_GT(jd);
            % create strings
            yyyy    = sprintf('%04d',yyyy);
            doy 	= sprintf('%03d',doy);
            % save subfolder into struct path
            handles.paths.rinex_date = ['/' yyyy '/' doy '/'];

            % Enable/Disable stuff on GUI depending on observation file
            % ||| check for Doppler observation and en/disable CS detection with Doppler
            % GPS
            set(handles.checkbox_GPS, 'Value', 1);
            set(handles.checkbox_GPS, 'Enable', 'On');
            set(handles.popupmenu_gps_1,                     'Enable', 'On');
            set(handles.popupmenu_gps_2,                     'Enable', 'On');
            set(handles.popupmenu_gps_3,                     'Enable', 'On');
            set(handles.edit_gps_rank,                       'Enable', 'On');
            set(handles.edit_filter_rec_clock_Q,             'Enable', 'On');
            set(handles.edit_filter_rec_clock_sigma0,        'Enable', 'On');
            set(handles.text_gps_time_offset,                'Enable', 'On');
            set(handles.text_gps_time_offset_m,              'Enable', 'On');
            set(handles.popupmenu_filter_rec_clock_dynmodel, 'Enable', 'On');
            % GLONASS
            set(handles.checkbox_GLO, 'Value', 1);
            set(handles.checkbox_GLO, 'Enable', 'On');
            set(handles.popupmenu_glo_1,                     'Enable', 'On');
            set(handles.popupmenu_glo_2,                     'Enable', 'On');
            set(handles.popupmenu_glo_3,                     'Enable', 'On');
            set(handles.edit_glo_rank,                       'Enable', 'On');
            set(handles.edit_filter_glonass_offset_Q,      	 'Enable', 'On');
            set(handles.edit_filter_glonass_offset_Q,        'Enable', 'On');
            set(handles.text_glo_time_offset,                'Enable', 'On');
            set(handles.text_glo_time_offset_m,              'Enable', 'On');
            set(handles.popupmenu_filter_glonass_offset_dynmodel, 'Enable', 'On');

            if floor(rheader.version_full) >= 3
                able_version(app, handles, 'On')
                set(handles.edit_gps_rank, 'String', rheader.gps_ranking);
                set(handles.edit_glo_rank, 'String', rheader.glo_ranking);
                set(handles.edit_gal_rank, 'String', rheader.gal_ranking);
                set(handles.edit_bds_rank, 'String', rheader.bds_ranking);
                set(handles.checkbox_GAL, 'Value', 1);
                set(handles.checkbox_BDS, 'Value', 1);
                onoff = 'On';
            elseif floor(rheader.version_full) == 2
                able_version(app, handles, 'Off')
                set(handles.checkbox_GAL, 'Value', 0);
                set(handles.checkbox_BDS, 'Value', 0);
                onoff = 'Off';
                set(handles.popupmenu_gps_3, 'Value', 4);
            end
            % handle Galileo
            set(handles.popupmenu_gal_1,                          'Enable', onoff);
            set(handles.popupmenu_gal_2,                          'Enable', onoff);
            set(handles.popupmenu_gal_3,                          'Enable', onoff);
            set(handles.edit_gal_rank,                            'Enable', onoff);
            set(handles.edit_filter_galileo_offset_Q,             'Enable', onoff);
            set(handles.edit_filter_galileo_offset_sigma0,        'Enable', onoff);
            set(handles.text_gal_time_offset,                     'Enable', onoff);
            set(handles.text_gal_time_offset_m,                   'Enable', onoff);
            set(handles.popupmenu_filter_galileo_offset_dynmodel, 'Enable', onoff);
            % handle BeiDou
            set(handles.edit_bds_rank,                            'Enable', onoff);
            set(handles.popupmenu_bds_1,                          'Enable', onoff);
            set(handles.popupmenu_bds_2,                          'Enable', onoff);
            set(handles.popupmenu_bds_3,                          'Enable', onoff);
            set(handles.edit_filter_beidou_offset_Q,              'Enable', onoff);
            set(handles.edit_filter_beidou_offset_sigma0,         'Enable', onoff);
            set(handles.text_bds_time_offset,                     'Enable', onoff);
            set(handles.text_bds_time_offset_m,                   'Enable', onoff);
            set(handles.popupmenu_filter_beidou_offset_dynmodel,  'Enable', onoff);

            if isempty(get(handles.edit_obs,'String'))   % enable/disable download button
                set(handles.pushbutton_download,'Enable','off');
                handles.pushbutton_analyze_rinex.Enable = 'off';
            else
                set(handles.pushbutton_download,'Enable','on');
                handles.pushbutton_analyze_rinex.Enable = 'on';
            end

            % clear true position
            set(handles.edit_x_true,  'String', '');
            set(handles.edit_y_true,  'String', '');
            set(handles.edit_z_true,  'String', '');

            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_obx
        function pushbutton_obx_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/ORBIT/'], handles.paths.obx_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile('*.obx', 'Select the .clk_30s File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_obx, 'String', FileName);

            handles.paths.obx_1 = PathName;
            handles.paths.obx_2 = FileName;
            if sscanf(get(handles.edit_clock, 'String'), '%f') == 0
                errordlg('Choose a File.', 'File Error');
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_plot
        function pushbutton_plot_Callback(app, event)
            % Plot selected plots

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if ~InWorkFolder();     return;     end         % check if in WORK folder
            % precheck of plot settings
            settings.PLOT = getPlotSettingsFromGUI(app, handles);
            [bool, settings.PLOT] = checkPlotSelection(settings.PLOT, handles);
            if ~bool;    return;    end


            if ~handles.checkbox_singlemultiplot.Value
                % --- normal single plot with data from one processing / data4plot.mat ---

                % file-path to data4plot.mat
                path_data4plot = handles.paths.plotfile;

                try     % try to load in
                    load(path_data4plot, 'settings');
                catch
                    fprintf('Check path to data4plot-file!\n')
                    return
                end

                % the following code is useful as it prevents to load in the data from
                % data4plot.mat each time a plot is opened
                if isfield(handles, 'settings')
                    % there is already some plot data in handles
                    if strcmp(handles.settings.PROC.output_dir, settings.PROC.output_dir)
                        % the handles-plot-data is the right one
                        settings = handles.settings;
                        obs = handles.obs;
                        satellites = handles.satellites;
                        storeData = handles.storeData;
                    else
                        % the handles-plot-data is not the right one
                        load(path_data4plot, 'obs', 'satellites', 'storeData');
                        handles.settings = settings;
                        handles.obs = obs;
                        handles.satellites = satellites;
                        handles.storeData = storeData;
                    end
                else
                    % no plot data yet saved in handles
                    load(path_data4plot, 'obs', 'satellites', 'storeData');
                    handles.settings = settings;
                    handles.obs = obs;
                    handles.satellites = satellites;
                    handles.storeData = storeData;
                end

            else
                % --- "Multi-Single-Plot" ---

                TABLE_use = GetTableData(handles.uitable_multi_plot.Data, 6, 6, 1, 1);        % Data of multi plot table [cell])

                if isempty(TABLE_use)
                    errordlg('Multi-Plot Table is empty!', 'Error')
                    return

                elseif ~all(strcmp(TABLE_use(1,2), TABLE_use(:,2)))
                    % more than one station is enabled in the Multi-Plot Table
                    choice = questdlg({'Single Plotting with Multi-Plot Table:',...
                        'Multi-Plot Table contains more than one station!'}, ...
                        'ATTENTION', ...
                        'Stop', 'Let�s try', 'Stop');
                    if strcmp(choice, 'Stop') || isempty(choice); return; end
                end

                [satellites, storeData, obs, settings] = stack_data4plot(TABLE_use(:,1), settings.PLOT);

                % just use selected GNSS to plot
                settings.INPUT.use_GPS = 1;
                settings.INPUT.use_GLO = 1;
                settings.INPUT.use_GAL = 1;
                settings.INPUT.use_BDS = 1;
            end

            % get the enabled plots from the GUI
            settings.PLOT = getPlotSettingsFromGUI(app, handles);
            [~, settings.PLOT] = checkPlotSelection(settings.PLOT, handles);

            % check GNSS wich should be plotted and overwrite in settings
            settings.INPUT.use_GPS = settings.INPUT.use_GPS & handles.checkbox_plot_gps.Value;
            settings.INPUT.use_GLO = settings.INPUT.use_GLO & handles.checkbox_plot_glo.Value;
            settings.INPUT.use_GAL = settings.INPUT.use_GAL & handles.checkbox_plot_gal.Value;
            settings.INPUT.use_BDS = settings.INPUT.use_BDS & handles.checkbox_plot_bds.Value;

            % uncheck all plots
            un_check_plot_checkboxes(handles, 0);

            % create plots
            SinglePlotting(satellites, storeData, obs, settings)
            handles.paths.last_plot = settings.PLOT;        % save selected plots

            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_plot_path
        function pushbutton_plot_path_Callback(app, event)
            % pushbutton to set path to data4plot.mat

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            [FileName, PathName] = uigetfile('data4plot*.mat','Select the data4plot.mat for Opening Plots', Path.RESULTS);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            path_data4plot = [PathName, FileName];
            set(handles.edit_plot_path,'String', path_data4plot);     % ||| ugly
            handles.paths.plotfile = path_data4plot;      % save path to data4plot.mat into handles
            % load settings for en/disabling plots which are (not) possible
            load(path_data4plot, 'settings');
            handles = disable_plot_checkboxes(handles, settings);
            % write true position into textfields (if there is one)
            set(handles.edit_x_true,  'String', num2str(settings.PLOT.pos_true(1)));
            set(handles.edit_y_true,  'String', num2str(settings.PLOT.pos_true(2)));
            set(handles.edit_z_true,  'String', num2str(settings.PLOT.pos_true(3)));
            guidata(hObject, handles);

            if isempty(get(handles.edit_plot_path,'String'))   % enable/disable "load from IGS" pushbutton
                set(handles.pushbutton_load_pos_true,'Enable','off');
            else
                set(handles.pushbutton_load_pos_true,'Enable','on');
            end
        end

        % Button pushed function: pushbutton_plot_stations
        function pushbutton_plot_stations_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % plot the stations which are currently in the batch processing table on a
            % world map
            TABLE = GetTableData(handles.uitable_batch_proc.Data, 2, [], 2, [1 2]);
            if ~isempty(TABLE)
                StationWorldPlot(TABLE);
            end
        end

        % Button pushed function: pushbutton_run
        function pushbutton_run_Callback(app, event)
            % Run

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if ~InWorkFolder();     return;     end         % check if in WORK folder
            % -+-+-+-+-+-+-+-+-+-+-+-+-+-+-+-+-+-+-+-+
            handles = StartProcessingFromGUI(handles);      % starts PPP_main.n
            % -+-+-+-+-+-+-+-+-+-+-+-+-+-+-+-+-+-+-+-+
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_save_filter_settings
        function pushbutton_save_filter_settings_Callback(app, event)
            % Save filter settings to a file

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if ~InWorkFolder();     return;     end         % check if in WORK folder
            guidata(hObject, handles);
            [filename, PathName] = uiputfile('*.mat', 'Save filter settings into file', [[pwd '/PARAMETERS/'], 'filter_']);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if filename
                filtersetts = getFilterSettingsFromGUI(handles);   % get input from GUI and put it into structure "settings"

                save([PathName, filename(1:end-4), '.mat'], 'filtersetts')    % save variable settings into file

                % write message box
                msgbox('Filter settings successfully saved.', 'Save settings file', 'help');
            end
        end

        % Button pushed function: pushbutton_save_plot_list
        function pushbutton_save_plot_list_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            plot_list = handles.uitable_multi_plot.Data;  	% Data of table [cell]

            % delete all lines which do not contain data
            ind = cellfun('isempty',plot_list);
            % consider the first column (path to data4plot) to decide whether there is data or not
            plot_list(ind(:,1),:) = [];

            % save the file
            if ~isempty(plot_list)
                [FileName, PathName, ~] = uiputfile( {'*.mat', 'Matlab Binary Format (*.mat)'}, 'Save as', './PLOTLIST/');
                if ~FileName
                    return
                end
                PathName = relativepath(PathName);
                save([PathName FileName], 'plot_list');
            else
                errordlg('Select observation files first!','Error');
            end
        end

        % Button pushed function: save_process_list
        function pushbutton_save_process_list_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            process_list = handles.uitable_batch_proc.Data;  	% Data of table [cell]

            % delete all lines which do not contain data
            ind = cellfun('isempty',process_list);
            process_list(ind(:,2),:) = [];   % consider the second column (file name) to decide whether there is data or not

            % save the file
            if ~isempty(process_list)
                try
                    [FileName, PathName, ~] = uiputfile( {'*.mat', 'Matlab Binary Format (*.mat)'}, 'Save as', 'PROCESSLIST/');
                catch
                    [FileName, PathName, ~] = uiputfile( {'*.mat', 'Matlab Binary Format (*.mat)'}, 'Save as', pwd);
                end
                if ~FileName
                    return
                end
                PathName = relativepath(PathName);
                save([PathName FileName], 'process_list');
            else
                errordlg('Select observation files first!','Error');
            end
        end

        % Button pushed function: pushbutton_sp3
        function pushbutton_sp3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/ORBIT/'], handles.paths.sp3_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile({'*.sp3*;*.eph*'}, 'Select the .sp3 File', folder);

            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName            % uigetfile cancelled
                return;
            end
            set(handles.edit_sp3, 'String', FileName);

            handles.paths.sp3_1 = PathName;
            handles.paths.sp3_2 = FileName;
            if sscanf(get(handles.edit_sp3, 'String'), '%f') == 0
                errordlg('Choose a File.', 'File Error');
            end
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_stop_calculation
        function pushbutton_stop_calculation_Callback(app, event)
            % Stop

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            global STOP_CALC
            STOP_CALC = 1;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_tropo_file
        function pushbutton_tropo_file_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            folder = getFolderPath(app, [Path.DATA '/TROPO/'], handles.paths.tropo_1, handles.paths.rinex_date);
            [FileName, PathName] = uigetfile({'*.*zpd;*.tro'}, 'Select the Tropo File', folder);
            PathName = relativepath(PathName);   % convert absolute path to relative path
            if ~FileName        % uigetfile cancelled
                return;
            end

            handles.paths.tropo_1 = PathName;
            handles.paths.tropo_2 = FileName;
            set(handles.edit_tropo_file, 'String', FileName);

            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_uncheck_all
        function pushbutton_uncheck_all_Callback(app, event)
            % pushbutton to uncheck all plot checkboxes

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = un_check_plot_checkboxes(handles, 0);
            guidata(hObject, handles);
        end

        % Selection changed function: uibuttongroup_multi_plot_solution
        function uibuttongroup_multi_plot_solution_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_enable_onoff(handles);
            if handles.radiobutton_multi_plot_float.Value
                handles.edit_multi_plot_thresh_hor_coord.String = DEF.float_hori_coord;
                handles.edit_multi_plot_thresh_height_coord.String = DEF.float_vert_coord;
                handles.edit_multi_plot_thresh_hor_pos.String = DEF.float_2D_pos;
                handles.edit_multi_plot_thresh_3D.String = DEF.float_3D_pos;
            elseif handles.radiobutton_multi_plot_fixed.Value
                handles.edit_multi_plot_thresh_hor_coord.String = DEF.fixed_hori_coord;
                handles.edit_multi_plot_thresh_height_coord.String = DEF.fixed_vert_coord;
                handles.edit_multi_plot_thresh_hor_pos.String = DEF.fixed_2D_pos;
                handles.edit_multi_plot_thresh_3D.String = DEF.fixed_3D_pos;
            end
        end

        % Cell edit callback: uitable_batch_proc
        function uitable_batch_proc_CellEditCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            bool_all = handles.checkbox_manipulate_all.Value;
            bool_ident = handles.checkbox_batch_manipulate_identical.Value;
            pos = eventdata.Indices;     % row and column of changed cell
            row = pos(1);   col = pos(2);
            TABLE = handles.uitable_batch_proc.Data;  	% Data of table [cell]
            bool_empty = cellfun('isempty', TABLE(:,1)) & cellfun('isempty', TABLE(:,2));
            TABLE = TABLE(~bool_empty,:);               % remove empty rows
            [rows, cols] = size(TABLE);             	% number of rows and columns
            if row > rows || col > cols                 % e.g. click on GNSS of empty row
                handles.uitable_batch_proc.Data = TABLE;    % save table without empty rows
                guidata(hObject, handles);
                return
            end
            value = TABLE{row, col};    % value after change
            filled = ~cellfun(@isempty,TABLE(:,1));

            switch col      % action depending of column where event happened
                case 1                      % folder-path is not editable
                    % no action

                case 2                      % RINEX File
                    if bool_all         % do not know that to do here
                        TABLE{row, col} = eventdata.PreviousData;
                    elseif isempty(value)
                        if bool_ident       % check for identical station
                            idx = contains(TABLE(:,col), eventdata.PreviousData(1:4));
                            idx(row) = true;
                            handles.checkbox_batch_manipulate_identical.Value = false;
                            row = idx;
                        end
                        TABLE(row,:) = [];
                    else
                        TABLE{row, col} = eventdata.PreviousData;       % editing RINEX does not make sense
                        % OLD:
                        %             TABLE(row:rows-1,:) = TABLE(row+1:rows,:);
                        %             TABLE(rows,:) = [];
                    end

                case {6, 9, 12, 15}         % checkboxes GNSS
                    if bool_all     % en/disable GPS for all files
                        TABLE(filled,col) = {value};
                        handles.checkbox_manipulate_all.Value = false;
                    end
                case {7, 8, 10, 11, 13, 14, 16, 17}         % Frequencies or Ranking
                    if (ischar(value) && str2double(value) == 0)
                        if row ~= 1     % all other rows -> copy from cell above
                            TABLE(row, col) = TABLE(row-1, col);
                        else            % 1st row -> delete row and "move data up"
                            TABLE(row:rows-1,:) = TABLE(row+1:rows,:);
                            TABLE(rows,:) = [];
                        end
                    elseif bool_all
                        TABLE(filled,col) = {value};     % write for all files
                        handles.checkbox_manipulate_all.Value = false;
                    elseif bool_ident
                        old = eventdata.PreviousData;           % data of cell before change
                        idx = contains(TABLE(:,col), old);        % check for identical data in current row
                        TABLE(idx,col) = {value};                 % change all identical data to new value
                        handles.checkbox_batch_manipulate_identical.Value = false;
                    end

                case {3, 4, 5, 18, 19}      % XYZ, Start or End
                    if value == 0
                        if row ~= 1     % all other rows -> copy from cell above
                            TABLE(row, col) = TABLE(row-1, col);
                        else            % 1st row -> delete row and "move data up"
                            TABLE(row:rows-1,:) = TABLE(row+1:rows,:);
                            TABLE(rows,:) = [];
                        end
                    elseif bool_all     % write for all files
                        TABLE(filled,col) = {value};
                        handles.checkbox_manipulate_all.Value = false;
                    elseif bool_ident
                        old = eventdata.PreviousData;         	  % data of cell before change
                        idx = cellfun(@(x)x == old,TABLE(:,col)); % check for identical data in current row
                        TABLE(idx,col) = {value};                 % change all identical data to new value
                        handles.checkbox_batch_manipulate_identical.Value = false;
                    end
            end

            handles.uitable_batch_proc.Data = TABLE;    % save manipulated table
            guidata(hObject, handles);
        end

        % Cell edit callback: uitable_excl_epochs
        function uitable_excl_epochs_CellEditCallback(app, event)
            % Exclude Epochs from Processing

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            pos = eventdata.Indices;     % row and column of changed cell
            row = pos(1);   col = pos(2);
            TABLE = handles.uitable_excl_epochs.Data;  	% Data of table [cell]
            [rows, cols] = size(TABLE);             	% number of rows and columns of table
            value = TABLE{row,col};         % value after manipulation

            switch col      % action depending of column where event happened
                case 1      % From
                    if isnan(value)        % delete row
                        if row == rows     % last row, clear
                            TABLE{row, 1} = []; TABLE{row, 2} = []; TABLE{row, 3} = [];
                        else            % all other rows, overwrite with other rows
                            TABLE(row:rows-1,:) = TABLE(row+1:rows,:);  % move up
                            TABLE{rows, 1} = []; TABLE{rows, 2} = []; TABLE{rows, 3} = false;   % delete last row
                        end
                    elseif isempty(TABLE{row,col+1})
                        TABLE{row,2} = value;       % set default values
                        TABLE{row,3} = true;
                    end
                case 2      % To
                    if isnan(value)
                        TABLE{row,col} = 999999;
                    end
                    if isempty(TABLE{row,col-1})        % no start
                        TABLE{row,col-1} = 1;
                    end
                case 3      % Reset?
            end

            if row == rows-1 || row == rows
                ADD = cell(1,3);    % maniplation in one of the last two rows, add two rows
                ADD{1,3} = false;
                TABLE = [TABLE; ADD; ADD];
            end

            handles.uitable_excl_epochs.Data = TABLE;    % save manipulated table
            guidata(hObject, handles);
        end

        % Cell edit callback: uitable_exclude
        function uitable_exclude_CellEditCallback(app, event)
            % Exclude satellites

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            pos = eventdata.Indices;     % row and column of changed cell
            row = pos(1);   col = pos(2);
            TABLE = handles.uitable_exclude.Data;  	% Data of table [cell]
            [rows, cols] = size(TABLE);             	% number of rows and columns of table
            prn = TABLE{row, 1};
            n = length(prn);

            % check for wrong input
            if ~isempty(prn)
                wrong_input = false;
                if isnumeric(prn) && (prn < 1 || prn > 399)
                    wrong_input = true;
                elseif ischar(prn)
                    if n > 3 || n <= 1
                        wrong_input = true;
                    end
                    if str2double(prn) > 399 || str2double(prn) < 1
                        wrong_input = true;
                    end
                end
                if wrong_input
                    errordlg({'Wrong input for PRN!', 'Please use 3-digit name (e.g., G04, 232, R08)'}, 'Error');
                    return
                end
            end


            % check if prn is text e.g. G15, E17, C05
            if ischar(prn)
                if isempty(prn)
                    prn = [];
                else
                    gnss_number = char2gnss_number(prn(1));
                    if ~isnan(gnss_number)      % convert from e.g. E23 to 223
                        prn = str2double(prn(2:end));
                        prn = prn + gnss_number;
                        TABLE{row, col} = prn;
                    end
                end
            end

            switch col      % action depending of column where event happened
                case {2, 3}     % start or end changed
                    if isnan(TABLE{row, col})
                        TABLE{row, col} = [];           % value deleted
                    end
                case 1          % PRN
                    if isempty(prn) || any(isnan(prn))	% clear row
                        if row == rows     % last row, clear
                            TABLE{row, 1} = []; TABLE{row, 2} = []; TABLE{row, 3} = [];
                        else            % all other rows, overwrite with other rows
                            TABLE(row:rows-1,:) = TABLE(row+1:rows,:);  % move up
                            TABLE{rows, 2} = []; TABLE{rows, 3} = [];   % delete last row
                        end
                    end
            end

            if row == rows-1 || row == rows
                ADD = cell(1,size(TABLE,2)); 	% maniplation in one of the last two rows, add two rows
                TABLE = [TABLE; ADD; ADD];
            end

            handles.uitable_exclude.Data = TABLE;    % save manipulated table
            guidata(hObject, handles);
        end

        % Cell edit callback: uitable_multi_plot
        function uitable_multi_plot_CellEditCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            pos = eventdata.Indices;        % position of changed cell
            row = pos(1);   col = pos(2);   % row and column of changed cell
            TABLE = handles.uitable_multi_plot.Data;	% Data of table [cell]
            bool_empty = cellfun('isempty', TABLE(:,1)) & cellfun('isempty', TABLE(:,2)) & cellfun('isempty', TABLE(:,7));
            TABLE = TABLE(~bool_empty,:);               % remove empty rows
            [rows, cols] = size(TABLE);             	% number of rows and columns of table
            value = TABLE{row, col};    % value after change
            bool_identical = handles.checkbox_multi_manipulate_identical.Value;
            bool_label = handles.checkbox_multi_manipulate_same_label.Value;
            bool_station = handles.checkbox_multi_manipulate_same_station.Value;
            bool_all = handles.checkbox_multi_manipulate_all.Value;

            switch col      % action depending of column where event happened
                case 1      % path to folder was changed -> delete
                    if bool_identical
                        old_path = eventdata.PreviousData;
                        idx_empty = cellfun(@(x) isempty(x), TABLE(:,col));
                        TABLE(idx_empty,col) = {old_path};
                        idx = strcmp(TABLE(:,col), old_path);
                        TABLE(idx,:) = [];
                    elseif bool_label
                        label = TABLE{row,7};
                        idx = strcmp(TABLE(:,7), label);
                        TABLE(idx,:) = [];
                    elseif bool_station
                        station = eventdata.PreviousData;
                        TABLE{row,2} = station;
                        idx = strcmp(TABLE(:,2), station);
                        TABLE(idx,:) = [];
                    else        % bool_all is not covered here
                        TABLE(row,:) = [];
                    end
                case 2    % station was changed
                    if bool_identical
                        old_stat = eventdata.PreviousData;
                        idx_empty = cellfun(@(x) isempty(x), TABLE(:,col));
                        TABLE(idx_empty,col) = {old_stat};
                        idx = strcmp(TABLE(:,col), old_stat);
                    elseif bool_label
                        label = TABLE{row,7};
                        idx = strcmp(TABLE(:,7), label);
                    elseif bool_station
                        old_station = eventdata.PreviousData;
                        idx = strcmp(TABLE(:,2), old_station);
                        idx(row) = 1;
                    elseif bool_all
                        idx = 1:size(TABLE,1);  % all rows
                    else
                        idx = row;          % only edited cell
                    end
                    % write new value
                    if isempty(value)       % delete
                        TABLE(idx,:) = [];
                    else                    % write new station name
                        TABLE(idx,2) = {value};
                    end
                case {3,4,5}   	% X, Y, Z true coordinates
                    if isnan(value)
                        TABLE{row, col} = 1;
                    end
                    if (value == 1 || value == 0) && row ~= 1   % if zero is entered copy coordinate value from above
                        TABLE{row, col} = TABLE{row-1, col};
                    end
                    if bool_all
                        TABLE(:,col) = {eventdata.NewData};
                    elseif bool_identical
                        old = eventdata.PreviousData;
                        idx = cellfun(@(x)x == old, TABLE(:,col));
                        TABLE(idx,col) = {eventdata.NewData};
                    elseif bool_label
                        new = eventdata.NewData;
                        label = TABLE{row,7};
                        idx_label = strcmp(TABLE(:,7), label);
                        TABLE(idx_label,col) = {new};
                    elseif bool_station
                        new = eventdata.NewData;
                        station = TABLE{row,2};
                        idx_station = strcmp(TABLE(:,2), station);
                        TABLE(idx_station,col) = {new};
                    end
                case 6          % logical use was changed
                    if bool_all
                        new = eventdata.NewData;
                        TABLE(:,col) = {new};
                    elseif bool_identical
                        old = eventdata.PreviousData;           % data of cell before change
                        column = TABLE(:,col);
                        idx_empty = cellfun(@(x) isempty(x), column);
                        column(idx_empty) = [];
                        idx = cell2mat(column) == old;          % check for identical data in current row
                        idx(idx_empty) = false;
                        TABLE(idx,col) = {value};               % change all identical data to new value
                    elseif bool_label
                        new = eventdata.NewData;
                        label = TABLE{row,7};
                        idx_label = strcmp(TABLE(:,7), label);
                        TABLE(idx_label,col) = {new};
                    elseif bool_station
                        new = eventdata.NewData;
                        station = TABLE{row,2};
                        idx_station = strcmp(TABLE(:,2), station);
                        TABLE(idx_station,col) = {new};
                    end
                case 7          % label was changed
                    if bool_all
                        new = eventdata.NewData;
                        TABLE(:,col) = {new};
                    elseif bool_identical || bool_label
                        old = eventdata.PreviousData;           % data of cell before change
                        idx = strcmp(TABLE(:,col), old);    	% check for identical data in current row
                        TABLE(idx,col) = {value};             	% change all identical data to new value
                    elseif bool_station
                        new = eventdata.NewData;
                        station = TABLE{row,2};
                        idx_station = strcmp(TABLE(:,2), station);
                        TABLE(idx_station,col) = {new};
                    end
            end

            handles.checkbox_multi_manipulate_identical.Value = false;
            handles.checkbox_multi_manipulate_same_label.Value = false;
            handles.checkbox_multi_manipulate_same_station.Value = false;
            handles.checkbox_multi_manipulate_all.Value = false;

            handles.uitable_multi_plot.Data = TABLE;    % save manipulated table
            guidata(hObject, handles);
        end

        % Callback function: buttongroup_models_troposphere_Gh, 
        % ...and 17 other components
        function update_GUI_Callback(app, event)
            % Simple function to call update function of the GUI which handles the
            % visibility of all items

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles = GUI_enable_onoff(handles);
            guidata(hObject, handles);
        end

        % Selection changed function: buttongroup_bspline
        function buttongroup_bsplineSelectionChanged(app, event)
            selectedButton = app.buttongroup_bspline.SelectedObject;
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles.buttongroup_bspline = app.buttongroup_bspline;
            handles = GUI_enable_onoff(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'raPPPid';
            app.figure1.Tag = 'figure1';
            app.menu_file = uimenu(app.figure1);
            app.menu_file.Text = 'File';
            app.menu_file.Tag = 'menu_file';
            app.menu_file_setInputFile = uimenu(app.menu_file);
            app.menu_file_setInputFile.MenuSelectedFcn = createCallbackFcn(app, @menu_file_setInputFile_Callback, true);
            app.menu_file_setInputFile.Text = 'Set input file';
            app.menu_file_setInputFile.Tag = 'menu_file_setInputFile';
            app.menu_file_batch_proc = uimenu(app.menu_file);
            app.menu_file_batch_proc.MenuSelectedFcn = createCallbackFcn(app, @menu_file_batch_proc_Callback, true);
            app.menu_file_batch_proc.Text = 'Batch-Processing';
            app.menu_file_batch_proc.Tag = 'menu_file_batch_proc';
            app.menu_file_parametersFiles = uimenu(app.menu_file);
            app.menu_file_parametersFiles.Text = 'Parameter files';
            app.menu_file_parametersFiles.Tag = 'menu_file_parametersFiles';
            app.menu_file_parametersFiles_loadDefaultParameters = uimenu(app.menu_file_parametersFiles);
            app.menu_file_parametersFiles_loadDefaultParameters.MenuSelectedFcn = createCallbackFcn(app, @menu_file_parametersFiles_loadDefaultParameters_Callback, true);
            app.menu_file_parametersFiles_loadDefaultParameters.Text = 'Load defaults';
            app.menu_file_parametersFiles_loadDefaultParameters.Tag = 'menu_file_parametersFiles_loadDefaultParameters';
            app.menu_file_parametersFiles_loadParameters = uimenu(app.menu_file_parametersFiles);
            app.menu_file_parametersFiles_loadParameters.MenuSelectedFcn = createCallbackFcn(app, @menu_file_parametersFiles_loadParameters_Callback, true);
            app.menu_file_parametersFiles_loadParameters.Text = 'Load parameters ...';
            app.menu_file_parametersFiles_loadParameters.Tag = 'menu_file_parametersFiles_loadParameters';
            app.menu_file_parametersFiles_saveParameters = uimenu(app.menu_file_parametersFiles);
            app.menu_file_parametersFiles_saveParameters.MenuSelectedFcn = createCallbackFcn(app, @menu_file_parametersFiles_saveParameters_Callback, true);
            app.menu_file_parametersFiles_saveParameters.Text = 'Save parameters as ...';
            app.menu_file_parametersFiles_saveParameters.Tag = 'menu_file_parametersFiles_saveParameters';
            app.menu_file_settingsFiles = uimenu(app.menu_file);
            app.menu_file_settingsFiles.Text = 'Settings Files';
            app.menu_file_settingsFiles.Tag = 'menu_file_settingsFiles';
            app.menu_file_settingsFiles_loadSettings = uimenu(app.menu_file_settingsFiles);
            app.menu_file_settingsFiles_loadSettings.MenuSelectedFcn = createCallbackFcn(app, @menu_file_settingsFiles_loadSettings_Callback, true);
            app.menu_file_settingsFiles_loadSettings.Text = 'Load settings ...';
            app.menu_file_settingsFiles_loadSettings.Tag = 'menu_file_settingsFiles_loadSettings';
            app.menu_file_settingsFiles_saveSettings = uimenu(app.menu_file_settingsFiles);
            app.menu_file_settingsFiles_saveSettings.MenuSelectedFcn = createCallbackFcn(app, @menu_file_settingsFiles_saveSettings_Callback, true);
            app.menu_file_settingsFiles_saveSettings.Text = 'Save settings as ...';
            app.menu_file_settingsFiles_saveSettings.Tag = 'menu_file_settingsFiles_saveSettings';
            app.menu_file_exit = uimenu(app.menu_file);
            app.menu_file_exit.MenuSelectedFcn = createCallbackFcn(app, @menu_file_exit_Callback, true);
            app.menu_file_exit.Text = 'Exit';
            app.menu_file_exit.Tag = 'menu_file_exit';
            app.menu_models = uimenu(app.figure1);
            app.menu_models.Text = 'Models';
            app.menu_models.Tag = 'menu_models';
            app.menu_models_orbitClockData = uimenu(app.menu_models);
            app.menu_models_orbitClockData.MenuSelectedFcn = createCallbackFcn(app, @menu_models_orbitClockData_Callback, true);
            app.menu_models_orbitClockData.Text = 'Orbit/Clock';
            app.menu_models_orbitClockData.Tag = 'menu_models_orbitClockData';
            app.menu_models_biases = uimenu(app.menu_models);
            app.menu_models_biases.MenuSelectedFcn = createCallbackFcn(app, @menu_models_biases_Callback, true);
            app.menu_models_biases.Text = 'Biases';
            app.menu_models_biases.Tag = 'menu_models_biases';
            app.menu_models_ionosphere = uimenu(app.menu_models);
            app.menu_models_ionosphere.MenuSelectedFcn = createCallbackFcn(app, @menu_models_ionosphere_Callback, true);
            app.menu_models_ionosphere.Text = 'Ionosphere';
            app.menu_models_ionosphere.Tag = 'menu_models_ionosphere';
            app.menu_models_troposphere = uimenu(app.menu_models);
            app.menu_models_troposphere.MenuSelectedFcn = createCallbackFcn(app, @menu_models_troposphere_Callback, true);
            app.menu_models_troposphere.Text = 'Troposphere';
            app.menu_models_troposphere.Tag = 'menu_models_troposphere';
            app.menu_models_otherCorrections = uimenu(app.menu_models);
            app.menu_models_otherCorrections.MenuSelectedFcn = createCallbackFcn(app, @menu_models_otherCorrections_Callback, true);
            app.menu_models_otherCorrections.Text = 'Other corrections';
            app.menu_models_otherCorrections.Tag = 'menu_models_otherCorrections';
            app.menu_estimation = uimenu(app.figure1);
            app.menu_estimation.Text = 'Estimation';
            app.menu_estimation.Tag = 'menu_estimation';
            app.menu_estimation_ambiguityFixing = uimenu(app.menu_estimation);
            app.menu_estimation_ambiguityFixing.MenuSelectedFcn = createCallbackFcn(app, @menu_estimation_ambiguityFixing_Callback, true);
            app.menu_estimation_ambiguityFixing.Text = 'Ambiguity fixing';
            app.menu_estimation_ambiguityFixing.Tag = 'menu_estimation_ambiguityFixing';
            app.menu_estimation_adjustment = uimenu(app.menu_estimation);
            app.menu_estimation_adjustment.MenuSelectedFcn = createCallbackFcn(app, @menu_estimation_adjustment_Callback, true);
            app.menu_estimation_adjustment.Text = 'Adjustment';
            app.menu_estimation_adjustment.Tag = 'menu_estimation_adjustment';
            app.menu_run = uimenu(app.figure1);
            app.menu_run.Text = 'Run';
            app.menu_run.Tag = 'menu_run';
            app.menu_run_processingOptions = uimenu(app.menu_run);
            app.menu_run_processingOptions.MenuSelectedFcn = createCallbackFcn(app, @menu_run_processingOptions_Callback, true);
            app.menu_run_processingOptions.Text = 'Processing options';
            app.menu_run_processingOptions.Tag = 'menu_run_processingOptions';
            app.menu_run_Export = uimenu(app.menu_run);
            app.menu_run_Export.MenuSelectedFcn = createCallbackFcn(app, @menu_run_export_Callback, true);
            app.menu_run_Export.Text = 'Export options';
            app.menu_run_Export.Tag = 'menu_run_Export';
            app.menu_plotting = uimenu(app.figure1);
            app.menu_plotting.Text = 'Plotting';
            app.menu_plotting.Tag = 'menu_plotting';
            app.menu_single_plot = uimenu(app.menu_plotting);
            app.menu_single_plot.MenuSelectedFcn = createCallbackFcn(app, @menu_single_plot_Callback, true);
            app.menu_single_plot.Text = 'Single-Plot';
            app.menu_single_plot.Tag = 'menu_single_plot';
            app.menu_multi_plot = uimenu(app.menu_plotting);
            app.menu_multi_plot.MenuSelectedFcn = createCallbackFcn(app, @menu_multi_plot_Callback, true);
            app.menu_multi_plot.Text = 'Multi-Plot';
            app.menu_multi_plot.Tag = 'menu_multi_plot';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [80 900];
            app.axes1.YLim = [0.5 50.5];
            app.axes1.YDir = 'reverse';
            app.axes1.XTick = zeros(1,0);
            app.axes1.YTick = zeros(1,0);
            app.axes1.Tag = 'axes1';
            app.uipanel_multi_plot = uipanel(app.figure1);
            app.uipanel_multi_plot.Title = 'Multi-Plot';
            app.uipanel_multi_plot.Visible = 'off';
            app.uipanel_multi_plot.Tag = 'uipanel_multi_plot';
            app.uitable_multi_plot = uitable(app.uipanel_multi_plot);
            app.uitable_multi_plot.ColumnName = {'Path'; 'Station'; 'X_true'; 'Y_true'; 'Z_true'; 'Use'; 'Label'};
            app.uitable_multi_plot.ColumnEditable = [true true true true true true true];
            app.uitable_multi_plot.CellEditCallback = createCallbackFcn(app, @uitable_multi_plot_CellEditCallback, true);
            app.uitable_multi_plot.Tag = 'uitable_multi_plot';
            app.pushbutton_multi_plot_add = uibutton(app.uipanel_multi_plot, 'push');
            app.pushbutton_multi_plot_add.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_multi_plot_add_Callback, true);
            app.pushbutton_multi_plot_add.Tag = 'pushbutton_multi_plot_add';
            app.pushbutton_multi_plot_add.Text = 'Add Folder';
            app.pushbutton_multi_plot_delete = uibutton(app.uipanel_multi_plot, 'push');
            app.pushbutton_multi_plot_delete.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_multi_plot_delete_Callback, true);
            app.pushbutton_multi_plot_delete.Tag = 'pushbutton_multi_plot_delete';
            app.pushbutton_multi_plot_delete.Text = 'Delete all';
            app.checkbox_coord_conv = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_coord_conv.Tag = 'checkbox_coord_conv';
            app.checkbox_coord_conv.Text = 'Coordinate Convergence';
            app.checkbox_histo_conv = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_histo_conv.ValueChangedFcn = createCallbackFcn(app, @checkbox_histo_conv_Callback, true);
            app.checkbox_histo_conv.Tag = 'checkbox_histo_conv';
            app.checkbox_histo_conv.Text = 'Histogram of Convergence';
            app.pushbutton_multi_plot_plot = uibutton(app.uipanel_multi_plot, 'push');
            app.pushbutton_multi_plot_plot.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_multi_plot_plot_Callback, true);
            app.pushbutton_multi_plot_plot.Tag = 'pushbutton_multi_plot_plot';
            app.pushbutton_multi_plot_plot.Text = 'PLOT';
            app.uibuttongroup_multi_plot_solution = uibuttongroup(app.uipanel_multi_plot);
            app.uibuttongroup_multi_plot_solution.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup_multi_plot_solution_SelectionChangeFcn, true);
            app.uibuttongroup_multi_plot_solution.Title = 'Plot results of';
            app.uibuttongroup_multi_plot_solution.Tag = 'uibuttongroup_multi_plot_solution';
            app.radiobutton_multi_plot_float = uiradiobutton(app.uibuttongroup_multi_plot_solution);
            app.radiobutton_multi_plot_float.Tag = 'radiobutton_multi_plot_float';
            app.radiobutton_multi_plot_float.Text = 'float solution';
            app.radiobutton_multi_plot_float.Value = true;
            app.radiobutton_multi_plot_fixed = uiradiobutton(app.uibuttongroup_multi_plot_solution);
            app.radiobutton_multi_plot_fixed.Tag = 'radiobutton_multi_plot_fixed';
            app.radiobutton_multi_plot_fixed.Text = 'fixed solution';
            app.checkbox_bar_conv = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_bar_conv.ValueChangedFcn = createCallbackFcn(app, @checkbox_bar_conv_Callback, true);
            app.checkbox_bar_conv.Tag = 'checkbox_bar_conv';
            app.checkbox_bar_conv.Text = 'Bar Plot of Convergence';
            app.text225 = uilabel(app.uipanel_multi_plot);
            app.text225.Tag = 'text225';
            app.text225.Text = '[m], horizontal coordinates';
            app.edit_multi_plot_thresh_hor_coord = uieditfield(app.uipanel_multi_plot, 'text');
            app.edit_multi_plot_thresh_hor_coord.Tag = 'edit_multi_plot_thresh_hor_coord';
            app.edit_multi_plot_thresh_hor_coord.Value = '0.10';
            app.checkbox_pos_conv = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_pos_conv.Tag = 'checkbox_pos_conv';
            app.checkbox_pos_conv.Text = 'Position Convergence';
            app.pushbutton_save_plot_list = uibutton(app.uipanel_multi_plot, 'push');
            app.pushbutton_save_plot_list.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_plot_list_Callback, true);
            app.pushbutton_save_plot_list.Tag = 'pushbutton_save_plot_list';
            app.pushbutton_save_plot_list.Text = 'Save plot list';
            app.pushbutton_load_plot_list = uibutton(app.uipanel_multi_plot, 'push');
            app.pushbutton_load_plot_list.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_plot_list_Callback, true);
            app.pushbutton_load_plot_list.Tag = 'pushbutton_load_plot_list';
            app.pushbutton_load_plot_list.Text = 'Load plot list';
            app.text228 = uilabel(app.uipanel_multi_plot);
            app.text228.Tag = 'text228';
            app.text228.Text = '[m], height coordinate';
            app.text229 = uilabel(app.uipanel_multi_plot);
            app.text229.Tag = 'text229';
            app.text229.Text = '[m], 2D position';
            app.text230 = uilabel(app.uipanel_multi_plot);
            app.text230.Tag = 'text230';
            app.text230.Text = 'Definition of Convergence:';
            app.edit_multi_plot_thresh_height_coord = uieditfield(app.uipanel_multi_plot, 'text');
            app.edit_multi_plot_thresh_height_coord.Tag = 'edit_multi_plot_thresh_height_coord';
            app.edit_multi_plot_thresh_height_coord.Value = '0.20';
            app.edit_multi_plot_thresh_hor_pos = uieditfield(app.uipanel_multi_plot, 'text');
            app.edit_multi_plot_thresh_hor_pos.Tag = 'edit_multi_plot_thresh_hor_pos';
            app.edit_multi_plot_thresh_hor_pos.Value = '0.10';
            app.checkbox_ttff_plot = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_ttff_plot.Tag = 'checkbox_ttff_plot';
            app.checkbox_ttff_plot.Enable = 'off';
            app.checkbox_ttff_plot.Text = 'Time to First Fix';
            app.checkbox_multi_manipulate_identical = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_multi_manipulate_identical.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.checkbox_multi_manipulate_identical.Tag = 'checkbox_multi_manipulate_identical';
            app.checkbox_multi_manipulate_identical.Text = '... identical';
            app.checkbox_multi_manipulate_same_label = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_multi_manipulate_same_label.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.checkbox_multi_manipulate_same_label.Tag = 'checkbox_multi_manipulate_same_label';
            app.checkbox_multi_manipulate_same_label.Text = '... same label';
            app.edit_conv_min = uieditfield(app.uipanel_multi_plot, 'text');
            app.edit_conv_min.Tag = 'edit_conv_min';
            app.edit_conv_min.Enable = 'off';
            app.edit_conv_min.Value = '2-5-7.5-10-15-20-30-45-60';
            app.text_conv_min = uilabel(app.uipanel_multi_plot);
            app.text_conv_min.Tag = 'text_conv_min';
            app.text_conv_min.Enable = 'off';
            app.text_conv_min.Text = '[min]';
            app.checkbox_pos_acc = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_pos_acc.Tag = 'checkbox_pos_acc';
            app.checkbox_pos_acc.Text = 'Position Accuracy';
            app.checkbox_multi_plot_each_row = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_multi_plot_each_row.ValueChangedFcn = createCallbackFcn(app, @checkbox_multi_plot_each_row_Callback, true);
            app.checkbox_multi_plot_each_row.Tag = 'checkbox_multi_plot_each_row';
            app.checkbox_multi_plot_each_row.Text = 'Plot each row';
            app.checkbox_box_plot = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_box_plot.Tag = 'checkbox_box_plot';
            app.checkbox_box_plot.Text = 'Box Plot';
            app.checkbox_multi_manipulate_same_station = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_multi_manipulate_same_station.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.checkbox_multi_manipulate_same_station.Tag = 'checkbox_multi_manipulate_same_station';
            app.checkbox_multi_manipulate_same_station.Text = '... same station';
            app.checkbox_quantile_conv = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_quantile_conv.Tag = 'checkbox_quantile_conv';
            app.checkbox_quantile_conv.Text = 'Quantile Convergence';
            app.text284 = uilabel(app.uipanel_multi_plot);
            app.text284.Tag = 'text284';
            app.text284.Text = 'Points in Time:';
            app.edit_multi_plot_thresh_3D = uieditfield(app.uipanel_multi_plot, 'text');
            app.edit_multi_plot_thresh_3D.Tag = 'edit_multi_plot_thresh_3D';
            app.edit_multi_plot_thresh_3D.Value = '0.15';
            app.text285 = uilabel(app.uipanel_multi_plot);
            app.text285.Tag = 'text285';
            app.text285.Text = '[m], 3D position';
            app.checkbox_multi_manipulate_all = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_multi_manipulate_all.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.checkbox_multi_manipulate_all.Tag = 'checkbox_multi_manipulate_all';
            app.checkbox_multi_manipulate_all.Text = '... all';
            app.text_multi_manipulate = uilabel(app.uipanel_multi_plot);
            app.text_multi_manipulate.Tag = 'text_multi_manipulate';
            app.text_multi_manipulate.Text = 'Manipulate: ';
            app.checkbox_station_results = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_station_results.Tag = 'checkbox_station_results';
            app.checkbox_station_results.Visible = 'off';
            app.checkbox_station_results.Text = 'Station Results';
            app.checkbox_station_graph = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_station_graph.Tag = 'checkbox_station_graph';
            app.checkbox_station_graph.Text = 'Station Graph';
            app.pushbutton_multi_plot_add_last = uibutton(app.uipanel_multi_plot, 'push');
            app.pushbutton_multi_plot_add_last.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_multi_plot_add_last_Callback, true);
            app.pushbutton_multi_plot_add_last.Tag = 'pushbutton_multi_plot_add_last';
            app.pushbutton_multi_plot_add_last.Text = 'Add Last';
            app.checkbox_ztd_convergence = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_ztd_convergence.Tag = 'checkbox_ztd_convergence';
            app.checkbox_ztd_convergence.Text = 'ZTD Convergence';
            app.checkbox_multi_plot_each_station = uicheckbox(app.uipanel_multi_plot);
            app.checkbox_multi_plot_each_station.ValueChangedFcn = createCallbackFcn(app, @checkbox_multi_plot_each_station_Callback, true);
            app.checkbox_multi_plot_each_station.Tag = 'checkbox_multi_plot_each_station';
            app.checkbox_multi_plot_each_station.Text = 'Plot each station';
            app.uipanel_setInputFile = uipanel(app.figure1);
            app.uipanel_setInputFile.Title = 'Input-File';
            app.uipanel_setInputFile.Tag = 'uipanel_setInputFile';
            app.text_obs = uilabel(app.uipanel_setInputFile);
            app.text_obs.Tag = 'text_obs';
            app.text_obs.Text = 'Observation File:';
            app.edit_obs = uieditfield(app.uipanel_setInputFile, 'text');
            app.edit_obs.ValueChangedFcn = createCallbackFcn(app, @edit_obs_Callback, true);
            app.edit_obs.Tag = 'edit_obs';
            app.text_koord = uilabel(app.uipanel_setInputFile);
            app.text_koord.Tag = 'text_koord';
            app.text_koord.Text = 'Approximate Position';
            app.text_x = uilabel(app.uipanel_setInputFile);
            app.text_x.Tag = 'text_x';
            app.text_x.Text = 'X-Coordinate:';
            app.text_y = uilabel(app.uipanel_setInputFile);
            app.text_y.Tag = 'text_y';
            app.text_y.Text = 'Y-Coordinate:';
            app.text_z = uilabel(app.uipanel_setInputFile);
            app.text_z.Tag = 'text_z';
            app.text_z.Text = 'Z-Coordinate:';
            app.edit_x = uieditfield(app.uipanel_setInputFile, 'text');
            app.edit_x.ValueChangedFcn = createCallbackFcn(app, @edit_x_Callback, true);
            app.edit_x.Tag = 'edit_x';
            app.edit_y = uieditfield(app.uipanel_setInputFile, 'text');
            app.edit_y.ValueChangedFcn = createCallbackFcn(app, @edit_y_Callback, true);
            app.edit_y.Tag = 'edit_y';
            app.edit_z = uieditfield(app.uipanel_setInputFile, 'text');
            app.edit_z.ValueChangedFcn = createCallbackFcn(app, @edit_z_Callback, true);
            app.edit_z.Tag = 'edit_z';
            app.pushbutton_obs_file = uibutton(app.uipanel_setInputFile, 'push');
            app.pushbutton_obs_file.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_obs_file_Callback, true);
            app.pushbutton_obs_file.Tag = 'pushbutton_obs_file';
            app.pushbutton_obs_file.Text = '...';
            app.text40 = uilabel(app.uipanel_setInputFile);
            app.text40.Tag = 'text40';
            app.text40.Text = '[m]';
            app.text42 = uilabel(app.uipanel_setInputFile);
            app.text42.Tag = 'text42';
            app.text42.Text = '[m]';
            app.checkbox_realtime = uicheckbox(app.uipanel_setInputFile);
            app.checkbox_realtime.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.checkbox_realtime.Tag = 'checkbox_realtime';
            app.checkbox_realtime.Text = 'real-time';
            app.text41 = uilabel(app.uipanel_setInputFile);
            app.text41.Tag = 'text41';
            app.text41.Text = {'[m]'; ''};
            app.text_proc_freq = uilabel(app.uipanel_setInputFile);
            app.text_proc_freq.Tag = 'text_proc_freq';
            app.text_proc_freq.Text = 'Processed Frequencies';
            app.popupmenu_gps_1 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_gps_1.Items = {'L1', 'L2', 'L5', 'OFF'};
            app.popupmenu_gps_1.ValueChangedFcn = createCallbackFcn(app, @popupmenu_gps_1_Callback, true);
            app.popupmenu_gps_1.Tag = 'popupmenu_gps_1';
            app.popupmenu_gps_1.Enable = 'off';
            app.popupmenu_gps_1.Value = 'L1';
            app.popupmenu_gps_2 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_gps_2.Items = {'L1', 'L2', 'L5', 'OFF'};
            app.popupmenu_gps_2.ValueChangedFcn = createCallbackFcn(app, @popupmenu_gps_2_Callback, true);
            app.popupmenu_gps_2.Tag = 'popupmenu_gps_2';
            app.popupmenu_gps_2.Enable = 'off';
            app.popupmenu_gps_2.Value = 'L2';
            app.popupmenu_gps_3 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_gps_3.Items = {'L1', 'L2', 'L5', 'OFF'};
            app.popupmenu_gps_3.ValueChangedFcn = createCallbackFcn(app, @popupmenu_gps_3_Callback, true);
            app.popupmenu_gps_3.Tag = 'popupmenu_gps_3';
            app.popupmenu_gps_3.Enable = 'off';
            app.popupmenu_gps_3.Value = 'L5';
            app.popupmenu_gal_1 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_gal_1.Items = {'E1', 'E5a', 'E5b', 'E5', 'E6', 'OFF'};
            app.popupmenu_gal_1.ValueChangedFcn = createCallbackFcn(app, @popupmenu_gal_1_Callback, true);
            app.popupmenu_gal_1.Tag = 'popupmenu_gal_1';
            app.popupmenu_gal_1.Enable = 'off';
            app.popupmenu_gal_1.Value = 'E1';
            app.popupmenu_gal_2 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_gal_2.Items = {'E1', 'E5a', 'E5b', 'E5', 'E6', 'OFF'};
            app.popupmenu_gal_2.ValueChangedFcn = createCallbackFcn(app, @popupmenu_gal_2_Callback, true);
            app.popupmenu_gal_2.Tag = 'popupmenu_gal_2';
            app.popupmenu_gal_2.Enable = 'off';
            app.popupmenu_gal_2.Value = 'E5a';
            app.popupmenu_gal_3 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_gal_3.Items = {'E1', 'E5a', 'E5b', 'E5', 'E6', 'OFF'};
            app.popupmenu_gal_3.ValueChangedFcn = createCallbackFcn(app, @popupmenu_gal_3_Callback, true);
            app.popupmenu_gal_3.Tag = 'popupmenu_gal_3';
            app.popupmenu_gal_3.Enable = 'off';
            app.popupmenu_gal_3.Value = 'E5b';
            app.edit_gps_rank = uieditfield(app.uipanel_setInputFile, 'text');
            app.edit_gps_rank.Tag = 'edit_gps_rank';
            app.edit_gps_rank.Enable = 'off';
            app.edit_gps_rank.Value = 'PWCSLXYMND';
            app.edit_gal_rank = uieditfield(app.uipanel_setInputFile, 'text');
            app.edit_gal_rank.Tag = 'edit_gal_rank';
            app.edit_gal_rank.Enable = 'off';
            app.edit_gal_rank.Value = 'BCIQXAZ';
            app.text_rank = uilabel(app.uipanel_setInputFile);
            app.text_rank.Tag = 'text_rank';
            app.text_rank.Enable = 'off';
            app.text_rank.Text = 'Ranking:';
            app.checkbox_GLO = uicheckbox(app.uipanel_setInputFile);
            app.checkbox_GLO.ValueChangedFcn = createCallbackFcn(app, @checkbox_GLO_Callback, true);
            app.checkbox_GLO.Tag = 'checkbox_GLO';
            app.checkbox_GLO.Enable = 'off';
            app.checkbox_GLO.Text = 'GLONASS';
            app.checkbox_GPS = uicheckbox(app.uipanel_setInputFile);
            app.checkbox_GPS.ValueChangedFcn = createCallbackFcn(app, @checkbox_GPS_Callback, true);
            app.checkbox_GPS.Tag = 'checkbox_GPS';
            app.checkbox_GPS.Enable = 'off';
            app.checkbox_GPS.Text = 'GPS';
            app.checkbox_GAL = uicheckbox(app.uipanel_setInputFile);
            app.checkbox_GAL.ValueChangedFcn = createCallbackFcn(app, @checkbox_GAL_Callback, true);
            app.checkbox_GAL.Tag = 'checkbox_GAL';
            app.checkbox_GAL.Enable = 'off';
            app.checkbox_GAL.Text = 'GALILEO';
            app.checkbox_BDS = uicheckbox(app.uipanel_setInputFile);
            app.checkbox_BDS.ValueChangedFcn = createCallbackFcn(app, @checkbox_BDS_Callback, true);
            app.checkbox_BDS.Tag = 'checkbox_BDS';
            app.checkbox_BDS.Enable = 'off';
            app.checkbox_BDS.Text = 'BEIDOU';
            app.popupmenu_bds_1 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_bds_1.Items = {'B1', 'B2', 'B3', 'OFF'};
            app.popupmenu_bds_1.ValueChangedFcn = createCallbackFcn(app, @popupmenu_bds_1_Callback, true);
            app.popupmenu_bds_1.Tag = 'popupmenu_bds_1';
            app.popupmenu_bds_1.Enable = 'off';
            app.popupmenu_bds_1.Value = 'B1';
            app.popupmenu_bds_2 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_bds_2.Items = {'B1', 'B2', 'B3', 'OFF'};
            app.popupmenu_bds_2.ValueChangedFcn = createCallbackFcn(app, @popupmenu_bds_2_Callback, true);
            app.popupmenu_bds_2.Tag = 'popupmenu_bds_2';
            app.popupmenu_bds_2.Enable = 'off';
            app.popupmenu_bds_2.Value = 'B2';
            app.popupmenu_bds_3 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_bds_3.Items = {'B1', 'B2', 'B3', 'OFF'};
            app.popupmenu_bds_3.ValueChangedFcn = createCallbackFcn(app, @popupmenu_bds_3_Callback, true);
            app.popupmenu_bds_3.Tag = 'popupmenu_bds_3';
            app.popupmenu_bds_3.Enable = 'off';
            app.popupmenu_bds_3.Value = 'B3';
            app.edit_bds_rank = uieditfield(app.uipanel_setInputFile, 'text');
            app.edit_bds_rank.Tag = 'edit_bds_rank';
            app.edit_bds_rank.Enable = 'off';
            app.edit_bds_rank.Value = 'IQX';
            app.popupmenu_glo_1 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_glo_1.Items = {'G1', 'G2', 'G3', 'OFF'};
            app.popupmenu_glo_1.ValueChangedFcn = createCallbackFcn(app, @popupmenu_glo_1_Callback, true);
            app.popupmenu_glo_1.Tag = 'popupmenu_glo_1';
            app.popupmenu_glo_1.Enable = 'off';
            app.popupmenu_glo_1.Value = 'G1';
            app.popupmenu_glo_2 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_glo_2.Items = {'G1', 'G2', 'G3', 'OFF'};
            app.popupmenu_glo_2.ValueChangedFcn = createCallbackFcn(app, @popupmenu_glo_2_Callback, true);
            app.popupmenu_glo_2.Tag = 'popupmenu_glo_2';
            app.popupmenu_glo_2.Enable = 'off';
            app.popupmenu_glo_2.Value = 'G2';
            app.popupmenu_glo_3 = uidropdown(app.uipanel_setInputFile);
            app.popupmenu_glo_3.Items = {'G1', 'G2', 'G3', 'OFF'};
            app.popupmenu_glo_3.ValueChangedFcn = createCallbackFcn(app, @popupmenu_glo_3_Callback, true);
            app.popupmenu_glo_3.Tag = 'popupmenu_glo_3';
            app.popupmenu_glo_3.Enable = 'off';
            app.popupmenu_glo_3.Value = 'G3';
            app.edit_glo_rank = uieditfield(app.uipanel_setInputFile, 'text');
            app.edit_glo_rank.Tag = 'edit_glo_rank';
            app.edit_glo_rank.Enable = 'off';
            app.edit_glo_rank.Value = 'PCIQX';
            app.pushbutton_analyze_rinex = uibutton(app.uipanel_setInputFile, 'push');
            app.pushbutton_analyze_rinex.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_analyze_rinex_Callback, true);
            app.pushbutton_analyze_rinex.Tag = 'pushbutton_analyze_rinex';
            app.pushbutton_analyze_rinex.Enable = 'off';
            app.pushbutton_analyze_rinex.Text = 'Analyze';
            app.text_RT_from = uilabel(app.uipanel_setInputFile);
            app.text_RT_from.Tag = 'text_RT_from';
            app.text_RT_from.Enable = 'off';
            app.text_RT_from.Visible = 'off';
            app.text_RT_from.Text = 'from:';
            app.edit_RT_from = uieditfield(app.uipanel_setInputFile, 'text');
            app.edit_RT_from.Tag = 'edit_RT_from';
            app.edit_RT_from.Enable = 'off';
            app.edit_RT_from.Visible = 'off';
            app.edit_RT_from.Value = '00:00:00';
            app.text_RT_to = uilabel(app.uipanel_setInputFile);
            app.text_RT_to.Tag = 'text_RT_to';
            app.text_RT_to.Enable = 'off';
            app.text_RT_to.Visible = 'off';
            app.text_RT_to.Text = 'to:';
            app.edit_RT_to = uieditfield(app.uipanel_setInputFile, 'text');
            app.edit_RT_to.Tag = 'edit_RT_to';
            app.edit_RT_to.Enable = 'off';
            app.edit_RT_to.Visible = 'off';
            app.edit_RT_to.Value = '00:00:00';
            app.text_RT_format = uilabel(app.uipanel_setInputFile);
            app.text_RT_format.Tag = 'text_RT_format';
            app.text_RT_format.Enable = 'off';
            app.text_RT_format.Visible = 'off';
            app.text_RT_format.Text = 'hh:mm:ss';
            app.uipanel_biases = uipanel(app.figure1);
            app.uipanel_biases.Title = 'Biases';
            app.uipanel_biases.Visible = 'off';
            app.uipanel_biases.Tag = 'uipanel_biases';
            app.buttongroup_models_biases_code = uibuttongroup(app.uipanel_biases);
            app.buttongroup_models_biases_code.SelectionChangedFcn = createCallbackFcn(app, @buttongroup_models_biases_code_SelectionChangeFcn, true);
            app.buttongroup_models_biases_code.Title = '(Code) Biases Source';
            app.buttongroup_models_biases_code.Tag = 'buttongroup_models_biases_code';
            app.radiobutton_models_biases_code_CAS = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_CAS.Tag = 'radiobutton_models_biases_code_CAS';
            app.radiobutton_models_biases_code_CAS.Tooltip = 'GREC, Multi-GNSS DCBs from Institute of Geodesy and Geophysics (IGG) of the Chinese Academy of Sciences (CAS) (available since 2013)';
            app.radiobutton_models_biases_code_CAS.Text = 'CAS Multi-GNSS DCBs';
            app.radiobutton_models_biases_code_CAS.Value = true;
            app.radiobutton_models_biases_code_DLR = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_DLR.Tag = 'radiobutton_models_biases_code_DLR';
            app.radiobutton_models_biases_code_DLR.Tooltip = 'GREC, Multi-GNSS DCBs from German Aerospace Center (DLR)  (available since 2016)';
            app.radiobutton_models_biases_code_DLR.Text = 'DLR Multi-GNSS DCBs';
            app.radiobutton_models_biases_code_CODE = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_CODE.Tag = 'radiobutton_models_biases_code_CODE';
            app.radiobutton_models_biases_code_CODE.Tooltip = 'GR, DCBs for GPS only from Center for Orbit Determination in Europe (CODE)';
            app.radiobutton_models_biases_code_CODE.Text = 'CODE DCBs (P1P2, P1C1, P2C2)';
            app.radiobutton_models_biases_code_CorrectionStream = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_CorrectionStream.Tag = 'radiobutton_models_biases_code_CorrectionStream';
            app.radiobutton_models_biases_code_CorrectionStream.Enable = 'off';
            app.radiobutton_models_biases_code_CorrectionStream.Tooltip = 'Code biases from (CNES) correction stream';
            app.radiobutton_models_biases_code_CorrectionStream.Text = 'Correction Stream';
            app.radiobutton_models_biases_code_manually = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_manually.Tag = 'radiobutton_models_biases_code_manually';
            app.radiobutton_models_biases_code_manually.Tooltip = 'Select DCB or Sinex-Bias-File manually';
            app.radiobutton_models_biases_code_manually.Text = 'manually';
            app.radiobutton_models_biases_code_off = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_off.Tag = 'radiobutton_models_biases_code_off';
            app.radiobutton_models_biases_code_off.Tooltip = 'No correction for code biases';
            app.radiobutton_models_biases_code_off.Text = 'off';
            app.radiobutton_models_biases_code_CODE_OSB = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_CODE_OSB.Tag = 'radiobutton_models_biases_code_CODE_OSB';
            app.radiobutton_models_biases_code_CODE_OSB.Tooltip = 'GRC, OSBs from Center for Orbit Determination in Europe (CODE)';
            app.radiobutton_models_biases_code_CODE_OSB.Text = 'CODE OSBs';
            app.radiobutton_models_biases_code_CODE_IAR = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_CODE_IAR.Tag = 'radiobutton_models_biases_code_CODE_IAR';
            app.radiobutton_models_biases_code_CODE_IAR.Tooltip = 'GRC, OSBs from Center for Orbit Determination in Europe (CODE)';
            app.radiobutton_models_biases_code_CODE_IAR.Text = 'CODE MGEX';
            app.radiobutton_models_biases_code_CNES_post = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_CNES_post.Tag = 'radiobutton_models_biases_code_CNES_post';
            app.radiobutton_models_biases_code_CNES_post.Tooltip = 'GR, DCBs for GPS only from Center for Orbit Determination in Europe (CODE)';
            app.radiobutton_models_biases_code_CNES_post.Text = 'CNES postprocessed';
            app.radiobutton_brdc_tgd = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_brdc_tgd.Tag = 'radiobutton_brdc_tgd';
            app.radiobutton_brdc_tgd.Tooltip = 'No correction for code biases';
            app.radiobutton_brdc_tgd.Text = 'Broadcasted TGD';
            app.radiobutton_models_biases_code_CAS_osb = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_CAS_osb.Tag = 'radiobutton_models_biases_code_CAS_osb';
            app.radiobutton_models_biases_code_CAS_osb.Tooltip = 'GRECJ, Multi-GNSS OSBs from Institute of Geodesy and Geophysics (IGG) of the Chinese Academy of Sciences (CAS)';
            app.radiobutton_models_biases_code_CAS_osb.Text = 'CAS Multi-GNSS OSBs';
            app.radiobutton_models_biases_code_WUM_MGEX = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_WUM_MGEX.Tag = 'radiobutton_models_biases_code_WUM_MGEX';
            app.radiobutton_models_biases_code_WUM_MGEX.Text = 'WUM MGEX';
            app.radiobutton_models_biases_code_CNES_MGEX = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_CNES_MGEX.Tag = 'radiobutton_models_biases_code_CNES_MGEX';
            app.radiobutton_models_biases_code_CNES_MGEX.Tooltip = 'GRC, OSBs from Center for Orbit Determination in Europe (CODE)';
            app.radiobutton_models_biases_code_CNES_MGEX.Text = 'CNES MGEX';
            app.radiobutton_models_biases_code_GFZ_MGEX = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_GFZ_MGEX.Tag = 'radiobutton_models_biases_code_GFZ_MGEX';
            app.radiobutton_models_biases_code_GFZ_MGEX.Tooltip = 'GRC, OSBs from Center for Orbit Determination in Europe (CODE)';
            app.radiobutton_models_biases_code_GFZ_MGEX.Text = 'GFZ MGEX';
            app.radiobutton_models_biases_code_CNES_OSB = uiradiobutton(app.buttongroup_models_biases_code);
            app.radiobutton_models_biases_code_CNES_OSB.Tag = 'radiobutton_models_biases_code_CNES_OSB';
            app.radiobutton_models_biases_code_CNES_OSB.Tooltip = 'GRC, OSBs from Center for Orbit Determination in Europe (CODE)';
            app.radiobutton_models_biases_code_CNES_OSB.Text = 'CNES OSBs';
            app.buttongroup_models_biases_phase = uibuttongroup(app.uipanel_biases);
            app.buttongroup_models_biases_phase.Title = '(Additional) Phase Biases Source ';
            app.buttongroup_models_biases_phase.Tag = 'buttongroup_models_biases_phase';
            app.radiobutton_models_biases_phase_TUW = uiradiobutton(app.buttongroup_models_biases_phase);
            app.radiobutton_models_biases_phase_TUW.Tag = 'radiobutton_models_biases_phase_TUW';
            app.radiobutton_models_biases_phase_TUW.Enable = 'off';
            app.radiobutton_models_biases_phase_TUW.Visible = 'off';
            app.radiobutton_models_biases_phase_TUW.Tooltip = 'Uncalibrated Phase Delays for Wide- and Narrow-Lane from TU Wien';
            app.radiobutton_models_biases_phase_TUW.Text = 'TUW (not implemented)';
            app.radiobutton_models_biases_phase_Wuhan = uiradiobutton(app.buttongroup_models_biases_phase);
            app.radiobutton_models_biases_phase_Wuhan.Tag = 'radiobutton_models_biases_phase_Wuhan';
            app.radiobutton_models_biases_phase_Wuhan.Tooltip = 'ftp://igs.gnsswhu.cn/pub/whu/phasebias/Readme.txt';
            app.radiobutton_models_biases_phase_Wuhan.Text = 'WHU phase/clock biases';
            app.radiobutton_models_biases_phase_CorrectionStream = uiradiobutton(app.buttongroup_models_biases_phase);
            app.radiobutton_models_biases_phase_CorrectionStream.Tag = 'radiobutton_models_biases_phase_CorrectionStream';
            app.radiobutton_models_biases_phase_CorrectionStream.Enable = 'off';
            app.radiobutton_models_biases_phase_CorrectionStream.Tooltip = 'Phase biases from (CNES) correction stream';
            app.radiobutton_models_biases_phase_CorrectionStream.Text = 'Correction Stream';
            app.radiobutton_models_biases_phase_manually = uiradiobutton(app.buttongroup_models_biases_phase);
            app.radiobutton_models_biases_phase_manually.Tag = 'radiobutton_models_biases_phase_manually';
            app.radiobutton_models_biases_phase_manually.Enable = 'off';
            app.radiobutton_models_biases_phase_manually.Visible = 'off';
            app.radiobutton_models_biases_phase_manually.Tooltip = 'Select TUW or Wuhan file manually';
            app.radiobutton_models_biases_phase_manually.Text = 'manually (not implemented)';
            app.radiobutton_models_biases_phase_off = uiradiobutton(app.buttongroup_models_biases_phase);
            app.radiobutton_models_biases_phase_off.Tag = 'radiobutton_models_biases_phase_off';
            app.radiobutton_models_biases_phase_off.Tooltip = 'No correction for phase biases';
            app.radiobutton_models_biases_phase_off.Text = 'off';
            app.radiobutton_models_biases_phase_off.Value = true;
            app.radiobutton_models_biases_phase_SGG = uiradiobutton(app.buttongroup_models_biases_phase);
            app.radiobutton_models_biases_phase_SGG.Tag = 'radiobutton_models_biases_phase_SGG';
            app.radiobutton_models_biases_phase_SGG.Tooltip = 'https://github.com/FCB-SGG/FCB-FILES';
            app.radiobutton_models_biases_phase_SGG.Text = 'SGG FCBs';
            app.buttongroup_models_biases_code_manually = uibuttongroup(app.uipanel_biases);
            app.buttongroup_models_biases_code_manually.Title = 'Manual file selection';
            app.buttongroup_models_biases_code_manually.Visible = 'off';
            app.buttongroup_models_biases_code_manually.Tag = 'buttongroup_models_biases_code_manually';
            app.radiobutton_models_biases_code_manually_DCBs = uiradiobutton(app.buttongroup_models_biases_code_manually);
            app.radiobutton_models_biases_code_manually_DCBs.Tag = 'radiobutton_models_biases_code_manually_DCBs';
            app.radiobutton_models_biases_code_manually_DCBs.Text = '*.DCB-File';
            app.text_dcb_P1P2 = uilabel(app.buttongroup_models_biases_code_manually);
            app.text_dcb_P1P2.Tag = 'text_dcb_P1P2';
            app.text_dcb_P1P2.Enable = 'off';
            app.text_dcb_P1P2.Text = 'P1P2:';
            app.edit_dcb_P1P2 = uieditfield(app.buttongroup_models_biases_code_manually, 'text');
            app.edit_dcb_P1P2.ValueChangedFcn = createCallbackFcn(app, @edit_dcb_P1P2_Callback, true);
            app.edit_dcb_P1P2.Tag = 'edit_dcb_P1P2';
            app.edit_dcb_P1P2.Enable = 'off';
            app.pushbutton_dcb_P1P2 = uibutton(app.buttongroup_models_biases_code_manually, 'push');
            app.pushbutton_dcb_P1P2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_dcb_P1P2_Callback, true);
            app.pushbutton_dcb_P1P2.Tag = 'pushbutton_dcb_P1P2';
            app.pushbutton_dcb_P1P2.Enable = 'off';
            app.pushbutton_dcb_P1P2.Text = '...';
            app.text_dcb_P1C1 = uilabel(app.buttongroup_models_biases_code_manually);
            app.text_dcb_P1C1.Tag = 'text_dcb_P1C1';
            app.text_dcb_P1C1.Enable = 'off';
            app.text_dcb_P1C1.Text = 'P1C1:';
            app.edit_dcb_P1C1 = uieditfield(app.buttongroup_models_biases_code_manually, 'text');
            app.edit_dcb_P1C1.ValueChangedFcn = createCallbackFcn(app, @edit_dcb_P1C1_Callback, true);
            app.edit_dcb_P1C1.Tag = 'edit_dcb_P1C1';
            app.edit_dcb_P1C1.Enable = 'off';
            app.pushbutton_dcb_P1C1 = uibutton(app.buttongroup_models_biases_code_manually, 'push');
            app.pushbutton_dcb_P1C1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_dcb_P1C1_Callback, true);
            app.pushbutton_dcb_P1C1.Tag = 'pushbutton_dcb_P1C1';
            app.pushbutton_dcb_P1C1.Enable = 'off';
            app.pushbutton_dcb_P1C1.Text = '...';
            app.radiobutton_models_biases_code_manually_Sinex = uiradiobutton(app.buttongroup_models_biases_code_manually);
            app.radiobutton_models_biases_code_manually_Sinex.Tag = 'radiobutton_models_biases_code_manually_Sinex';
            app.radiobutton_models_biases_code_manually_Sinex.Text = 'Sinex-Bias-File';
            app.radiobutton_models_biases_code_manually_Sinex.Value = true;
            app.edit_bias = uieditfield(app.buttongroup_models_biases_code_manually, 'text');
            app.edit_bias.ValueChangedFcn = createCallbackFcn(app, @edit_bias_Callback, true);
            app.edit_bias.Tag = 'edit_bias';
            app.pushbutton_bias = uibutton(app.buttongroup_models_biases_code_manually, 'push');
            app.pushbutton_bias.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_bias_Callback, true);
            app.pushbutton_bias.Tag = 'pushbutton_bias';
            app.pushbutton_bias.Text = '...';
            app.text_dcb_P2C2 = uilabel(app.buttongroup_models_biases_code_manually);
            app.text_dcb_P2C2.Tag = 'text_dcb_P2C2';
            app.text_dcb_P2C2.Enable = 'off';
            app.text_dcb_P2C2.Text = 'P2C2:';
            app.edit_dcb_P2C2 = uieditfield(app.buttongroup_models_biases_code_manually, 'text');
            app.edit_dcb_P2C2.ValueChangedFcn = createCallbackFcn(app, @edit_dcb_P2C2_Callback, true);
            app.edit_dcb_P2C2.Tag = 'edit_dcb_P2C2';
            app.edit_dcb_P2C2.Enable = 'off';
            app.pushbutton_dcb_P2C2 = uibutton(app.buttongroup_models_biases_code_manually, 'push');
            app.pushbutton_dcb_P2C2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_dcb_P2C2_Callback, true);
            app.pushbutton_dcb_P2C2.Tag = 'pushbutton_dcb_P2C2';
            app.pushbutton_dcb_P2C2.Enable = 'off';
            app.pushbutton_dcb_P2C2.Text = '...';
            app.uipanel_otherCorrections = uipanel(app.figure1);
            app.uipanel_otherCorrections.Title = 'Other Corrections';
            app.uipanel_otherCorrections.Tag = 'uipanel_otherCorrections';
            app.checkbox_sat_pco = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_sat_pco.ValueChangedFcn = createCallbackFcn(app, @checkbox_sat_pco_Callback, true);
            app.checkbox_sat_pco.Tag = 'checkbox_sat_pco';
            app.checkbox_sat_pco.Text = 'Satellite Phase Center Offset (for .sp3 orbits)';
            app.checkbox_sat_pco.Value = true;
            app.checkbox_rec_pco = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_rec_pco.ValueChangedFcn = createCallbackFcn(app, @checkbox_rec_pco_Callback, true);
            app.checkbox_rec_pco.Tag = 'checkbox_rec_pco';
            app.checkbox_rec_pco.Text = 'Receiver Phase Center Offset';
            app.checkbox_rec_pco.Value = true;
            app.checkbox_rec_ARP = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_rec_ARP.Tag = 'checkbox_rec_ARP';
            app.checkbox_rec_ARP.Text = 'Antenna Reference Point';
            app.checkbox_rec_ARP.Value = true;
            app.checkbox_solid_tides = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_solid_tides.Tag = 'checkbox_solid_tides';
            app.checkbox_solid_tides.Text = 'Solid Tides';
            app.checkbox_solid_tides.Value = true;
            app.checkbox_wind_up = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_wind_up.Tag = 'checkbox_wind_up';
            app.checkbox_wind_up.Text = 'Phase Wind-Up';
            app.checkbox_wind_up.Value = true;
            app.checkbox_CycleSlip_L1C1 = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_CycleSlip_L1C1.Tag = 'checkbox_CycleSlip_L1C1';
            app.checkbox_CycleSlip_L1C1.Text = 'L1-C1 Difference (slow!)';
            app.edit_CycleSlip_L1C1_threshold = uieditfield(app.uipanel_otherCorrections, 'text');
            app.edit_CycleSlip_L1C1_threshold.Tag = 'edit_CycleSlip_L1C1_threshold';
            app.edit_CycleSlip_L1C1_threshold.Value = '2';
            app.text115 = uilabel(app.uipanel_otherCorrections);
            app.text115.Tag = 'text115';
            app.text115.Text = 'Window Size [epochs]:';
            app.edit_CycleSlip_L1C1_window = uieditfield(app.uipanel_otherCorrections, 'text');
            app.edit_CycleSlip_L1C1_window.Tag = 'edit_CycleSlip_L1C1_window';
            app.edit_CycleSlip_L1C1_window.Value = '100';
            app.text116 = uilabel(app.uipanel_otherCorrections);
            app.text116.Tag = 'text116';
            app.text116.Text = 'Threshold [m]:';
            app.text117 = uilabel(app.uipanel_otherCorrections);
            app.text117.Tag = 'text117';
            app.text117.Text = 'Cycle-Slip Detection:';
            app.checkbox_CycleSlip_DF = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_CycleSlip_DF.Tag = 'checkbox_CycleSlip_DF';
            app.checkbox_CycleSlip_DF.Text = 'dLi-dLj Difference';
            app.checkbox_CycleSlip_DF.Value = true;
            app.text120 = uilabel(app.uipanel_otherCorrections);
            app.text120.Tag = 'text120';
            app.text120.Text = 'Threshold [m]:';
            app.edit_CycleSlip_DF_threshold = uieditfield(app.uipanel_otherCorrections, 'text');
            app.edit_CycleSlip_DF_threshold.Tag = 'edit_CycleSlip_DF_threshold';
            app.edit_CycleSlip_DF_threshold.Value = '0.05';
            app.checkbox_CycleSlip_Doppler = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_CycleSlip_Doppler.Tag = 'checkbox_CycleSlip_Doppler';
            app.checkbox_CycleSlip_Doppler.Text = 'Doppler-Shift';
            app.text182 = uilabel(app.uipanel_otherCorrections);
            app.text182.Tag = 'text182';
            app.text182.Text = 'Threshold [cy]:';
            app.edit_CycleSlip_Doppler_threshold = uieditfield(app.uipanel_otherCorrections, 'text');
            app.edit_CycleSlip_Doppler_threshold.Tag = 'edit_CycleSlip_Doppler_threshold';
            app.edit_CycleSlip_Doppler_threshold.Value = '0.5';
            app.checkbox_sat_pcv = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_sat_pcv.Tag = 'checkbox_sat_pcv';
            app.checkbox_sat_pcv.Text = 'Satellite Phase Center Variations (for .sp3 orbits)';
            app.checkbox_sat_pcv.Value = true;
            app.checkbox_rec_pcv = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_rec_pcv.Tag = 'checkbox_rec_pcv';
            app.checkbox_rec_pcv.Text = 'Receiver Phase Center Variations';
            app.checkbox_rec_pcv.Value = true;
            app.uibuttongroup_antex = uibuttongroup(app.uipanel_otherCorrections);
            app.uibuttongroup_antex.Title = 'ANTEX File';
            app.uibuttongroup_antex.Tag = 'uibuttongroup_antex';
            app.radiobutton_antex_existing = uiradiobutton(app.uibuttongroup_antex);
            app.radiobutton_antex_existing.Tag = 'radiobutton_antex_existing';
            app.radiobutton_antex_existing.Text = 'Use existing igsXX.atx';
            app.radiobutton_antex_existing.Value = true;
            app.radiobutton_antex_downl = uiradiobutton(app.uibuttongroup_antex);
            app.radiobutton_antex_downl.Tag = 'radiobutton_antex_downl';
            app.radiobutton_antex_downl.Text = 'Download current igsXX.atx';
            app.radiobutton_antex_manual = uiradiobutton(app.uibuttongroup_antex);
            app.radiobutton_antex_manual.Tag = 'radiobutton_antex_manual';
            app.radiobutton_antex_manual.Text = 'Manual choice:';
            app.edit_antex = uieditfield(app.uibuttongroup_antex, 'text');
            app.edit_antex.Tag = 'edit_antex';
            app.edit_antex.Enable = 'off';
            app.pushbutton_antex = uibutton(app.uibuttongroup_antex, 'push');
            app.pushbutton_antex.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_antex_Callback, true);
            app.pushbutton_antex.Tag = 'pushbutton_antex';
            app.pushbutton_antex.Enable = 'off';
            app.pushbutton_antex.Text = '...';
            app.text_rec_ant = uilabel(app.uibuttongroup_antex);
            app.text_rec_ant.Tag = 'text_rec_ant';
            app.text_rec_ant.Text = 'Receiver ANTEX:';
            app.checkbox_rec_antex = uicheckbox(app.uibuttongroup_antex);
            app.checkbox_rec_antex.Tag = 'checkbox_rec_antex';
            app.checkbox_rec_antex.Text = 'MyAntex.atx';
            app.checkbox_GDV = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_GDV.Tag = 'checkbox_GDV';
            app.checkbox_GDV.Text = 'Group Delay Variations';
            app.checkbox_ocean_loading = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_ocean_loading.Tag = 'checkbox_ocean_loading';
            app.checkbox_ocean_loading.Text = 'Ocean loading';
            app.checkbox_eclipse = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_eclipse.Tag = 'checkbox_eclipse';
            app.checkbox_eclipse.Text = 'Eclipse condition';
            app.checkbox_eclipse.Value = true;
            app.checkbox_LLI = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_LLI.Tag = 'checkbox_LLI';
            app.checkbox_LLI.Text = 'Use LLI of RINEX-File';
            app.checkbox_LLI.Value = true;
            app.checkbox_cs_td = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_cs_td.Tag = 'checkbox_cs_td';
            app.checkbox_cs_td.Text = 'Time difference';
            app.edit_cs_td_thresh = uieditfield(app.uipanel_otherCorrections, 'text');
            app.edit_cs_td_thresh.Tag = 'edit_cs_td_thresh';
            app.edit_cs_td_thresh.Value = '0.25';
            app.text_cs_td_degree = uilabel(app.uipanel_otherCorrections);
            app.text_cs_td_degree.Tag = 'text_cs_td_degree';
            app.text_cs_td_degree.Text = 'Degree:';
            app.edit_cs_td_degree = uieditfield(app.uipanel_otherCorrections, 'text');
            app.edit_cs_td_degree.Tag = 'edit_cs_td_degree';
            app.edit_cs_td_degree.Value = '3';
            app.text_cs_td_thresh = uilabel(app.uipanel_otherCorrections);
            app.text_cs_td_thresh.Tag = 'text_cs_td_thresh';
            app.text_cs_td_thresh.Text = 'Threshold [m]:';
            app.text321 = uilabel(app.uipanel_otherCorrections);
            app.text321.Tag = 'text321';
            app.text321.Text = 'Multipath Detection:';
            app.checkbox_mp_detection = uicheckbox(app.uipanel_otherCorrections);
            app.checkbox_mp_detection.Tag = 'checkbox_mp_detection';
            app.checkbox_mp_detection.Text = 'Code difference';
            app.edit_mp_thresh = uieditfield(app.uipanel_otherCorrections, 'text');
            app.edit_mp_thresh.Tag = 'edit_mp_thresh';
            app.edit_mp_thresh.Value = '1';
            app.text_mp_degree = uilabel(app.uipanel_otherCorrections);
            app.text_mp_degree.Tag = 'text_mp_degree';
            app.text_mp_degree.Text = 'Degree:';
            app.edit_mp_degree = uieditfield(app.uipanel_otherCorrections, 'text');
            app.edit_mp_degree.Tag = 'edit_mp_degree';
            app.edit_mp_degree.Value = '3';
            app.text_mp_thresh = uilabel(app.uipanel_otherCorrections);
            app.text_mp_thresh.Tag = 'text_mp_thresh';
            app.text_mp_thresh.Text = 'Threshold [m]:';
            app.text_mp_cooldown = uilabel(app.uipanel_otherCorrections);
            app.text_mp_cooldown.Tag = 'text_mp_cooldown';
            app.text_mp_cooldown.Text = 'Cooldown [s]:';
            app.edit_mp_cooldown = uieditfield(app.uipanel_otherCorrections, 'text');
            app.edit_mp_cooldown.Tag = 'edit_mp_cooldown';
            app.edit_mp_cooldown.Value = '600';
            app.uipanel_export = uipanel(app.figure1);
            app.uipanel_export.Title = 'Export';
            app.uipanel_export.Visible = 'off';
            app.uipanel_export.Tag = 'uipanel_export';
            app.checkbox_exp_model_save = uicheckbox(app.uipanel_export);
            app.checkbox_exp_model_save.Tag = 'checkbox_exp_model_save';
            app.checkbox_exp_model_save.Text = 'export model_save (data4plot.mat)';
            app.text_exp_obs = uilabel(app.uipanel_export);
            app.text_exp_obs.Tag = 'text_exp_obs';
            app.text_exp_obs.Text = 'obs';
            app.text_exp_output = uilabel(app.uipanel_export);
            app.text_exp_output.Tag = 'text_exp_output';
            app.text_exp_output.Text = 'Output';
            app.checkbox_exp_data4plot = uicheckbox(app.uipanel_export);
            app.checkbox_exp_data4plot.Tag = 'checkbox_exp_data4plot';
            app.checkbox_exp_data4plot.Text = 'data4plot.mat';
            app.checkbox_exp_data4plot.Value = true;
            app.checkbox_exp_results_float = uicheckbox(app.uipanel_export);
            app.checkbox_exp_results_float.Tag = 'checkbox_exp_results_float';
            app.checkbox_exp_results_float.Text = 'results_float.txt';
            app.checkbox_exp_results_float.Value = true;
            app.checkbox_exp_results_fixed = uicheckbox(app.uipanel_export);
            app.checkbox_exp_results_fixed.Tag = 'checkbox_exp_results_fixed';
            app.checkbox_exp_results_fixed.Text = 'results_fixed.txt';
            app.checkbox_exp_results_fixed.Value = true;
            app.checkbox_exp_settings = uicheckbox(app.uipanel_export);
            app.checkbox_exp_settings.Tag = 'checkbox_exp_settings';
            app.checkbox_exp_settings.Text = 'settings.mat';
            app.checkbox_exp_settings.Value = true;
            app.checkbox_exp_settings_summary = uicheckbox(app.uipanel_export);
            app.checkbox_exp_settings_summary.Tag = 'checkbox_exp_settings_summary';
            app.checkbox_exp_settings_summary.Text = 'settings_summary.txt';
            app.checkbox_exp_settings_summary.Value = true;
            app.text_exp_variables = uilabel(app.uipanel_export);
            app.text_exp_variables.Tag = 'text_exp_variables';
            app.text_exp_variables.Text = 'Variables';
            app.checkbox_exp_obs_bias = uicheckbox(app.uipanel_export);
            app.checkbox_exp_obs_bias.Tag = 'checkbox_exp_obs_bias';
            app.checkbox_exp_obs_bias.Text = '.C1_/.../L1_/.../_bias';
            app.checkbox_exp_obs_epochheader = uicheckbox(app.uipanel_export);
            app.checkbox_exp_obs_epochheader.Tag = 'checkbox_exp_obs_epochheader';
            app.checkbox_exp_obs_epochheader.Text = '.epochheader';
            app.checkbox_exp_storeData_mp_1_2 = uicheckbox(app.uipanel_export);
            app.checkbox_exp_storeData_mp_1_2.Tag = 'checkbox_exp_storeData_mp_1_2';
            app.checkbox_exp_storeData_mp_1_2.Text = '.mp1, .mp2';
            app.checkbox_exp_storeData_mp_1_2.Value = true;
            app.checkbox_exp_storeData_vtec = uicheckbox(app.uipanel_export);
            app.checkbox_exp_storeData_vtec.Tag = 'checkbox_exp_storeData_vtec';
            app.checkbox_exp_storeData_vtec.Text = '.vtec';
            app.checkbox_exp_storeData_iono_mf = uicheckbox(app.uipanel_export);
            app.checkbox_exp_storeData_iono_mf.Tag = 'checkbox_exp_storeData_iono_mf';
            app.checkbox_exp_storeData_iono_mf.Text = '.iono_mf';
            app.checkbox_exp_storeData = uicheckbox(app.uipanel_export);
            app.checkbox_exp_storeData.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.checkbox_exp_storeData.Tag = 'checkbox_exp_storeData';
            app.checkbox_exp_storeData.Text = 'storeData';
            app.checkbox_exp_storeData.Value = true;
            app.checkbox_exp_satellites = uicheckbox(app.uipanel_export);
            app.checkbox_exp_satellites.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.checkbox_exp_satellites.Tag = 'checkbox_exp_satellites';
            app.checkbox_exp_satellites.Text = 'satellites';
            app.checkbox_exp_satellites.Value = true;
            app.checkbox_exp_tropo_zpd = uicheckbox(app.uipanel_export);
            app.checkbox_exp_tropo_zpd.Tag = 'checkbox_exp_tropo_zpd';
            app.checkbox_exp_tropo_zpd.Text = 'write TROpo file';
            app.checkbox_exp_nmea = uicheckbox(app.uipanel_export);
            app.checkbox_exp_nmea.Tag = 'checkbox_exp_nmea';
            app.checkbox_exp_nmea.Text = 'write nmea file';
            app.checkbox_exp_kml = uicheckbox(app.uipanel_export);
            app.checkbox_exp_kml.Tag = 'checkbox_exp_kml';
            app.checkbox_exp_kml.Text = 'write kml file';
            app.checkbox_exp_satellites_D = uicheckbox(app.uipanel_export);
            app.checkbox_exp_satellites_D.Tag = 'checkbox_exp_satellites_D';
            app.checkbox_exp_satellites_D.Text = '.D1, .D2, .D3';
            app.uipanel_single_plot = uipanel(app.figure1);
            app.uipanel_single_plot.Title = 'Single plot';
            app.uipanel_single_plot.Visible = 'off';
            app.uipanel_single_plot.Tag = 'uipanel_single_plot';
            app.checkbox_plot_residuals = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_residuals.Tag = 'checkbox_plot_residuals';
            app.checkbox_plot_residuals.Text = 'Residual Plots (Code&Phase)';
            app.checkbox_plot_coordinate = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_coordinate.Tag = 'checkbox_plot_coordinate';
            app.checkbox_plot_coordinate.Text = 'Coordinate Plot';
            app.checkbox_plot_coordinate.Value = true;
            app.checkbox_plot_googlemaps = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_googlemaps.Tag = 'checkbox_plot_googlemaps';
            app.checkbox_plot_googlemaps.Text = 'Map Plot';
            app.checkbox_plot_xyz = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_xyz.Tag = 'checkbox_plot_xyz';
            app.checkbox_plot_xyz.Text = 'Three Coordinates Plot';
            app.checkbox_plot_sat_visibility = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_sat_visibility.Tag = 'checkbox_plot_sat_visibility';
            app.checkbox_plot_sat_visibility.Text = 'Satellite Visibility Plot';
            app.checkbox_plot_elev = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_elev.Tag = 'checkbox_plot_elev';
            app.checkbox_plot_elev.Text = 'Elevation Plot';
            app.checkbox_plot_fixed_amb = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_fixed_amb.Tag = 'checkbox_plot_fixed_amb';
            app.checkbox_plot_fixed_amb.Text = 'Fixed Ambiguity Plots';
            app.checkbox_plot_float_amb = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_float_amb.Tag = 'checkbox_plot_float_amb';
            app.checkbox_plot_float_amb.Text = 'Float Ambiguity Plots';
            app.checkbox_plot_cov_amb = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_cov_amb.Tag = 'checkbox_plot_cov_amb';
            app.checkbox_plot_cov_amb.Text = 'Standard Deviation of Ambiguities';
            app.checkbox_plot_cov_info = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_cov_info.Tag = 'checkbox_plot_cov_info';
            app.checkbox_plot_cov_info.Text = 'Standard Deviation of Parameters';
            app.checkbox_plot_wet_tropo = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_wet_tropo.Tag = 'checkbox_plot_wet_tropo';
            app.checkbox_plot_wet_tropo.Text = 'Troposphere Plot';
            app.checkbox_plot_clock = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_clock.Tag = 'checkbox_plot_clock';
            app.checkbox_plot_clock.Text = 'Clock Plot';
            app.checkbox_plot_corr = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_corr.Tag = 'checkbox_plot_corr';
            app.checkbox_plot_corr.Text = 'Correlation Plot';
            app.checkbox_plot_signal_qual = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_signal_qual.Tag = 'checkbox_plot_signal_qual';
            app.checkbox_plot_signal_qual.Text = 'Signal Quality';
            app.checkbox_plot_res_sats = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_res_sats.Tag = 'checkbox_plot_res_sats';
            app.checkbox_plot_res_sats.Text = 'Residuals for each Satellite';
            app.checkbox_plot_skyplot = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_skyplot.Tag = 'checkbox_plot_skyplot';
            app.checkbox_plot_skyplot.Text = 'Satellite Skyplot';
            app.checkbox_plot_DOP = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_DOP.Tag = 'checkbox_plot_DOP';
            app.checkbox_plot_DOP.Text = 'DOP Plot';
            app.pushbutton_plot = uibutton(app.uipanel_single_plot, 'push');
            app.pushbutton_plot.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plot_Callback, true);
            app.pushbutton_plot.Tag = 'pushbutton_plot';
            app.pushbutton_plot.Text = 'Plot';
            app.checkbox_plot_iono = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_iono.Tag = 'checkbox_plot_iono';
            app.checkbox_plot_iono.Text = 'Ionospheric Correction Plot';
            app.checkbox_plot_cs = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_cs.Tag = 'checkbox_plot_cs';
            app.checkbox_plot_cs.Text = 'Cycle-Slip Detection Plot';
            app.checkbox_plot_stream_corr = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_stream_corr.Tag = 'checkbox_plot_stream_corr';
            app.checkbox_plot_stream_corr.Text = 'Stream Corrections Plot';
            app.uibuttongroup_plot_float_fixed = uibuttongroup(app.uipanel_single_plot);
            app.uibuttongroup_plot_float_fixed.Title = 'Plot results from';
            app.uibuttongroup_plot_float_fixed.Tag = 'uibuttongroup_plot_float_fixed';
            app.radiobutton_plot_float = uiradiobutton(app.uibuttongroup_plot_float_fixed);
            app.radiobutton_plot_float.Tag = 'radiobutton_plot_float';

            app.radiobutton_plot_float.Text = 'Float solution';
            app.radiobutton_plot_float.Value = true;
            app.radiobutton_plot_fixed = uiradiobutton(app.uibuttongroup_plot_float_fixed);
            app.radiobutton_plot_fixed.Tag = 'radiobutton_plot_fixed';
            app.radiobutton_plot_fixed.Text = 'Fixed solution';
            app.checkbox_plot_gps = uicheckbox(app.uibuttongroup_plot_float_fixed);
            app.checkbox_plot_gps.Tag = 'checkbox_plot_gps';
            app.checkbox_plot_gps.Text = 'GPS';
            app.checkbox_plot_glo = uicheckbox(app.uibuttongroup_plot_float_fixed);
            app.checkbox_plot_glo.Tag = 'checkbox_plot_glo';
            app.checkbox_plot_glo.Text = 'Glonass';
            app.checkbox_plot_gal = uicheckbox(app.uibuttongroup_plot_float_fixed);
            app.checkbox_plot_gal.Tag = 'checkbox_plot_gal';
            app.checkbox_plot_gal.Text = 'Galileo';
            app.checkbox_plot_bds = uicheckbox(app.uibuttongroup_plot_float_fixed);
            app.checkbox_plot_bds.Tag = 'checkbox_plot_bds';
            app.checkbox_plot_bds.Text = 'BeiDou';
            app.edit_plot_path = uieditfield(app.uipanel_single_plot, 'text');
            app.edit_plot_path.ValueChangedFcn = createCallbackFcn(app, @edit_plot_path_Callback, true);
            app.edit_plot_path.Tag = 'edit_plot_path';
            app.pushbutton_plot_path = uibutton(app.uipanel_single_plot, 'push');
            app.pushbutton_plot_path.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plot_path_Callback, true);
            app.pushbutton_plot_path.Tag = 'pushbutton_plot_path';
            app.pushbutton_plot_path.Text = '...';
            app.text_plot_path = uilabel(app.uipanel_single_plot);
            app.text_plot_path.Tag = 'text_plot_path';
            app.text_plot_path.Text = 'File to plot:';
            app.pushbutton_uncheck_all = uibutton(app.uipanel_single_plot, 'push');
            app.pushbutton_uncheck_all.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_uncheck_all_Callback, true);
            app.pushbutton_uncheck_all.Tag = 'pushbutton_uncheck_all';
            app.pushbutton_uncheck_all.Text = 'Uncheck all';
            app.pushbutton_check_all = uibutton(app.uipanel_single_plot, 'push');
            app.pushbutton_check_all.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_check_all_Callback, true);
            app.pushbutton_check_all.Tag = 'pushbutton_check_all';
            app.pushbutton_check_all.Text = 'Check all';
            app.text_pos_true = uilabel(app.uipanel_single_plot);
            app.text_pos_true.Tag = 'text_pos_true';
            app.text_pos_true.Text = 'True Position';
            app.edit_x_true = uieditfield(app.uipanel_single_plot, 'text');
            app.edit_x_true.Tag = 'edit_x_true';
            app.text_x_true = uilabel(app.uipanel_single_plot);
            app.text_x_true.Tag = 'text_x_true';
            app.text_x_true.Text = 'X-Coordinate:';
            app.text139 = uilabel(app.uipanel_single_plot);
            app.text139.Tag = 'text139';
            app.text139.Text = '[m]';
            app.edit_y_true = uieditfield(app.uipanel_single_plot, 'text');
            app.edit_y_true.Tag = 'edit_y_true';
            app.text_y_true = uilabel(app.uipanel_single_plot);
            app.text_y_true.Tag = 'text_y_true';
            app.text_y_true.Text = 'Y-Coordinate:';
            app.text141 = uilabel(app.uipanel_single_plot);
            app.text141.Tag = 'text141';
            app.text141.Text = '[m]';
            app.text_z_true = uilabel(app.uipanel_single_plot);
            app.text_z_true.Tag = 'text_z_true';
            app.text_z_true.Text = 'Z-Coordinate:';
            app.text143 = uilabel(app.uipanel_single_plot);
            app.text143.Tag = 'text143';
            app.text143.Text = '[m]';
            app.edit_z_true = uieditfield(app.uipanel_single_plot, 'text');
            app.edit_z_true.Tag = 'edit_z_true';
            app.pushbutton_load_pos_true = uibutton(app.uipanel_single_plot, 'push');
            app.pushbutton_load_pos_true.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_pos_true_Callback, true);
            app.pushbutton_load_pos_true.Tag = 'pushbutton_load_pos_true';
            app.pushbutton_load_pos_true.Enable = 'off';
            app.pushbutton_load_pos_true.Text = 'Load static';
            app.text237 = uilabel(app.uipanel_single_plot);
            app.text237.Tag = 'text237';
            app.text237.Text = 'Estimated Parameters';
            app.text238 = uilabel(app.uipanel_single_plot);
            app.text238.Tag = 'text238';
            app.text238.Text = 'Satellite Geometry';
            app.text239 = uilabel(app.uipanel_single_plot);
            app.text239.Tag = 'text239';
            app.text239.Text = 'Observations and Models';
            app.checkbox_plot_appl_biases = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_appl_biases.Tag = 'checkbox_plot_appl_biases';
            app.checkbox_plot_appl_biases.Text = 'Applied Biases Plot';
            app.checkbox_singlemultiplot = uicheckbox(app.uipanel_single_plot);
            app.checkbox_singlemultiplot.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.checkbox_singlemultiplot.Tag = 'checkbox_singlemultiplot';
            app.checkbox_singlemultiplot.Text = 'Use Multi-Plot-Table';
            app.checkbox_plot_UTM = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_UTM.Tag = 'checkbox_plot_UTM';
            app.checkbox_plot_UTM.Text = 'UTM Plot';
            app.checkbox_plot_dcb = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_dcb.Tag = 'checkbox_plot_dcb';
            app.checkbox_plot_dcb.Text = 'DCB Plot';
            app.checkbox_plot_GI = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_GI.Tag = 'checkbox_plot_GI';
            app.checkbox_plot_GI.Enable = 'off';
            app.checkbox_plot_GI.Visible = 'off';
            app.checkbox_plot_GI.Text = 'empty';
            app.checkbox_plot_mp = uicheckbox(app.uipanel_single_plot);
            app.checkbox_plot_mp.Tag = 'checkbox_plot_mp';
            app.checkbox_plot_mp.Text = 'Multipath Detection';
            app.pushbutton_load_true_kinematic = uibutton(app.uipanel_single_plot, 'push');
            app.pushbutton_load_true_kinematic.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_true_kinematic_Callback, true);
            app.pushbutton_load_true_kinematic.Tag = 'pushbutton_load_true_kinematic';
            app.pushbutton_load_true_kinematic.Enable = 'off';
            app.pushbutton_load_true_kinematic.Text = 'Trajectory';
            app.uipanel_ambiguityFixing = uipanel(app.figure1);
            app.uipanel_ambiguityFixing.Title = 'PPP with Ambiguity Resolution';
            app.uipanel_ambiguityFixing.Tag = 'uipanel_ambiguityFixing';
            app.text_start_WL = uilabel(app.uipanel_ambiguityFixing);
            app.text_start_WL.Tag = 'text_start_WL';
            app.text_start_WL.Visible = 'off';
            app.text_start_WL.Text = 'Start WL-Fixing after                        seconds';
            app.text_start_NL = uilabel(app.uipanel_ambiguityFixing);
            app.text_start_NL.Tag = 'text_start_NL';
            app.text_start_NL.Visible = 'off';
            app.text_start_NL.Text = 'Start other fixing after                          seconds';
            app.edit_start_WL = uieditfield(app.uipanel_ambiguityFixing, 'text');
            app.edit_start_WL.Tag = 'edit_start_WL';
            app.edit_start_WL.Visible = 'off';
            app.edit_start_WL.Value = '90';
            app.edit_start_NL = uieditfield(app.uipanel_ambiguityFixing, 'text');
            app.edit_start_NL.Tag = 'edit_start_NL';
            app.edit_start_NL.Visible = 'off';
            app.edit_start_NL.Value = '120';
            app.checkbox_fixing = uicheckbox(app.uipanel_ambiguityFixing);
            app.checkbox_fixing.ValueChangedFcn = createCallbackFcn(app, @checkbox_fixing_Callback, true);
            app.checkbox_fixing.Tag = 'checkbox_fixing';
            app.checkbox_fixing.Text = 'Fix ambiguities';
            app.uibuttongroup_refSat = uibuttongroup(app.uipanel_ambiguityFixing);
            app.uibuttongroup_refSat.Title = 'Choice of Reference Satellite';
            app.uibuttongroup_refSat.Visible = 'off';
            app.uibuttongroup_refSat.Tag = 'uibuttongroup_refSat';
            app.radiobutton_refSat_high = uiradiobutton(app.uibuttongroup_refSat);
            app.radiobutton_refSat_high.Tag = 'radiobutton_refSat_high';
            app.radiobutton_refSat_high.Text = 'Highest satellite';
            app.radiobutton_refSat_high.Value = true;
            app.radiobutton_refSat_manually = uiradiobutton(app.uibuttongroup_refSat);
            app.radiobutton_refSat_manually.Tag = 'radiobutton_refSat_manually';
            app.radiobutton_refSat_manually.Text = 'manual choice (list):';
            app.radiobutton_refSat = uiradiobutton(app.uibuttongroup_refSat);
            app.radiobutton_refSat.Tag = 'radiobutton_refSat';
            app.radiobutton_refSat.Visible = 'off';
            app.radiobutton_refSat.Text = '???';
            app.text_refSatGPS = uilabel(app.uibuttongroup_refSat);
            app.text_refSatGPS.Tag = 'text_refSatGPS';
            app.text_refSatGPS.Enable = 'off';
            app.text_refSatGPS.Text = 'GPS:';
            app.text_refSatGAL = uilabel(app.uibuttongroup_refSat);
            app.text_refSatGAL.Tag = 'text_refSatGAL';
            app.text_refSatGAL.Enable = 'off';
            app.text_refSatGAL.Text = 'Galileo:';
            app.edit_refSatGPS = uieditfield(app.uibuttongroup_refSat, 'text');
            app.edit_refSatGPS.Tag = 'edit_refSatGPS';
            app.edit_refSatGPS.Enable = 'off';
            app.edit_refSatGAL = uieditfield(app.uibuttongroup_refSat, 'text');
            app.edit_refSatGAL.Tag = 'edit_refSatGAL';
            app.edit_refSatGAL.Enable = 'off';
            app.edit_refSatBDS = uieditfield(app.uibuttongroup_refSat, 'text');
            app.edit_refSatBDS.Tag = 'edit_refSatBDS';
            app.edit_refSatBDS.Enable = 'off';
            app.text_refSatBDS = uilabel(app.uibuttongroup_refSat);
            app.text_refSatBDS.Tag = 'text_refSatBDS';
            app.text_refSatBDS.Enable = 'off';
            app.text_refSatBDS.Text = 'BeiDou:';
            app.uibuttongroup_wrongFixes = uibuttongroup(app.uipanel_ambiguityFixing);
            app.uibuttongroup_wrongFixes.Title = 'Detection of Wrong Fixes';
            app.uibuttongroup_wrongFixes.Visible = 'off';
            app.uibuttongroup_wrongFixes.Tag = 'uibuttongroup_wrongFixes';
            app.radiobutton_wrongFixes_diff = uiradiobutton(app.uibuttongroup_wrongFixes);
            app.radiobutton_wrongFixes_diff.Tag = 'radiobutton_wrongFixes_diff';
            app.radiobutton_wrongFixes_diff.Text = 'Difference to float solution';
            app.radiobutton_wrongFixes_vTPv = uiradiobutton(app.uibuttongroup_wrongFixes);
            app.radiobutton_wrongFixes_vTPv.Tag = 'radiobutton_wrongFixes_vTPv';
            app.radiobutton_wrongFixes_vTPv.Text = 'vTPv';
            app.radiobutton_wrongFixes_3 = uiradiobutton(app.uibuttongroup_wrongFixes);
            app.radiobutton_wrongFixes_3.Tag = 'radiobutton_wrongFixes_3';
            app.radiobutton_wrongFixes_3.Text = '?????';
            app.radiobutton_wrongFixes_off = uiradiobutton(app.uibuttongroup_wrongFixes);
            app.radiobutton_wrongFixes_off.Tag = 'radiobutton_wrongFixes_off';
            app.radiobutton_wrongFixes_off.Text = 'Off';
            app.radiobutton_wrongFixes_off.Value = true;
            app.edit_pppar_cutoff = uieditfield(app.uipanel_ambiguityFixing, 'text');
            app.edit_pppar_cutoff.Tag = 'edit_pppar_cutoff';
            app.edit_pppar_cutoff.Value = '10';
            app.text_pppar_cutoff = uilabel(app.uipanel_ambiguityFixing);
            app.text_pppar_cutoff.Tag = 'text_pppar_cutoff';
            app.text_pppar_cutoff.Text = 'Elevation Cutoff for Fixing [°]:';
            app.edit_pppar_excl_sats = uieditfield(app.uipanel_ambiguityFixing, 'text');
            app.edit_pppar_excl_sats.Tag = 'edit_pppar_excl_sats';
            app.text_pppar_excl_sats = uilabel(app.uipanel_ambiguityFixing);
            app.text_pppar_excl_sats.Tag = 'text_pppar_excl_sats';
            app.text_pppar_excl_sats.Text = 'Exclude satellites manually from fixing:';
            app.text_hmw_fixing = uilabel(app.uipanel_ambiguityFixing);
            app.text_hmw_fixing.Tag = 'text_hmw_fixing';
            app.text_hmw_fixing.Text = 'HMW Fixing';
            app.edit_hmw_release = uieditfield(app.uipanel_ambiguityFixing, 'text');
            app.edit_hmw_release.Tag = 'edit_hmw_release';
            app.edit_hmw_release.Visible = 'off';
            app.edit_hmw_release.Value = '0.50';
            app.edit_hmw_thresh = uieditfield(app.uipanel_ambiguityFixing, 'text');
            app.edit_hmw_thresh.Tag = 'edit_hmw_thresh';
            app.edit_hmw_thresh.Visible = 'off';
            app.edit_hmw_thresh.Value = '0.33';
            app.text_hmw_thresh = uilabel(app.uipanel_ambiguityFixing);
            app.text_hmw_thresh.Tag = 'text_hmw_thresh';
            app.text_hmw_thresh.Visible = 'off';
            app.text_hmw_thresh.Text = 'Fixing threshold [cy]';
            app.text_hmw_release = uilabel(app.uipanel_ambiguityFixing);
            app.text_hmw_release.Tag = 'text_hmw_release';
            app.text_hmw_release.Visible = 'off';
            app.text_hmw_release.Text = 'Releasing threshold [cy]';
            app.text_fixing_window = uilabel(app.uipanel_ambiguityFixing);
            app.text_fixing_window.Tag = 'text_fixing_window';
            app.text_fixing_window.Visible = 'off';
            app.text_fixing_window.Text = 'Fixing window [s]';
            app.edit_fixing_window = uieditfield(app.uipanel_ambiguityFixing, 'text');
            app.edit_fixing_window.Tag = 'edit_fixing_window';
            app.edit_fixing_window.Visible = 'off';
            app.edit_fixing_window.Value = '300';
            app.uipanel_ionosphere = uipanel(app.figure1);
            app.uipanel_ionosphere.Title = 'Ionosphere';
            app.uipanel_ionosphere.Visible = 'off';
            app.uipanel_ionosphere.Tag = 'uipanel_ionosphere';
            app.buttongroup_models_ionosphere = uibuttongroup(app.uipanel_ionosphere);
            app.buttongroup_models_ionosphere.SelectionChangedFcn = createCallbackFcn(app, @buttongroup_models_ionosphere_SelectionChangeFcn, true);
            app.buttongroup_models_ionosphere.Title = 'Model';
            app.buttongroup_models_ionosphere.Tag = 'buttongroup_models_ionosphere';
            app.radiobutton_models_ionosphere_off = uiradiobutton(app.buttongroup_models_ionosphere);
            app.radiobutton_models_ionosphere_off.Tag = 'radiobutton_models_ionosphere_off';
            app.radiobutton_models_ionosphere_off.Tooltip = 'No correction for ionospheric delay is used.';
            app.radiobutton_models_ionosphere_off.Text = 'off';
            app.radiobutton_models_ionosphere_estimate = uiradiobutton(app.buttongroup_models_ionosphere);
            app.radiobutton_models_ionosphere_estimate.Tag = 'radiobutton_models_ionosphere_estimate';
            app.radiobutton_models_ionosphere_estimate.Tooltip = 'For Single-Frequency-Processing: the ionospheric delay is corrected using an Ionex-file. ';
            app.radiobutton_models_ionosphere_estimate.Text = 'Estimate';
            app.radiobutton_models_ionosphere_correct = uiradiobutton(app.buttongroup_models_ionosphere);
            app.radiobutton_models_ionosphere_correct.Tag = 'radiobutton_models_ionosphere_correct';
            app.radiobutton_models_ionosphere_correct.Tooltip = 'For Single-Frequency-Processing: the ionospheric delay is corrected using an Ionex-file. ';
            app.radiobutton_models_ionosphere_correct.Text = 'Correct with ...';
            app.radiobutton_models_ionosphere_estimateVTEC = uiradiobutton(app.buttongroup_models_ionosphere);
            app.radiobutton_models_ionosphere_estimateVTEC.Tag = 'radiobutton_models_ionosphere_estimateVTEC';
            app.radiobutton_models_ionosphere_estimateVTEC.Tooltip = {'The VTEC is estimated during the processing using an Ionex-file as constraint.'};
            app.radiobutton_models_ionosphere_estimateVTEC.Text = 'Estimate VTEC';
            app.radiobutton_models_ionosphere_estimateConstraint = uiradiobutton(app.buttongroup_models_ionosphere);
            app.radiobutton_models_ionosphere_estimateConstraint.Tag = 'radiobutton_models_ionosphere_estimateConstraint';
            app.radiobutton_models_ionosphere_estimateConstraint.Tooltip = 'The ionospheric delay is estimated during the processing using an Ionex-file as constraint.';
            app.radiobutton_models_ionosphere_estimateConstraint.Text = 'Estimate with ... as constraint';
            app.radiobutton_models_ionosphere_3freq = uiradiobutton(app.buttongroup_models_ionosphere);
            app.radiobutton_models_ionosphere_3freq.Tag = 'radiobutton_models_ionosphere_3freq';
            app.radiobutton_models_ionosphere_3freq.Tooltip = 'Ionosphere-Free Linear-Combination of three frequencies is used the remove the effect of the ionosphere.';
            app.radiobutton_models_ionosphere_3freq.Text = '3-Frequency-IF-LC';
            app.radiobutton_models_ionosphere_2freq = uiradiobutton(app.buttongroup_models_ionosphere);
            app.radiobutton_models_ionosphere_2freq.Tag = 'radiobutton_models_ionosphere_2freq';
            app.radiobutton_models_ionosphere_2freq.Tooltip = 'Ionosphere-Free Linear-Combination of two frequencies is used the remove the effect of the ionosphere.';
            app.radiobutton_models_ionosphere_2freq.Text = '2-Frequency-IF-LCs';
            app.radiobutton_models_ionosphere_2freq.Value = true;
            app.buttongroup_models_ionosphere_ionex = uibuttongroup(app.uipanel_ionosphere);
            app.buttongroup_models_ionosphere_ionex.SelectionChangedFcn = createCallbackFcn(app, @buttongroup_models_ionosphere_ionex_SelectionChangeFcn, true);
            app.buttongroup_models_ionosphere_ionex.Title = 'Ionex file';
            app.buttongroup_models_ionosphere_ionex.Visible = 'off';
            app.buttongroup_models_ionosphere_ionex.Tag = 'buttongroup_models_ionosphere_ionex';
            app.radiobutton_models_ionosphere_ionex_manually = uiradiobutton(app.buttongroup_models_ionosphere_ionex);
            app.radiobutton_models_ionosphere_ionex_manually.Tag = 'radiobutton_models_ionosphere_ionex_manually';
            app.radiobutton_models_ionosphere_ionex_manually.Text = 'manually:';
            app.radiobutton_models_ionosphere_ionex_manually.Value = true;
            app.pushbutton_ionex = uibutton(app.buttongroup_models_ionosphere_ionex, 'push');
            app.pushbutton_ionex.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_ionex_Callback, true);
            app.pushbutton_ionex.Tag = 'pushbutton_ionex';
            app.pushbutton_ionex.Enable = 'off';
            app.pushbutton_ionex.Text = '...';
            app.edit_ionex = uieditfield(app.buttongroup_models_ionosphere_ionex, 'text');
            app.edit_ionex.ValueChangedFcn = createCallbackFcn(app, @edit_ionex_Callback, true);
            app.edit_ionex.Tag = 'edit_ionex';
            app.edit_ionex.Enable = 'off';
            app.radiobutton_models_ionosphere_ionex_autodetect = uiradiobutton(app.buttongroup_models_ionosphere_ionex);
            app.radiobutton_models_ionosphere_ionex_autodetect.Tag = 'radiobutton_models_ionosphere_ionex_autodetect';
            app.radiobutton_models_ionosphere_ionex_autodetect.Text = 'Auto-Detection:';
            app.edit_iono_autodetect = uieditfield(app.buttongroup_models_ionosphere_ionex, 'text');
            app.edit_iono_autodetect.Tag = 'edit_iono_autodetect';
            app.edit_iono_autodetect.Enable = 'off';
            app.radiobutton_models_ionosphere_source = uiradiobutton(app.buttongroup_models_ionosphere_ionex);
            app.radiobutton_models_ionosphere_source.Tag = 'radiobutton_models_ionosphere_source';
            app.radiobutton_models_ionosphere_source.Text = 'Source:';
            app.popupmenu_iono_source = uidropdown(app.buttongroup_models_ionosphere_ionex);
            app.popupmenu_iono_source.Items = {'IGS', 'CODE', 'CAS', 'ESA', 'GFZ', 'JPL', 'NRCAN', 'UPC', 'WHU', 'IGS RT GIM', 'Regiomontan', 'GIOMO', 'GIOMO predicted'};
            app.popupmenu_iono_source.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.popupmenu_iono_source.Tag = 'popupmenu_iono_source';
            app.popupmenu_iono_source.Value = 'IGS';
            app.text_iono_interpol = uilabel(app.buttongroup_models_ionosphere_ionex);
            app.text_iono_interpol.Tag = 'text_iono_interpol';
            app.text_iono_interpol.Visible = 'off';
            app.text_iono_interpol.Text = 'TEC-Interpolation:';
            app.popupmenu_iono_interpol = uidropdown(app.buttongroup_models_ionosphere_ionex);
            app.popupmenu_iono_interpol.Items = {'Nearest Map', 'Consecutive Maps', 'Nearest Rotated Map', 'Consecutive Rotated Maps'};
            app.popupmenu_iono_interpol.Tag = 'popupmenu_iono_interpol';
            app.popupmenu_iono_interpol.Visible = 'off';
            app.popupmenu_iono_interpol.Value = 'Consecutive Rotated Maps';
            app.buttongroup_models_ionosphere_autodetect = uibuttongroup(app.uipanel_ionosphere);
            app.buttongroup_models_ionosphere_autodetect.SelectionChangedFcn = createCallbackFcn(app, @buttongroup_models_ionosphere_autodetect_SelectionChangeFcn, true);
            app.buttongroup_models_ionosphere_autodetect.Title = 'Ionex Auto-Detection: Folder';
            app.buttongroup_models_ionosphere_autodetect.Visible = 'off';
            app.buttongroup_models_ionosphere_autodetect.SizeChangedFcn = createCallbackFcn(app, @buttongroup_models_ionosphere_autodetect_SelectionChangeFcn, true);
            app.buttongroup_models_ionosphere_autodetect.Tag = 'buttongroup_models_ionosphere_autodetect';
            app.radiobutton_iono_folder_auto = uiradiobutton(app.buttongroup_models_ionosphere_autodetect);
            app.radiobutton_iono_folder_auto.Tag = 'radiobutton_iono_folder_auto';
            app.radiobutton_iono_folder_auto.Text = 'automatic folder';
            app.radiobutton_iono_folder_auto.Value = true;
            app.radiobutton_iono_folder_manual = uiradiobutton(app.buttongroup_models_ionosphere_autodetect);
            app.radiobutton_iono_folder_manual.Tag = 'radiobutton_iono_folder_manual';
            app.radiobutton_iono_folder_manual.Text = 'manual folder';
            app.pushbutton_iono_folder = uibutton(app.buttongroup_models_ionosphere_autodetect, 'push');
            app.pushbutton_iono_folder.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_iono_folder_Callback, true);
            app.pushbutton_iono_folder.Tag = 'pushbutton_iono_folder';
            app.pushbutton_iono_folder.Enable = 'off';
            app.pushbutton_iono_folder.Text = '...';
            app.edit_iono_folder = uieditfield(app.buttongroup_models_ionosphere_autodetect, 'text');
            app.edit_iono_folder.Tag = 'edit_iono_folder';
            app.edit_iono_folder.Enable = 'off';
            app.buttongroup_source_ionosphere = uibuttongroup(app.uipanel_ionosphere);
            app.buttongroup_source_ionosphere.SelectionChangedFcn = createCallbackFcn(app, @buttongroup_source_ionosphere_SelectionChangeFcn, true);
            app.buttongroup_source_ionosphere.Title = 'Source';
            app.buttongroup_source_ionosphere.Visible = 'off';
            app.buttongroup_source_ionosphere.Tag = 'buttongroup_source_ionosphere';
            app.radiobutton_model_ionosphere_CorrectionStream = uiradiobutton(app.buttongroup_source_ionosphere);
            app.radiobutton_model_ionosphere_CorrectionStream.Tag = 'radiobutton_model_ionosphere_CorrectionStream';
            app.radiobutton_model_ionosphere_CorrectionStream.Tooltip = 'The ionospheric delay is estimated during the processing using the VTEC from a correction stream as constraint.';
            app.radiobutton_model_ionosphere_CorrectionStream.Text = 'VTEC from Correction Stream';
            app.radiobutton_source_ionosphere_CODE = uiradiobutton(app.buttongroup_source_ionosphere);
            app.radiobutton_source_ionosphere_CODE.Tag = 'radiobutton_source_ionosphere_CODE';
            app.radiobutton_source_ionosphere_CODE.Tooltip = 'For Single-Frequency-Processing: the ionospheric delay is corrected using Spherical Harmonics from CODE.';
            app.radiobutton_source_ionosphere_CODE.Text = 'CODE Spherical Harmonics';
            app.radiobutton_source_ionosphere_NeQuick = uiradiobutton(app.buttongroup_source_ionosphere);
            app.radiobutton_source_ionosphere_NeQuick.Tag = 'radiobutton_source_ionosphere_NeQuick';
            app.radiobutton_source_ionosphere_NeQuick.Tooltip = 'For Single-Frequency-Processing: the ionospheric delay is corrected using the NeQuick-Model.';
            app.radiobutton_source_ionosphere_NeQuick.Text = 'NeQuick model';
            app.radiobutton_source_ionosphere_Klobuchar = uiradiobutton(app.buttongroup_source_ionosphere);
            app.radiobutton_source_ionosphere_Klobuchar.Tag = 'radiobutton_source_ionosphere_Klobuchar';
            app.radiobutton_source_ionosphere_Klobuchar.Tooltip = 'For Single-Frequency-Processing: the ionospheric delay is corrected using the Klobuchar-Model.';
            app.radiobutton_source_ionosphere_Klobuchar.Text = 'Klobuchar model';
            app.radiobutton_source_ionosphere_IONEX = uiradiobutton(app.buttongroup_source_ionosphere);
            app.radiobutton_source_ionosphere_IONEX.Tag = 'radiobutton_source_ionosphere_IONEX';
            app.radiobutton_source_ionosphere_IONEX.Tooltip = 'For Single-Frequency-Processing: the ionospheric delay is corrected using the Klobuchar-Model.';
            app.radiobutton_source_ionosphere_IONEX.Text = 'IONEX File';
            app.radiobutton_source_ionosphere_IONEX.Value = true;
            app.text_constraint_until = uilabel(app.uipanel_ionosphere);
            app.text_constraint_until.Tag = 'text_constraint_until';
            app.text_constraint_until.Visible = 'off';
            app.text_constraint_until.Text = 'Constraint until minute:';
            app.edit_constraint_until = uieditfield(app.uipanel_ionosphere, 'text');
            app.edit_constraint_until.Tag = 'edit_constraint_until';
            app.edit_constraint_until.Visible = 'off';
            app.edit_constraint_until.Value = '10';
            app.buttongroup_models_ionosphere_ionex_type = uibuttongroup(app.uipanel_ionosphere);
            app.buttongroup_models_ionosphere_ionex_type.Title = 'Product Type';
            app.buttongroup_models_ionosphere_ionex_type.Visible = 'off';
            app.buttongroup_models_ionosphere_ionex_type.Tag = 'buttongroup_models_ionosphere_ionex_type';
            app.radiobutton_ionex_final = uiradiobutton(app.buttongroup_models_ionosphere_ionex_type);
            app.radiobutton_ionex_final.Tag = 'radiobutton_ionex_final';
            app.radiobutton_ionex_final.Tooltip = 'For Single-Frequency-Processing: the ionospheric delay is corrected using Spherical Harmonics from CODE.';
            app.radiobutton_ionex_final.Text = 'final';
            app.radiobutton_ionex_final.Value = true;
            app.radiobutton_ionex_rapid = uiradiobutton(app.buttongroup_models_ionosphere_ionex_type);
            app.radiobutton_ionex_rapid.Tag = 'radiobutton_ionex_rapid';
            app.radiobutton_ionex_rapid.Tooltip = 'For Single-Frequency-Processing: the ionospheric delay is corrected using Spherical Harmonics from CODE.';
            app.radiobutton_ionex_rapid.Text = 'rapid';
            app.radiobutton_ionex_rapid_highrate = uiradiobutton(app.buttongroup_models_ionosphere_ionex_type);
            app.radiobutton_ionex_rapid_highrate.Tag = 'radiobutton_ionex_rapid_highrate';
            app.radiobutton_ionex_rapid_highrate.Tooltip = 'For Single-Frequency-Processing: the ionospheric delay is corrected using Spherical Harmonics from CODE.';
            app.radiobutton_ionex_rapid_highrate.Text = 'rapid high-rate';
            app.text_constraint_decrease = uilabel(app.uipanel_ionosphere);
            app.text_constraint_decrease.Tag = 'text_constraint_decrease';
            app.text_constraint_decrease.Visible = 'off';
            app.text_constraint_decrease.Text = 'Decrease Stdev [m] until:';
            app.edit_constraint_decrease = uieditfield(app.uipanel_ionosphere, 'text');
            app.edit_constraint_decrease.Tag = 'edit_constraint_decrease';
            app.edit_constraint_decrease.Visible = 'off';
            app.edit_constraint_decrease.Value = '3';
            app.buttongroup_bspline = uibuttongroup(app.uipanel_ionosphere);
            app.buttongroup_bspline.SelectionChangedFcn = createCallbackFcn(app, @buttongroup_bsplineSelectionChanged, true);
            app.buttongroup_bspline.Title = 'VTEC B-spline';
            app.buttongroup_bspline.Visible = 'off';
            app.buttongroup_bspline.Tag = 'buttongroup_bspline';
            app.text_lon_range = uilabel(app.buttongroup_bspline);
            app.text_lon_range.Tag = 'text_lon_range';
            app.text_lon_range.Visible = 'off';
            app.text_lon_range.Text = 'Longitude range [min | max]';
            app.edit_lon_min = uieditfield(app.buttongroup_bspline, 'text');
            app.edit_lon_min.Tag = 'edit_lon_min';
            app.edit_lon_min.Visible = 'off';
            app.edit_lon_min.Value = '-140';
            app.edit_lon_max = uieditfield(app.buttongroup_bspline, 'text');
            app.edit_lon_max.Tag = 'edit_lon_max';
            app.edit_lon_max.Visible = 'off';
            app.edit_lon_max.Value = '-95';
            app.text_lat_range = uilabel(app.buttongroup_bspline);
            app.text_lat_range.Tag = 'text_lat_range';
            app.text_lat_range.Visible = 'off';
            app.text_lat_range.Text = 'Latitude range [min | max]';
            app.edit_lat_min = uieditfield(app.buttongroup_bspline, 'text');
            app.edit_lat_min.Tag = 'edit_lat_min';
            app.edit_lat_min.Visible = 'off';
            app.edit_lat_min.Value = '15';
            app.edit_lat_max = uieditfield(app.buttongroup_bspline, 'text');
            app.edit_lat_max.Tag = 'edit_lat_max';
            app.edit_lat_max.Visible = 'off';
            app.edit_lat_max.Value = '50';
            app.text_lon_num_basis = uilabel(app.buttongroup_bspline);
            app.text_lon_num_basis.Tag = 'text_lon_num_basis';
            app.text_lon_num_basis.Visible = 'off';
            app.text_lon_num_basis.Text = 'Longitude [Degree | Resultion level]';
            app.edit_lon_degree = uieditfield(app.buttongroup_bspline, 'text');
            app.edit_lon_degree.InputType = 'digits';
            app.edit_lon_degree.Tag = 'edit_lon_degree';
            app.edit_lon_degree.Visible = 'off';
            app.edit_lon_degree.Value = '2';
            app.edit_lon_res_level = uieditfield(app.buttongroup_bspline, 'text');
            app.edit_lon_res_level.InputType = 'digits';
            app.edit_lon_res_level.Tag = 'edit_lon_res_level';
            app.edit_lon_res_level.Visible = 'off';
            app.edit_lon_res_level.Value = '3';
            app.text_lat_num_basis = uilabel(app.buttongroup_bspline);
            app.text_lat_num_basis.Tag = 'text_lat_num_basis';
            app.text_lat_num_basis.Visible = 'off';
            app.text_lat_num_basis.Text = 'Latitude [Degree | Resultion level]';
            app.edit_lat_degree = uieditfield(app.buttongroup_bspline, 'text');
            app.edit_lat_degree.InputType = 'digits';
            app.edit_lat_degree.Tag = 'edit_lat_degree';
            app.edit_lat_degree.Visible = 'off';
            app.edit_lat_degree.Value = '2';
            app.edit_lat_res_level = uieditfield(app.buttongroup_bspline, 'text');
            app.edit_lat_res_level.InputType = 'digits';
            app.edit_lat_res_level.Tag = 'edit_lat_res_level';
            app.edit_lat_res_level.Visible = 'off';
            app.edit_lat_res_level.Value = '3';
            app.uipanel_troposphere = uipanel(app.figure1);
            app.uipanel_troposphere.Title = 'Troposphere';
            app.uipanel_troposphere.Visible = 'off';
            app.uipanel_troposphere.Tag = 'uipanel_troposphere';
            app.text_temp = uilabel(app.uipanel_troposphere);
            app.text_temp.Tag = 'text_temp';
            app.text_temp.Visible = 'off';
            app.text_temp.Text = 'Temperature [°C]:';
            app.text_druck = uilabel(app.uipanel_troposphere);
            app.text_druck.Tag = 'text_druck';
            app.text_druck.Visible = 'off';
            app.text_druck.Text = 'Pressure [hPa]:';
            app.text_feuchte = uilabel(app.uipanel_troposphere);
            app.text_feuchte.Tag = 'text_feuchte';
            app.text_feuchte.Visible = 'off';
            app.text_feuchte.Text = 'Relative humidity [%]:';
            app.edit_feuchte = uieditfield(app.uipanel_troposphere, 'text');
            app.edit_feuchte.ValueChangedFcn = createCallbackFcn(app, @edit_feuchte_Callback, true);
            app.edit_feuchte.Tag = 'edit_feuchte';
            app.edit_feuchte.Visible = 'off';
            app.edit_feuchte.Value = '48.14';
            app.edit_druck = uieditfield(app.uipanel_troposphere, 'text');
            app.edit_druck.ValueChangedFcn = createCallbackFcn(app, @edit_druck_Callback, true);
            app.edit_druck.Tag = 'edit_druck';
            app.edit_druck.Visible = 'off';
            app.edit_druck.Value = '1013.25';
            app.edit_temp = uieditfield(app.uipanel_troposphere, 'text');
            app.edit_temp.ValueChangedFcn = createCallbackFcn(app, @edit_temp_Callback, true);
            app.edit_temp.Tag = 'edit_temp';
            app.edit_temp.Visible = 'off';
            app.edit_temp.Value = '15';
            app.uipanel24 = uipanel(app.uipanel_troposphere);
            app.uipanel24.Title = 'Hydrostatic';
            app.uipanel24.Tag = 'uipanel24';
            app.buttongroup_models_troposphere_zhd = uibuttongroup(app.uipanel24);
            app.buttongroup_models_troposphere_zhd.SelectionChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.buttongroup_models_troposphere_zhd.Title = 'Zenith delay';
            app.buttongroup_models_troposphere_zhd.Tag = 'buttongroup_models_troposphere_zhd';
            app.radiobutton_models_troposphere_zhd_VMF3 = uiradiobutton(app.buttongroup_models_troposphere_zhd);
            app.radiobutton_models_troposphere_zhd_VMF3.Tag = 'radiobutton_models_troposphere_zhd_VMF3';
            app.radiobutton_models_troposphere_zhd_VMF3.Tooltip = 'zhd from VMF3 (Landskron and Böhm, 2018)';
            app.radiobutton_models_troposphere_zhd_VMF3.Text = 'VMF3';
            app.radiobutton_models_troposphere_zhd_VMF3.Value = true;
            app.radiobutton_models_troposphere_zhd_GPT3 = uiradiobutton(app.buttongroup_models_troposphere_zhd);
            app.radiobutton_models_troposphere_zhd_GPT3.Tag = 'radiobutton_models_troposphere_zhd_GPT3';
            app.radiobutton_models_troposphere_zhd_GPT3.Tooltip = 'zhd calculated with Saastamoinen (1972) with p taken from GPT3';
            app.radiobutton_models_troposphere_zhd_GPT3.Text = 'p (GPT3) + Saastamoinen';
            app.radiobutton_models_troposphere_zhd_fromInSitu = uiradiobutton(app.buttongroup_models_troposphere_zhd);
            app.radiobutton_models_troposphere_zhd_fromInSitu.Tag = 'radiobutton_models_troposphere_zhd_fromInSitu';
            app.radiobutton_models_troposphere_zhd_fromInSitu.Tooltip = 'zhd calculated with Saastamoinen (1972) with p taken from in situ data';
            app.radiobutton_models_troposphere_zhd_fromInSitu.Text = 'p (in situ) + Saastamoinen';
            app.radiobutton_models_troposphere_zhd_no = uiradiobutton(app.buttongroup_models_troposphere_zhd);
            app.radiobutton_models_troposphere_zhd_no.Tag = 'radiobutton_models_troposphere_zhd_no';
            app.radiobutton_models_troposphere_zhd_no.Tooltip = 'no a priori zhd used';
            app.radiobutton_models_troposphere_zhd_no.Text = 'no';
            app.radiobutton_models_troposphere_zhd_tropoFile = uiradiobutton(app.buttongroup_models_troposphere_zhd);
            app.radiobutton_models_troposphere_zhd_tropoFile.Tag = 'radiobutton_models_troposphere_zhd_tropoFile';
            app.radiobutton_models_troposphere_zhd_tropoFile.Tooltip = 'zhd+zwd taken from an external .tro file (only works for IGS stations!)';
            app.radiobutton_models_troposphere_zhd_tropoFile.Text = 'Tropo file';
            app.buttongroup_models_troposphere_mfh = uibuttongroup(app.uipanel24);
            app.buttongroup_models_troposphere_mfh.SelectionChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.buttongroup_models_troposphere_mfh.Title = 'Mapping function';
            app.buttongroup_models_troposphere_mfh.Tag = 'buttongroup_models_troposphere_mfh';
            app.radiobutton_models_troposphere_mfh_VMF3 = uiradiobutton(app.buttongroup_models_troposphere_mfh);
            app.radiobutton_models_troposphere_mfh_VMF3.Tag = 'radiobutton_models_troposphere_mfh_VMF3';
            app.radiobutton_models_troposphere_mfh_VMF3.Tooltip = 'mfh from VMF3 (Landskron and Böhm, 2018)';
            app.radiobutton_models_troposphere_mfh_VMF3.Text = 'VMF3';
            app.radiobutton_models_troposphere_mfh_VMF3.Value = true;
            app.radiobutton_models_troposphere_mfh_GPT3 = uiradiobutton(app.buttongroup_models_troposphere_mfh);
            app.radiobutton_models_troposphere_mfh_GPT3.Tag = 'radiobutton_models_troposphere_mfh_GPT3';
            app.radiobutton_models_troposphere_mfh_GPT3.Tooltip = 'mfh from GPT3 (Landskron and Böhm, 2018)';
            app.radiobutton_models_troposphere_mfh_GPT3.Text = 'GPT3';
            app.buttongroup_models_troposphere_Gh = uibuttongroup(app.uipanel24);
            app.buttongroup_models_troposphere_Gh.SelectionChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.buttongroup_models_troposphere_Gh.Title = 'Gradients';
            app.buttongroup_models_troposphere_Gh.Tag = 'buttongroup_models_troposphere_Gh';
            app.radiobutton_models_troposphere_Gh_no = uiradiobutton(app.buttongroup_models_troposphere_Gh);
            app.radiobutton_models_troposphere_Gh_no.Tag = 'radiobutton_models_troposphere_Gh_no';
            app.radiobutton_models_troposphere_Gh_no.Tooltip = 'no hydrostatic a priori gradients used';
            app.radiobutton_models_troposphere_Gh_no.Text = 'no';
            app.radiobutton_models_troposphere_Gh_GPT3 = uiradiobutton(app.buttongroup_models_troposphere_Gh);
            app.radiobutton_models_troposphere_Gh_GPT3.Tag = 'radiobutton_models_troposphere_Gh_GPT3';
            app.radiobutton_models_troposphere_Gh_GPT3.Tooltip = 'Hydrostatic gradients from GPT3 (Landskron and Böhm, 2018)';
            app.radiobutton_models_troposphere_Gh_GPT3.Text = 'GPT3';
            app.radiobutton_models_troposphere_Gh_GRAD = uiradiobutton(app.buttongroup_models_troposphere_Gh);
            app.radiobutton_models_troposphere_Gh_GRAD.Tag = 'radiobutton_models_troposphere_Gh_GRAD';
            app.radiobutton_models_troposphere_Gh_GRAD.Tooltip = 'Hydrostatic gradients from GRAD (Landskron and Böhm, 2018)';
            app.radiobutton_models_troposphere_Gh_GRAD.Text = 'GRAD';
            app.radiobutton_models_troposphere_Gh_GRAD.Value = true;
            app.uipanel26 = uipanel(app.uipanel_troposphere);
            app.uipanel26.Title = 'Wet';
            app.uipanel26.Tag = 'uipanel26';
            app.buttongroup_models_troposphere_zwd = uibuttongroup(app.uipanel26);
            app.buttongroup_models_troposphere_zwd.SelectionChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.buttongroup_models_troposphere_zwd.Title = 'Zenith delay';
            app.buttongroup_models_troposphere_zwd.Tag = 'buttongroup_models_troposphere_zwd';
            app.radiobutton_models_troposphere_zwd_VMF3 = uiradiobutton(app.buttongroup_models_troposphere_zwd);
            app.radiobutton_models_troposphere_zwd_VMF3.Tag = 'radiobutton_models_troposphere_zwd_VMF3';
            app.radiobutton_models_troposphere_zwd_VMF3.Tooltip = 'zwd from VMF3 (Landskron and Böhm, 2018)';
            app.radiobutton_models_troposphere_zwd_VMF3.Text = 'VMF3';
            app.radiobutton_models_troposphere_zwd_VMF3.Value = true;
            app.radiobutton_models_troposphere_zwd_tropoFile = uiradiobutton(app.buttongroup_models_troposphere_zwd);
            app.radiobutton_models_troposphere_zwd_tropoFile.Tag = 'radiobutton_models_troposphere_zwd_tropoFile';
            app.radiobutton_models_troposphere_zwd_tropoFile.Tooltip = 'zhd+zwd taken from an external .tro file (only works for IGS stations!)';
            app.radiobutton_models_troposphere_zwd_tropoFile.Text = 'Tropo file';
            app.radiobutton_models_troposphere_zwd_GPT3 = uiradiobutton(app.buttongroup_models_troposphere_zwd);
            app.radiobutton_models_troposphere_zwd_GPT3.Tag = 'radiobutton_models_troposphere_zwd_GPT3';
            app.radiobutton_models_troposphere_zwd_GPT3.Tooltip = 'zwd calculated with Askne & Nordius (1988) with e and T taken from GPT3';
            app.radiobutton_models_troposphere_zwd_GPT3.Text = 'e (GPT3) + Askne';
            app.radiobutton_models_troposphere_zwd_fromInSitu = uiradiobutton(app.buttongroup_models_troposphere_zwd);
            app.radiobutton_models_troposphere_zwd_fromInSitu.Tag = 'radiobutton_models_troposphere_zwd_fromInSitu';
            app.radiobutton_models_troposphere_zwd_fromInSitu.Tooltip = 'zwd calculated with Askne & Nordius (1988) with e and T taken from in situ data';
            app.radiobutton_models_troposphere_zwd_fromInSitu.Text = 'e (in situ) + Askne';
            app.radiobutton_models_troposphere_zwd_no = uiradiobutton(app.buttongroup_models_troposphere_zwd);
            app.radiobutton_models_troposphere_zwd_no.Tag = 'radiobutton_models_troposphere_zwd_no';
            app.radiobutton_models_troposphere_zwd_no.Tooltip = 'no a priori zwd used';
            app.radiobutton_models_troposphere_zwd_no.Text = 'no';
            app.buttongroup_models_troposphere_mfw = uibuttongroup(app.uipanel26);
            app.buttongroup_models_troposphere_mfw.SelectionChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.buttongroup_models_troposphere_mfw.Title = 'Mapping function';
            app.buttongroup_models_troposphere_mfw.Tag = 'buttongroup_models_troposphere_mfw';
            app.radiobutton_models_troposphere_mfw_GPT3 = uiradiobutton(app.buttongroup_models_troposphere_mfw);
            app.radiobutton_models_troposphere_mfw_GPT3.Tag = 'radiobutton_models_troposphere_mfw_GPT3';
            app.radiobutton_models_troposphere_mfw_GPT3.Tooltip = 'mfw from GPT3 (Landskron and Böhm, 2018)';
            app.radiobutton_models_troposphere_mfw_GPT3.Text = 'GPT3';
            app.radiobutton_models_troposphere_mfw_VMF3 = uiradiobutton(app.buttongroup_models_troposphere_mfw);
            app.radiobutton_models_troposphere_mfw_VMF3.Tag = 'radiobutton_models_troposphere_mfw_VMF3';
            app.radiobutton_models_troposphere_mfw_VMF3.Tooltip = 'mfw from VMF3 (Landskron and Böhm, 2018)';
            app.radiobutton_models_troposphere_mfw_VMF3.Text = 'VMF3';
            app.radiobutton_models_troposphere_mfw_VMF3.Value = true;
            app.checkbox_estimate_ZWD = uicheckbox(app.uipanel26);
            app.checkbox_estimate_ZWD.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.checkbox_estimate_ZWD.Tag = 'checkbox_estimate_ZWD';
            app.checkbox_estimate_ZWD.Text = 'Estimate ZWD';
            app.checkbox_estimate_ZWD.Value = true;
            app.buttongroup_models_troposphere_Gw = uibuttongroup(app.uipanel26);
            app.buttongroup_models_troposphere_Gw.SelectionChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.buttongroup_models_troposphere_Gw.Title = 'Gradients';
            app.buttongroup_models_troposphere_Gw.Tag = 'buttongroup_models_troposphere_Gw';
            app.radiobutton_models_troposphere_Gw_GRAD = uiradiobutton(app.buttongroup_models_troposphere_Gw);
            app.radiobutton_models_troposphere_Gw_GRAD.Tag = 'radiobutton_models_troposphere_Gw_GRAD';
            app.radiobutton_models_troposphere_Gw_GRAD.Tooltip = 'Wet gradients from GRAD (Landskron and Böhm, 2018)';
            app.radiobutton_models_troposphere_Gw_GRAD.Text = 'GRAD';
            app.radiobutton_models_troposphere_Gw_GRAD.Value = true;
            app.radiobutton_models_troposphere_Gw_GPT3 = uiradiobutton(app.buttongroup_models_troposphere_Gw);
            app.radiobutton_models_troposphere_Gw_GPT3.Tag = 'radiobutton_models_troposphere_Gw_GPT3';
            app.radiobutton_models_troposphere_Gw_GPT3.Tooltip = 'Wet gradients from GPT3 (Landskron and Böhm, 2018)';
            app.radiobutton_models_troposphere_Gw_GPT3.Text = 'GPT3';
            app.radiobutton_models_troposphere_Gw_no = uiradiobutton(app.buttongroup_models_troposphere_Gw);
            app.radiobutton_models_troposphere_Gw_no.Tag = 'radiobutton_models_troposphere_Gw_no';
            app.radiobutton_models_troposphere_Gw_no.Tooltip = 'no wet a priori gradients used';
            app.radiobutton_models_troposphere_Gw_no.Text = 'no';
            app.edit_est_zwd_from = uieditfield(app.uipanel26, 'text');
            app.edit_est_zwd_from.Tag = 'edit_est_zwd_from';
            app.edit_est_zwd_from.Value = '0';
            app.text_est_zwd_from = uilabel(app.uipanel26);
            app.text_est_zwd_from.Tag = 'text_est_zwd_from';
            app.text_est_zwd_from.Text = 'from minute:';
            app.popupmenu_tropo_file = uidropdown(app.uipanel_troposphere);
            app.popupmenu_tropo_file.Items = {'IGS troposphere product', 'manually'};
            app.popupmenu_tropo_file.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.popupmenu_tropo_file.Tag = 'popupmenu_tropo_file';
            app.popupmenu_tropo_file.Visible = 'off';
            app.popupmenu_tropo_file.Value = 'IGS troposphere product';
            app.edit_tropo_file = uieditfield(app.uipanel_troposphere, 'text');
            app.edit_tropo_file.ValueChangedFcn = createCallbackFcn(app, @edit_tropo_file_Callback, true);
            app.edit_tropo_file.Tag = 'edit_tropo_file';
            app.edit_tropo_file.Visible = 'off';
            app.pushbutton_tropo_file = uibutton(app.uipanel_troposphere, 'push');
            app.pushbutton_tropo_file.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_tropo_file_Callback, true);
            app.pushbutton_tropo_file.Tag = 'pushbutton_tropo_file';
            app.pushbutton_tropo_file.Visible = 'off';
            app.pushbutton_tropo_file.Text = '...';
            app.uipanel_batch_proc = uipanel(app.figure1);
            app.uipanel_batch_proc.Title = 'Batch-Processing';
            app.uipanel_batch_proc.Tag = 'uipanel_batch_proc';
            app.uitable_batch_proc = uitable(app.uipanel_batch_proc);
            app.uitable_batch_proc.ColumnName = {'Folder'; 'File'; 'X'; 'Y'; 'Z'; 'GPS'; 'Freq.'; 'Ranking'; 'GLO'; 'Freq.'; 'Ranking'; 'GAL'; 'Freq.'; 'Ranking'; 'BDS'; 'Freq.'; 'Ranking'; 'Start'; 'End'};
            app.uitable_batch_proc.ColumnEditable = [false true true true true true true true true true true true true true true true true true true];
            app.uitable_batch_proc.CellEditCallback = createCallbackFcn(app, @uitable_batch_proc_CellEditCallback, true);
            app.uitable_batch_proc.Tag = 'uitable_batch_proc';
            app.pushbutton_add_files = uibutton(app.uipanel_batch_proc, 'push');
            app.pushbutton_add_files.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_add_files_Callback, true);
            app.pushbutton_add_files.Tag = 'pushbutton_add_files';
            app.pushbutton_add_files.Text = 'Add files';
            app.pushbutton_delete_all_file = uibutton(app.uipanel_batch_proc, 'push');
            app.pushbutton_delete_all_file.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_delete_all_files_Callback, true);
            app.pushbutton_delete_all_file.Tag = 'pushbutton_delete_all_file';
            app.pushbutton_delete_all_file.Text = 'Delete all';
            app.save_process_list = uibutton(app.uipanel_batch_proc, 'push');
            app.save_process_list.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_process_list_Callback, true);
            app.save_process_list.Tag = 'save_process_list';
            app.save_process_list.Text = 'Save process list';
            app.load_process_list = uibutton(app.uipanel_batch_proc, 'push');
            app.load_process_list.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_process_list_Callback, true);
            app.load_process_list.Tag = 'load_process_list';
            app.load_process_list.Text = 'Load process list';
            app.checkbox_manipulate_all = uicheckbox(app.uipanel_batch_proc);
            app.checkbox_manipulate_all.ValueChangedFcn = createCallbackFcn(app, @checkbox_manipulate_all_Callback, true);
            app.checkbox_manipulate_all.Tag = 'checkbox_manipulate_all';
            app.checkbox_manipulate_all.Text = 'Manipulate all';
            app.checkbox_batch_manipulate_identical = uicheckbox(app.uipanel_batch_proc);
            app.checkbox_batch_manipulate_identical.ValueChangedFcn = createCallbackFcn(app, @checkbox_batch_manipulate_identical_Callback, true);
            app.checkbox_batch_manipulate_identical.Tag = 'checkbox_batch_manipulate_identical';
            app.checkbox_batch_manipulate_identical.Text = 'Manipulate identical';
            app.pushbutton_add_folder = uibutton(app.uipanel_batch_proc, 'push');
            app.pushbutton_add_folder.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_add_folder_Callback, true);
            app.pushbutton_add_folder.Tag = 'pushbutton_add_folder';
            app.pushbutton_add_folder.Text = 'Add folder';
            app.checkbox_parfor = uicheckbox(app.uipanel_batch_proc);
            app.checkbox_parfor.Tag = 'checkbox_parfor';
            app.checkbox_parfor.Text = 'Use parfor loop';
            app.checkbox_parfor.Value = true;
            app.pushbutton_plot_stations = uibutton(app.uipanel_batch_proc, 'push');
            app.pushbutton_plot_stations.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plot_stations_Callback, true);
            app.pushbutton_plot_stations.Tag = 'pushbutton_plot_stations';
            app.pushbutton_plot_stations.Text = 'Plot';
            app.uipanel_orbitClockData = uipanel(app.figure1);
            app.uipanel_orbitClockData.Title = 'Orbit/Clock Data';
            app.uipanel_orbitClockData.Visible = 'off';
            app.uipanel_orbitClockData.Tag = 'uipanel_orbitClockData';
            app.uibuttongroup11 = uibuttongroup(app.uipanel_orbitClockData);
            app.uibuttongroup11.Tag = 'uibuttongroup11';
            app.uibuttongroup_prec_prod_type = uibuttongroup(app.uibuttongroup11);
            app.uibuttongroup_prec_prod_type.Title = 'Type:';
            app.uibuttongroup_prec_prod_type.Tag = 'uibuttongroup_prec_prod_type';
            app.radiobutton_prec_prod_final = uiradiobutton(app.uibuttongroup_prec_prod_type);
            app.radiobutton_prec_prod_final.Tag = 'radiobutton_prec_prod_final';
            app.radiobutton_prec_prod_final.Text = 'Final';
            app.radiobutton_prec_prod_final.Value = true;
            app.radiobutton_prec_prod_rapid = uiradiobutton(app.uibuttongroup_prec_prod_type);
            app.radiobutton_prec_prod_rapid.Tag = 'radiobutton_prec_prod_rapid';
            app.radiobutton_prec_prod_rapid.Text = 'Rapid';
            app.radiobutton_prec_prod_ultrarapid = uiradiobutton(app.uibuttongroup_prec_prod_type);
            app.radiobutton_prec_prod_ultrarapid.Tag = 'radiobutton_prec_prod_ultrarapid';
            app.radiobutton_prec_prod_ultrarapid.Text = 'Ultra-Rapid';
            app.uibuttongroup12 = uibuttongroup(app.uibuttongroup11);
            app.uibuttongroup12.Tag = 'uibuttongroup12';
            app.radiobutton_multi_nav = uiradiobutton(app.uibuttongroup12);
            app.radiobutton_multi_nav.Tag = 'radiobutton_multi_nav';
            app.radiobutton_multi_nav.Enable = 'off';
            app.radiobutton_multi_nav.Text = 'Multi-GNSS Navigation File';
            app.radiobutton_multi_nav.Value = true;
            app.radiobutton_single_nav = uiradiobutton(app.uibuttongroup12);
            app.radiobutton_single_nav.Tag = 'radiobutton_single_nav';
            app.radiobutton_single_nav.Enable = 'off';
            app.radiobutton_single_nav.Text = 'Single-GNSS Navigation Files';
            app.edit_nav_multi = uieditfield(app.uibuttongroup12, 'text');
            app.edit_nav_multi.ValueChangedFcn = createCallbackFcn(app, @edit_nav_multi_Callback, true);
            app.edit_nav_multi.Tag = 'edit_nav_multi';
            app.edit_nav_multi.Enable = 'off';
            app.edit_nav_multi.Visible = 'off';
            app.pushbutton_nav_multi = uibutton(app.uibuttongroup12, 'push');
            app.pushbutton_nav_multi.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_nav_multi_Callback, true);
            app.pushbutton_nav_multi.Tag = 'pushbutton_nav_multi';
            app.pushbutton_nav_multi.Enable = 'off';
            app.pushbutton_nav_multi.Visible = 'off';
            app.pushbutton_nav_multi.Text = '...';
            app.popupmenu_nav_multi = uidropdown(app.uibuttongroup12);
            app.popupmenu_nav_multi.Items = {'IGN', 'IGS', 'manually'};
            app.popupmenu_nav_multi.ValueChangedFcn = createCallbackFcn(app, @popupmenu_multi_nav_Callback, true);
            app.popupmenu_nav_multi.Tag = 'popupmenu_nav_multi';
            app.popupmenu_nav_multi.Enable = 'off';
            app.popupmenu_nav_multi.Value = 'IGN';
            app.text190 = uilabel(app.uibuttongroup12);
            app.text190.Tag = 'text190';
            app.text190.Enable = 'off';
            app.text190.Text = 'Glonass:';
            app.edit_nav_GLO = uieditfield(app.uibuttongroup12, 'text');
            app.edit_nav_GLO.ValueChangedFcn = createCallbackFcn(app, @edit_nav_GLO_Callback, true);
            app.edit_nav_GLO.Tag = 'edit_nav_GLO';
            app.edit_nav_GLO.Enable = 'off';
            app.pushbutton_nav_GLO = uibutton(app.uibuttongroup12, 'push');
            app.pushbutton_nav_GLO.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_nav_GLO_Callback, true);
            app.pushbutton_nav_GLO.Tag = 'pushbutton_nav_GLO';
            app.pushbutton_nav_GLO.Enable = 'off';
            app.pushbutton_nav_GLO.Text = '...';
            app.text191 = uilabel(app.uibuttongroup12);
            app.text191.Tag = 'text191';
            app.text191.Enable = 'off';
            app.text191.Text = 'GPS:';
            app.edit_nav_GPS = uieditfield(app.uibuttongroup12, 'text');
            app.edit_nav_GPS.ValueChangedFcn = createCallbackFcn(app, @edit_nav_GPS_Callback, true);
            app.edit_nav_GPS.Tag = 'edit_nav_GPS';
            app.edit_nav_GPS.Enable = 'off';
            app.pushbutton_nav_GPS = uibutton(app.uibuttongroup12, 'push');
            app.pushbutton_nav_GPS.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_nav_GPS_Callback, true);
            app.pushbutton_nav_GPS.Tag = 'pushbutton_nav_GPS';
            app.pushbutton_nav_GPS.Enable = 'off';
            app.pushbutton_nav_GPS.Text = '...';
            app.text192 = uilabel(app.uibuttongroup12);
            app.text192.Tag = 'text192';
            app.text192.Enable = 'off';
            app.text192.Text = 'Galileo:';
            app.edit_nav_GAL = uieditfield(app.uibuttongroup12, 'text');
            app.edit_nav_GAL.ValueChangedFcn = createCallbackFcn(app, @edit_nav_GAL_Callback, true);
            app.edit_nav_GAL.Tag = 'edit_nav_GAL';
            app.edit_nav_GAL.Enable = 'off';
            app.pushbutton_nav_GAL = uibutton(app.uibuttongroup12, 'push');
            app.pushbutton_nav_GAL.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_nav_GAL_Callback, true);
            app.pushbutton_nav_GAL.Tag = 'pushbutton_nav_GAL';
            app.pushbutton_nav_GAL.Enable = 'off';
            app.pushbutton_nav_GAL.Text = '...';
            app.edit_nav_BDS = uieditfield(app.uibuttongroup12, 'text');
            app.edit_nav_BDS.ValueChangedFcn = createCallbackFcn(app, @edit_nav_BDS_Callback, true);
            app.edit_nav_BDS.Tag = 'edit_nav_BDS';
            app.edit_nav_BDS.Enable = 'off';
            app.text281 = uilabel(app.uibuttongroup12);
            app.text281.Tag = 'text281';
            app.text281.Enable = 'off';
            app.text281.Text = 'BeiDou:';
            app.pushbutton_nav_BDS = uibutton(app.uibuttongroup12, 'push');
            app.pushbutton_nav_BDS.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_nav_BDS_Callback, true);
            app.pushbutton_nav_BDS.Tag = 'pushbutton_nav_BDS';
            app.pushbutton_nav_BDS.Enable = 'off';
            app.pushbutton_nav_BDS.Text = '...';
            app.radiobutton_prec_prod = uiradiobutton(app.uibuttongroup11);
            app.radiobutton_prec_prod.Tag = 'radiobutton_prec_prod';
            app.radiobutton_prec_prod.Tooltip = 'Precise Orbits (*.sp3) and Clocks (*.clk) from one Analysis Center';
            app.radiobutton_prec_prod.Text = 'Precise Products';
            app.radiobutton_prec_prod.Value = true;
            app.radiobutton_brdc_corr = uiradiobutton(app.uibuttongroup11);
            app.radiobutton_brdc_corr.Tag = 'radiobutton_brdc_corr';
            app.radiobutton_brdc_corr.Tooltip = 'Broadcast navigation message plus corrections from stream';
            app.radiobutton_brdc_corr.Text = 'Broadcast Products + Correction Stream';
            app.text201 = uilabel(app.uibuttongroup11);
            app.text201.Tag = 'text201';
            app.text201.Text = 'Source of precise products:';
            app.popupmenu_prec_prod = uidropdown(app.uibuttongroup11);
            app.popupmenu_prec_prod.Items = {'IGS', 'ESA', 'CNES', 'CODE', 'GFZ', 'WUM', 'manually'};
            app.popupmenu_prec_prod.ValueChangedFcn = createCallbackFcn(app, @popupmenu_prec_prod_Callback, true);
            app.popupmenu_prec_prod.Tag = 'popupmenu_prec_prod';
            app.popupmenu_prec_prod.Value = 'IGS';
            app.text_sp3 = uilabel(app.uibuttongroup11);
            app.text_sp3.Tag = 'text_sp3';
            app.text_sp3.Visible = 'off';
            app.text_sp3.Text = 'Ephemerides (*.sp3):';
            app.text_clock = uilabel(app.uibuttongroup11);
            app.text_clock.Tag = 'text_clock';
            app.text_clock.Visible = 'off';
            app.text_clock.Text = 'Clocks (*.clk):';
            app.edit_sp3 = uieditfield(app.uibuttongroup11, 'text');
            app.edit_sp3.ValueChangedFcn = createCallbackFcn(app, @edit_sp3_Callback, true);
            app.edit_sp3.Tag = 'edit_sp3';
            app.edit_sp3.Visible = 'off';
            app.pushbutton_sp3 = uibutton(app.uibuttongroup11, 'push');
            app.pushbutton_sp3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_sp3_Callback, true);
            app.pushbutton_sp3.Tag = 'pushbutton_sp3';
            app.pushbutton_sp3.Visible = 'off';
            app.pushbutton_sp3.Text = '...';
            app.pushbutton_clock = uibutton(app.uibuttongroup11, 'push');
            app.pushbutton_clock.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_clock_Callback, true);
            app.pushbutton_clock.Tag = 'pushbutton_clock';
            app.pushbutton_clock.Visible = 'off';
            app.pushbutton_clock.Text = '...';
            app.edit_clock = uieditfield(app.uibuttongroup11, 'text');
            app.edit_clock.ValueChangedFcn = createCallbackFcn(app, @edit_clock_Callback, true);
            app.edit_clock.Tag = 'edit_clock';
            app.edit_clock.Visible = 'off';
            app.text_CorrectionStream = uilabel(app.uibuttongroup11);
            app.text_CorrectionStream.Tag = 'text_CorrectionStream';
            app.text_CorrectionStream.Enable = 'off';
            app.text_CorrectionStream.Text = 'Correction Stream:';
            app.edit_corr2brdc = uieditfield(app.uibuttongroup11, 'text');
            app.edit_corr2brdc.ValueChangedFcn = createCallbackFcn(app, @edit_corr2brdc_Callback, true);
            app.edit_corr2brdc.Tag = 'edit_corr2brdc';
            app.edit_corr2brdc.Visible = 'off';
            app.pushbutton_corr2brdc = uibutton(app.uibuttongroup11, 'push');
            app.pushbutton_corr2brdc.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_corr2brdc_Callback, true);
            app.pushbutton_corr2brdc.Tag = 'pushbutton_corr2brdc';
            app.pushbutton_corr2brdc.Visible = 'off';
            app.pushbutton_corr2brdc.Text = '...';
            app.popupmenu_CorrectionStream = uidropdown(app.uibuttongroup11);
            app.popupmenu_CorrectionStream.Items = {'CNES Archive', 'IGC01 Archive', 'manually', 'off'};
            app.popupmenu_CorrectionStream.ValueChangedFcn = createCallbackFcn(app, @popupmenu_CorrectionStream_Callback, true);
            app.popupmenu_CorrectionStream.Tag = 'popupmenu_CorrectionStream';
            app.popupmenu_CorrectionStream.Enable = 'off';
            app.popupmenu_CorrectionStream.Value = 'off';
            app.checkbox_MGEX = uicheckbox(app.uibuttongroup11);
            app.checkbox_MGEX.ValueChangedFcn = createCallbackFcn(app, @update_GUI_Callback, true);
            app.checkbox_MGEX.Tag = 'checkbox_MGEX';
            app.checkbox_MGEX.Text = 'MGEX Product';
            app.edit_obx = uieditfield(app.uibuttongroup11, 'text');
            app.edit_obx.ValueChangedFcn = createCallbackFcn(app, @edit_obx_Callback, true);
            app.edit_obx.Tag = 'edit_obx';
            app.edit_obx.Visible = 'off';
            app.pushbutton_obx = uibutton(app.uibuttongroup11, 'push');
            app.pushbutton_obx.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_obx_Callback, true);
            app.pushbutton_obx.Tag = 'pushbutton_obx';
            app.pushbutton_obx.Visible = 'off';
            app.pushbutton_obx.Text = '...';
            app.text_obx = uilabel(app.uibuttongroup11);
            app.text_obx.Tag = 'text_obx';
            app.text_obx.Visible = 'off';
            app.text_obx.Text = 'ORBEX (*.obx):';
            app.text_corr2brdc_age_2 = uilabel(app.uibuttongroup11);
            app.text_corr2brdc_age_2.Tag = 'text_corr2brdc_age_2';
            app.text_corr2brdc_age_2.Visible = 'off';
            app.text_corr2brdc_age_2.Text = '(orbit, clock, biases)';
            app.edit_corr2brdc_age = uieditfield(app.uibuttongroup11, 'text');
            app.edit_corr2brdc_age.Tag = 'edit_corr2brdc_age';
            app.edit_corr2brdc_age.Visible = 'off';
            app.edit_corr2brdc_age.Value = '40, 40, NaN';
            app.text_corr2brdc_age_1 = uilabel(app.uibuttongroup11);
            app.text_corr2brdc_age_1.Tag = 'text_corr2brdc_age_1';
            app.text_corr2brdc_age_1.Visible = 'off';
            app.text_corr2brdc_age_1.Text = 'Corrections age limit [s]';
            app.checkbox_obx = uicheckbox(app.uipanel_orbitClockData);
            app.checkbox_obx.ValueChangedFcn = createCallbackFcn(app, @checkbox_obx_Callback, true);
            app.checkbox_obx.Tag = 'checkbox_obx';
            app.checkbox_obx.Text = 'Use ORBEX';
            app.uipanel_adjustment = uipanel(app.figure1);
            app.uipanel_adjustment.Title = 'Stochastic / Filter';
            app.uipanel_adjustment.Visible = 'off';
            app.uipanel_adjustment.Tag = 'uipanel_adjustment';
            app.edit_Std_Phase = uieditfield(app.uipanel_adjustment, 'text');
            app.edit_Std_Phase.Tag = 'edit_Std_Phase';
            app.edit_Std_Phase.Value = '0.002';
            app.text_std_phase = uilabel(app.uipanel_adjustment);
            app.text_std_phase.Tag = 'text81';
            app.text_std_phase.Text = 'Phase:';
            app.text_std_code = uilabel(app.uipanel_adjustment);
            app.text_std_code.Tag = 'text76';
            app.text_std_code.Text = 'Code:';
            app.edit_Std_CA_Code = uieditfield(app.uipanel_adjustment, 'text');
            app.edit_Std_CA_Code.Tag = 'edit_Std_CA_Code';
            app.edit_Std_CA_Code.Value = '0.300';
            app.uipanel_filter = uipanel(app.uipanel_adjustment);
            app.uipanel_filter.Title = 'Filter Settings';
            app.uipanel_filter.Tag = 'uipanel_filter';
            app.text_coordinates = uilabel(app.uipanel_filter);
            app.text_coordinates.Tag = 'text_coordinates';
            app.text_coordinates.Text = 'Coordinates:';
            app.edit_filter_coord_sigma0 = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_coord_sigma0.Tag = 'edit_filter_coord_sigma0';
            app.edit_filter_coord_sigma0.Value = '1e+04';
            app.edit_filter_coord_Q = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_coord_Q.Tag = 'edit_filter_coord_Q';
            app.edit_filter_coord_Q.Value = '0';
            app.text86 = uilabel(app.uipanel_filter);
            app.text86.Tag = 'text86';
            app.text86.Text = 'Initial Std';
            app.text87 = uilabel(app.uipanel_filter);
            app.text87.Tag = 'text87';
            app.text87.Text = 'System Noise';
            app.text_gps_time_offset = uilabel(app.uipanel_filter);
            app.text_gps_time_offset.Tag = 'text_gps_time_offset';
            app.text_gps_time_offset.Text = 'Receiver Clock GPS:';
            app.text_float_ambiguities = uilabel(app.uipanel_filter);
            app.text_float_ambiguities.Tag = 'text_float_ambiguities';
            app.text_float_ambiguities.Text = 'Float Ambiguities:';
            app.edit_filter_rec_clock_sigma0 = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_rec_clock_sigma0.Tag = 'edit_filter_rec_clock_sigma0';
            app.edit_filter_rec_clock_sigma0.Value = '3e+05';
            app.edit_filter_rec_clock_Q = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_rec_clock_Q.Tag = 'edit_filter_rec_clock_Q';
            app.edit_filter_rec_clock_Q.Value = '3e+05';
            app.edit_filter_glonass_offset_sigma0 = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_glonass_offset_sigma0.Tag = 'edit_filter_glonass_offset_sigma0';
            app.edit_filter_glonass_offset_sigma0.Enable = 'off';
            app.edit_filter_glonass_offset_sigma0.Value = '3e+05';
            app.edit_filter_glonass_offset_Q = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_glonass_offset_Q.Tag = 'edit_filter_glonass_offset_Q';
            app.edit_filter_glonass_offset_Q.Enable = 'off';
            app.edit_filter_glonass_offset_Q.Value = '3e+05';
            app.edit_filter_ambiguities_sigma0 = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_ambiguities_sigma0.Tag = 'edit_filter_ambiguities_sigma0';
            app.edit_filter_ambiguities_sigma0.Value = '20';
            app.edit_filter_ambiguities_Q = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_ambiguities_Q.Tag = 'edit_filter_ambiguities_Q';
            app.edit_filter_ambiguities_Q.Value = '0';
            app.text92 = uilabel(app.uipanel_filter);
            app.text92.Tag = 'text92';
            app.text92.Text = 'm/√h';
            app.text_float_ambiguities_m = uilabel(app.uipanel_filter);
            app.text_float_ambiguities_m.Tag = 'text_float_ambiguities_m';
            app.text_float_ambiguities_m.Text = 'm/√h';
            app.text_gps_time_offset_m = uilabel(app.uipanel_filter);
            app.text_gps_time_offset_m.Tag = 'text_gps_time_offset_m';
            app.text_gps_time_offset_m.Text = 'm/√h';
            app.text_glo_time_offset = uilabel(app.uipanel_filter);
            app.text_glo_time_offset.Tag = 'text_glo_time_offset';
            app.text_glo_time_offset.Enable = 'off';
            app.text_glo_time_offset.Text = 'Receiver Clock Glonass:';
            app.text_glo_time_offset_m = uilabel(app.uipanel_filter);
            app.text_glo_time_offset_m.Tag = 'text_glo_time_offset_m';
            app.text_glo_time_offset_m.Enable = 'off';
            app.text_glo_time_offset_m.Text = 'm/√h';
            app.text_zwd = uilabel(app.uipanel_filter);
            app.text_zwd.Tag = 'text_zwd';
            app.text_zwd.Text = 'Zenith Wet Delay:';
            app.edit_filter_zwd_sigma0 = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_zwd_sigma0.Tag = 'edit_filter_zwd_sigma0';
            app.edit_filter_zwd_sigma0.Value = '10';
            app.edit_filter_zwd_Q = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_zwd_Q.Tag = 'edit_filter_zwd_Q';
            app.edit_filter_zwd_Q.Value = '0.3';
            app.text_zwd_m = uilabel(app.uipanel_filter);
            app.text_zwd_m.Tag = 'text_zwd_m';
            app.text_zwd_m.Text = 'm/√h';
            app.text107 = uilabel(app.uipanel_filter);
            app.text107.Tag = 'text107';
            app.text107.Text = 'Dynamic Model';
            app.popupmenu_filter_coord_dynmodel = uidropdown(app.uipanel_filter);
            app.popupmenu_filter_coord_dynmodel.Items = {'No Model (Phi = 0)', 'Static Model (Phi = 1)'};
            app.popupmenu_filter_coord_dynmodel.Tag = 'popupmenu_filter_coord_dynmodel';
            app.popupmenu_filter_coord_dynmodel.Value = 'Static Model (Phi = 1)';
            app.popupmenu_filter_rec_clock_dynmodel = uidropdown(app.uipanel_filter);
            app.popupmenu_filter_rec_clock_dynmodel.Items = {'No Model (Phi = 0)', 'Static Model (Phi = 1)'};
            app.popupmenu_filter_rec_clock_dynmodel.Tag = 'popupmenu_filter_rec_clock_dynmodel';
            app.popupmenu_filter_rec_clock_dynmodel.Value = 'Static Model (Phi = 1)';
            app.popupmenu_filter_glonass_offset_dynmodel = uidropdown(app.uipanel_filter);
            app.popupmenu_filter_glonass_offset_dynmodel.Items = {'No Model (Phi = 0)', 'Static Model (Phi = 1)'};
            app.popupmenu_filter_glonass_offset_dynmodel.Tag = 'popupmenu_filter_glonass_offset_dynmodel';
            app.popupmenu_filter_glonass_offset_dynmodel.Enable = 'off';
            app.popupmenu_filter_glonass_offset_dynmodel.Value = 'Static Model (Phi = 1)';
            app.popupmenu_filter_ambiguities_dynmodel = uidropdown(app.uipanel_filter);
            app.popupmenu_filter_ambiguities_dynmodel.Items = {'No Model (Phi = 0)', 'Static Model (Phi = 1)'};
            app.popupmenu_filter_ambiguities_dynmodel.Tag = 'popupmenu_filter_ambiguities_dynmodel';
            app.popupmenu_filter_ambiguities_dynmodel.Value = 'Static Model (Phi = 1)';
            app.popupmenu_filter_zwd_dynmodel = uidropdown(app.uipanel_filter);
            app.popupmenu_filter_zwd_dynmodel.Items = {'No Model (Phi = 0)', 'Static Model (Phi = 1)'};
            app.popupmenu_filter_zwd_dynmodel.Tag = 'popupmenu_filter_zwd_dynmodel';
            app.popupmenu_filter_zwd_dynmodel.Value = 'Static Model (Phi = 1)';
            app.edit_filter_galileo_offset_sigma0 = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_galileo_offset_sigma0.Tag = 'edit_filter_galileo_offset_sigma0';
            app.edit_filter_galileo_offset_sigma0.Value = '3e+05';
            app.edit_filter_galileo_offset_Q = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_galileo_offset_Q.Tag = 'edit_filter_galileo_offset_Q';
            app.edit_filter_galileo_offset_Q.Value = '3e+05';
            app.text_gal_time_offset = uilabel(app.uipanel_filter);
            app.text_gal_time_offset.Tag = 'text_gal_time_offset';
            app.text_gal_time_offset.Text = 'Receiver Clock Galileo:';
            app.text_gal_time_offset_m = uilabel(app.uipanel_filter);
            app.text_gal_time_offset_m.Tag = 'text_gal_time_offset_m';
            app.text_gal_time_offset_m.Text = 'm/√h';
            app.popupmenu_filter_galileo_offset_dynmodel = uidropdown(app.uipanel_filter);
            app.popupmenu_filter_galileo_offset_dynmodel.Items = {'No Model (Phi = 0)', 'Static Model (Phi = 1)'};
            app.popupmenu_filter_galileo_offset_dynmodel.Tag = 'popupmenu_filter_galileo_offset_dynmodel';
            app.popupmenu_filter_galileo_offset_dynmodel.Value = 'Static Model (Phi = 1)';
            app.text_iono_vtec_m = uilabel(app.uipanel_filter);
            app.text_iono_vtec_m.Tag = 'text_iono_vtec_m';
            app.text_iono_vtec_m.Enable = 'off';
            app.text_iono_vtec_m.Text = 'TECu/√h';
            app.edit_filter_iono_vtec_Q = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_iono_vtec_Q.Tag = 'edit_filter_iono_vtec_Q';
            app.edit_filter_iono_vtec_Q.Enable = 'off';
            app.edit_filter_iono_vtec_Q.Value = '10';
            app.edit_filter_iono_vtec_sigma0 = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_iono_vtec_sigma0.Tag = 'edit_filter_iono_vtec_sigma0';
            app.edit_filter_iono_vtec_sigma0.Enable = 'off';
            app.edit_filter_iono_vtec_sigma0.Value = '10';
            app.text_iono_vtec = uilabel(app.uipanel_filter);
            app.text_iono_vtec.Tag = 'text_iono_vtec';
            app.text_iono_vtec.Enable = 'off';
            app.text_iono_vtec.Text = 'Ionospheric VTEC:';
            app.popupmenu_filter_iono_vtec_dynmodel = uidropdown(app.uipanel_filter);
            app.popupmenu_filter_iono_vtec_dynmodel.Items = {'No Model (Phi = 0)', 'Static Model (Phi = 1)'};
            app.popupmenu_filter_iono_vtec_dynmodel.Tag = 'popupmenu_filter_iono_vtec_dynmodel';
            app.popupmenu_filter_iono_vtec_dynmodel.Enable = 'off';
            app.popupmenu_filter_iono_vtec_dynmodel.Value = 'Static Model (Phi = 1)';
            app.text_iono = uilabel(app.uipanel_filter);
            app.text_iono.Tag = 'text_iono';
            app.text_iono.Enable = 'off';
            app.text_iono.Text = 'Ionospheric Delay:';
            app.edit_filter_iono_sigma0 = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_iono_sigma0.Tag = 'edit_filter_iono_sigma0';
            app.edit_filter_iono_sigma0.Enable = 'off';
            app.edit_filter_iono_sigma0.Value = '1';
            app.edit_filter_iono_Q = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_iono_Q.Tag = 'edit_filter_iono_Q';
            app.edit_filter_iono_Q.Enable = 'off';
            app.edit_filter_iono_Q.Value = '1';
            app.text_iono_m = uilabel(app.uipanel_filter);
            app.text_iono_m.Tag = 'text_iono_m';
            app.text_iono_m.Enable = 'off';
            app.text_iono_m.Text = 'm/√h';
            app.popupmenu_filter_iono_dynmodel = uidropdown(app.uipanel_filter);
            app.popupmenu_filter_iono_dynmodel.Items = {'No Model (Phi = 0)', 'Static Model (Phi = 1)'};
            app.popupmenu_filter_iono_dynmodel.Tag = 'popupmenu_filter_iono_dynmodel';
            app.popupmenu_filter_iono_dynmodel.Enable = 'off';
            app.popupmenu_filter_iono_dynmodel.Value = 'Static Model (Phi = 1)';
            app.text_rec_dcbs = uilabel(app.uipanel_filter);
            app.text_rec_dcbs.Tag = 'text_rec_dcbs';
            app.text_rec_dcbs.Enable = 'off';
            app.text_rec_dcbs.Text = 'Receiver DCBs:';
            app.edit_filter_dcbs_sigma0 = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_dcbs_sigma0.Tag = 'edit_filter_dcbs_sigma0';
            app.edit_filter_dcbs_sigma0.Enable = 'off';
            app.edit_filter_dcbs_sigma0.Value = '3';
            app.edit_filter_dcbs_Q = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_dcbs_Q.Tag = 'edit_filter_dcbs_Q';
            app.edit_filter_dcbs_Q.Enable = 'off';
            app.edit_filter_dcbs_Q.Value = '0';
            app.text_dcbs_m = uilabel(app.uipanel_filter);
            app.text_dcbs_m.Tag = 'text_dcbs_m';
            app.text_dcbs_m.Enable = 'off';
            app.text_dcbs_m.Text = 'm/√h';
            app.popupmenu_filter_dcbs_dynmodel = uidropdown(app.uipanel_filter);
            app.popupmenu_filter_dcbs_dynmodel.Items = {'No Model (Phi = 0)', 'Static Model (Phi = 1)'};
            app.popupmenu_filter_dcbs_dynmodel.Tag = 'popupmenu_filter_dcbs_dynmodel';
            app.popupmenu_filter_dcbs_dynmodel.Enable = 'off';
            app.popupmenu_filter_dcbs_dynmodel.Value = 'Static Model (Phi = 1)';
            app.edit_filter_beidou_offset_sigma0 = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_beidou_offset_sigma0.Tag = 'edit_filter_beidou_offset_sigma0';
            app.edit_filter_beidou_offset_sigma0.Enable = 'off';
            app.edit_filter_beidou_offset_sigma0.Value = '3e+05';
            app.edit_filter_beidou_offset_Q = uieditfield(app.uipanel_filter, 'text');
            app.edit_filter_beidou_offset_Q.Tag = 'edit_filter_beidou_offset_Q';
            app.edit_filter_beidou_offset_Q.Enable = 'off';
            app.edit_filter_beidou_offset_Q.Value = '3e+05';
            app.text_bds_time_offset = uilabel(app.uipanel_filter);
            app.text_bds_time_offset.Tag = 'text_bds_time_offset';
            app.text_bds_time_offset.Enable = 'off';
            app.text_bds_time_offset.Text = 'Receiver Clock BeiDou:';
            app.text_bds_time_offset_m = uilabel(app.uipanel_filter);
            app.text_bds_time_offset_m.Tag = 'text_bds_time_offset_m';
            app.text_bds_time_offset_m.Enable = 'off';
            app.text_bds_time_offset_m.Text = 'm/√h';
            app.popupmenu_filter_beidou_offset_dynmodel = uidropdown(app.uipanel_filter);
            app.popupmenu_filter_beidou_offset_dynmodel.Items = {'No Model (Phi = 0)', 'Static Model (Phi = 1)'};
            app.popupmenu_filter_beidou_offset_dynmodel.Tag = 'popupmenu_filter_beidou_offset_dynmodel';
            app.popupmenu_filter_beidou_offset_dynmodel.Enable = 'off';
            app.popupmenu_filter_beidou_offset_dynmodel.Value = 'Static Model (Phi = 1)';
            app.checkbox_estimate_rec_dcbs = uicheckbox(app.uipanel_filter);
            app.checkbox_estimate_rec_dcbs.ValueChangedFcn = createCallbackFcn(app, @checkbox_estimate_rec_dcbs_Callback, true);
            app.checkbox_estimate_rec_dcbs.Tag = 'checkbox_estimate_rec_dcbs';
            app.checkbox_estimate_rec_dcbs.Text = 'Estimate Rec. DCBs';
            app.pushbutton_save_filter_settings = uibutton(app.uipanel_filter, 'push');
            app.pushbutton_save_filter_settings.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_filter_settings_Callback, true);
            app.pushbutton_save_filter_settings.Tag = 'pushbutton_save_filter_settings';
            app.pushbutton_save_filter_settings.Text = 'Save';
            app.pushbutton_load_filter_settings = uibutton(app.uipanel_filter, 'push');
            app.pushbutton_load_filter_settings.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_filter_settings_Callback, true);
            app.pushbutton_load_filter_settings.Tag = 'pushbutton_load_filter_settings';
            app.pushbutton_load_filter_settings.Text = 'Load';
            app.text102 = uilabel(app.uipanel_adjustment);
            app.text102.Tag = 'text102';
            app.text102.Text = 'Filter:';
            app.popupmenu_filter = uidropdown(app.uipanel_adjustment);
            app.popupmenu_filter.Items = {'Kalman Filter Iterative', 'Kalman Filter', 'No Filter'};
            app.popupmenu_filter.ValueChangedFcn = createCallbackFcn(app, @popupmenu_filter_Callback, true);
            app.popupmenu_filter.Tag = 'popupmenu_filter';
            app.popupmenu_filter.Value = 'Kalman Filter Iterative';
            app.uibuttongroup1 = uibuttongroup(app.uipanel_adjustment);
            app.uibuttongroup1.Title = 'Observation Weighting';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.radiobutton_MPLC_Dependency = uiradiobutton(app.uibuttongroup1);
            app.radiobutton_MPLC_Dependency.Tag = 'radiobutton_MPLC_Dependency';
            app.radiobutton_MPLC_Dependency.Text = 'Multipath-LC';
            app.radiobutton_Elevation_Dependency = uiradiobutton(app.uibuttongroup1);
            app.radiobutton_Elevation_Dependency.Tag = 'radiobutton_Elevation_Dependency';
            app.radiobutton_Elevation_Dependency.Text = 'Elevation';
            app.radiobutton_Elevation_Dependency.Value = true;
            app.radiobutton_Signal_Strength_Dependency = uiradiobutton(app.uibuttongroup1);
            app.radiobutton_Signal_Strength_Dependency.Tag = 'radiobutton_Signal_Strength_Dependency';
            app.radiobutton_Signal_Strength_Dependency.Text = 'SNR';
            app.radiobutton_No_Dependency = uiradiobutton(app.uibuttongroup1);
            app.radiobutton_No_Dependency.Tag = 'radiobutton_No_Dependency';
            app.radiobutton_No_Dependency.Text = 'None';
            app.edit_elevation_weighting_function = uieditfield(app.uibuttongroup1, 'text');
            app.edit_elevation_weighting_function.Tag = 'edit_elevation_weighting_function';
            app.edit_elevation_weighting_function.Value = 'sin(e).^2';
            app.edit_snr_weighting_function = uieditfield(app.uibuttongroup1, 'text');
            app.edit_snr_weighting_function.Tag = 'edit_snr_weighting_function';
            app.edit_snr_weighting_function.Value = '10.^-(max([55-snr,0])./10)';
            app.text241 = uilabel(app.uipanel_adjustment);
            app.text241.Tag = 'text241';
            app.text241.Text = 'Standard deviation of';
            app.text_std_iono_vtec = uilabel(app.uipanel_adjustment);
            app.text_std_iono_vtec.Tag = 'text_std_iono_vtec';
            app.text_std_iono_vtec.Text = 'Ionosphere VTEC:';
            app.edit_Std_Iono_vtec = uieditfield(app.uipanel_adjustment, 'text');
            app.edit_Std_Iono_vtec.Tag = 'edit_Std_Iono_vtec';
            app.edit_Std_Iono_vtec.Value = '0.150';
            app.text_std_iono = uilabel(app.uipanel_adjustment);
            app.text_std_iono.Tag = 'text_std_iono';
            app.text_std_iono.Text = 'Ionosphere:';
            app.edit_Std_Iono = uieditfield(app.uipanel_adjustment, 'text');
            app.edit_Std_Iono.Tag = 'edit_Std_Iono';
            app.edit_Std_Iono.Value = '0.150';
            app.pushbutton_def_filter = uibutton(app.uipanel_adjustment, 'push');
            app.pushbutton_def_filter.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_def_filter_Callback, true);
            app.pushbutton_def_filter.Tag = 'pushbutton_def_filter';
            app.pushbutton_def_filter.Text = 'Default settings';
            app.text245 = uilabel(app.uipanel_adjustment);
            app.text245.Tag = 'text245';
            app.text245.Text = 'raw observations [m]';
            app.uipanel_gnss_weights = uipanel(app.uipanel_adjustment);
            app.uipanel_gnss_weights.Title = 'GNSS Weights';
            app.uipanel_gnss_weights.Tag = 'uipanel_gnss_weights';
            app.edit_weight_GPS = uieditfield(app.uipanel_gnss_weights, 'text');
            app.edit_weight_GPS.Tag = 'edit_weight_GPS';
            app.edit_weight_GPS.Value = '1';
            app.edit_weight_GLO = uieditfield(app.uipanel_gnss_weights, 'text');
            app.edit_weight_GLO.Tag = 'edit_weight_GLO';
            app.edit_weight_GLO.Value = '1';
            app.edit_weight_GAL = uieditfield(app.uipanel_gnss_weights, 'text');
            app.edit_weight_GAL.Tag = 'edit_weight_GAL';
            app.edit_weight_GAL.Value = '1';
            app.edit_weight_BDS = uieditfield(app.uipanel_gnss_weights, 'text');
            app.edit_weight_BDS.Tag = 'edit_weight_BDS';
            app.edit_weight_BDS.Value = '1';
            app.text_weight_GPS = uilabel(app.uipanel_gnss_weights);
            app.text_weight_GPS.Tag = 'text_weight_GPS';
            app.text_weight_GPS.Text = 'GPS';
            app.text_weight_GAL = uilabel(app.uipanel_gnss_weights);
            app.text_weight_GAL.Tag = 'text_weight_GAL';
            app.text_weight_GAL.Text = 'Galileo';
            app.text_weight_GLO = uilabel(app.uipanel_gnss_weights);
            app.text_weight_GLO.Tag = 'text_weight_GLO';
            app.text_weight_GLO.Text = 'Glonass';
            app.text_weight_BDS = uilabel(app.uipanel_gnss_weights);
            app.text_weight_BDS.Tag = 'text_weight_BDS';
            app.text_weight_BDS.Text = 'BeiDou';
            app.uipanel_processingOptions = uipanel(app.figure1);
            app.uipanel_processingOptions.Title = 'Processing-Options';
            app.uipanel_processingOptions.Visible = 'off';
            app.uipanel_processingOptions.Tag = 'uipanel_processingOptions';
            app.checkbox_AdjustPhase = uicheckbox(app.uipanel_processingOptions);
            app.checkbox_AdjustPhase.Tag = 'checkbox_AdjustPhase';
            app.checkbox_AdjustPhase.Text = 'Adjust phase to code';
            app.text_process = uilabel(app.uipanel_processingOptions);
            app.text_process.Tag = 'text_process';
            app.text_process.Text = 'Processing Method:';
            app.text_elev_mask = uilabel(app.uipanel_processingOptions);
            app.text_elev_mask.Tag = 'text_elev_mask';
            app.text_elev_mask.Text = 'Elevation Cutoff [°]:';
            app.edit_Elevation_Mask = uieditfield(app.uipanel_processingOptions, 'text');
            app.edit_Elevation_Mask.Tag = 'edit_Elevation_Mask';
            app.edit_Elevation_Mask.Value = '5';
            app.text108 = uilabel(app.uipanel_processingOptions);
            app.text108.Tag = 'text108';
            app.text108.Text = 'Exclude Satellites';
            app.popupmenu_process = uidropdown(app.uipanel_processingOptions);
            app.popupmenu_process.Items = {'Code + Phase', 'Code Only', 'Code (Doppler Smoothing)', 'Code (Phase Smoothing)'};
            app.popupmenu_process.ValueChangedFcn = createCallbackFcn(app, @popupmenu_process_Callback, true);
            app.popupmenu_process.Tag = 'popupmenu_process';
            app.popupmenu_process.Value = 'Code + Phase';
            app.buttongroup_timeSpan_format = uibuttongroup(app.uipanel_processingOptions);
            app.buttongroup_timeSpan_format.Title = 'Time span';
            app.buttongroup_timeSpan_format.Tag = 'buttongroup_timeSpan_format';
            app.radiobutton_timeSpan_format_epochs = uiradiobutton(app.buttongroup_timeSpan_format);
            app.radiobutton_timeSpan_format_epochs.Tag = 'radiobutton_timeSpan_format_epochs';
            app.radiobutton_timeSpan_format_epochs.Text = 'Epochs';
            app.radiobutton_timeSpan_format_epochs.Value = true;
            app.radiobutton_timeSpan_format_SOD = uiradiobutton(app.buttongroup_timeSpan_format);
            app.radiobutton_timeSpan_format_SOD.Tag = 'radiobutton_timeSpan_format_SOD';
            app.radiobutton_timeSpan_format_SOD.Text = 'Seconds of day';
            app.radiobutton_timeSpan_format_HOD = uiradiobutton(app.buttongroup_timeSpan_format);
            app.radiobutton_timeSpan_format_HOD.Tag = 'radiobutton_timeSpan_format_HOD';
            app.radiobutton_timeSpan_format_HOD.Text = 'Hours of day';
            app.edit_timeFrame_from = uieditfield(app.buttongroup_timeSpan_format, 'text');
            app.edit_timeFrame_from.Tag = 'edit_timeFrame_from';
            app.edit_timeFrame_from.Value = '1';
            app.edit_timeFrame_to = uieditfield(app.buttongroup_timeSpan_format, 'text');
            app.edit_timeFrame_to.Tag = 'edit_timeFrame_to';
            app.edit_timeFrame_to.Value = 'end';
            app.text62 = uilabel(app.buttongroup_timeSpan_format);
            app.text62.Tag = 'text62';
            app.text62.Text = 'to';
            app.uitable_exclude = uitable(app.uipanel_processingOptions);
            app.uitable_exclude.ColumnName = {'PRN'; 'Start'; 'End'};
            app.uitable_exclude.ColumnEditable = [true true true];
            app.uitable_exclude.CellEditCallback = createCallbackFcn(app, @uitable_exclude_CellEditCallback, true);
            app.uitable_exclude.Tag = 'uitable_exclude';
            app.text_excl_epochs = uilabel(app.uipanel_processingOptions);
            app.text_excl_epochs.Tag = 'text_excl_epochs';
            app.text_excl_epochs.Text = 'Exclude Epochs';
            app.uitable_excl_epochs = uitable(app.uipanel_processingOptions);
            app.uitable_excl_epochs.ColumnName = {'From'; 'To'; 'Reset?'};
            app.uitable_excl_epochs.ColumnEditable = [true true true];
            app.uitable_excl_epochs.CellEditCallback = createCallbackFcn(app, @uitable_excl_epochs_CellEditCallback, true);
            app.uitable_excl_epochs.Tag = 'uitable_excl_epochs';
            app.text_reset_epoch = uilabel(app.uipanel_processingOptions);
            app.text_reset_epoch.Tag = 'text_reset_epoch';
            app.text_reset_epoch.Text = 'Reset                      solution every';
            app.checkbox_reset_float = uicheckbox(app.uipanel_processingOptions);
            app.checkbox_reset_float.ValueChangedFcn = createCallbackFcn(app, @checkbox_reset_float_Callback, true);
            app.checkbox_reset_float.Tag = 'checkbox_reset_float';
            app.checkbox_reset_float.Text = 'float';
            app.checkbox_reset_fixed = uicheckbox(app.uipanel_processingOptions);
            app.checkbox_reset_fixed.ValueChangedFcn = createCallbackFcn(app, @checkbox_reset_fixed_Callback, true);
            app.checkbox_reset_fixed.Tag = 'checkbox_reset_fixed';
            app.checkbox_reset_fixed.Enable = 'off';
            app.checkbox_reset_fixed.Text = 'fixed';
            app.edit_reset_epoch = uieditfield(app.uipanel_processingOptions, 'text');
            app.edit_reset_epoch.Tag = 'edit_reset_epoch';
            app.edit_reset_epoch.Value = '60';
            app.uibuttongroup_reset = uibuttongroup(app.uipanel_processingOptions);
            app.uibuttongroup_reset.Tag = 'uibuttongroup_reset';
            app.radiobutton_reset_epoch = uiradiobutton(app.uibuttongroup_reset);
            app.radiobutton_reset_epoch.Tag = 'radiobutton_reset_epoch';
            app.radiobutton_reset_epoch.Text = 'Epochs';
            app.radiobutton_reset_min = uiradiobutton(app.uibuttongroup_reset);
            app.radiobutton_reset_min.Tag = 'radiobutton_reset_min';
            app.radiobutton_reset_min.Text = 'Minutes';
            app.radiobutton_reset_min.Value = true;
            app.checkbox_check_omc = uicheckbox(app.uipanel_processingOptions);
            app.checkbox_check_omc.ValueChangedFcn = createCallbackFcn(app, @checkbox_check_omc_Callback, true);
            app.checkbox_check_omc.Tag = 'checkbox_check_omc';
            app.checkbox_check_omc.Text = 'Check omc';
            app.checkbox_check_omc.Value = true;
            app.text_SNR_mask = uilabel(app.uipanel_processingOptions);
            app.text_SNR_mask.Tag = 'text_SNR_mask';
            app.text_SNR_mask.Text = 'SNR Cutoff [dbHz]:';
            app.edit_SNR_Mask = uieditfield(app.uipanel_processingOptions, 'text');
            app.edit_SNR_Mask.Tag = 'edit_SNR_Mask';
            app.edit_SNR_Mask.Value = 'NaN';
            app.text_ss_thresh = uilabel(app.uipanel_processingOptions);
            app.text_ss_thresh.Tag = 'text_ss_thresh';
            app.text_ss_thresh.Text = 'Signal Strength Threshold [1-9]:';
            app.edit_ss_thresh = uieditfield(app.uipanel_processingOptions, 'text');
            app.edit_ss_thresh.Tag = 'edit_ss_thresh';
            app.edit_ss_thresh.Value = '3';
            app.text302 = uilabel(app.uipanel_processingOptions);
            app.text302.Tag = 'text302';
            app.text302.Text = 'Satellite Exclusion Criteria';
            app.text_omc_thresh_c = uilabel(app.uipanel_processingOptions);
            app.text_omc_thresh_c.Tag = 'text_omc_thresh_c';
            app.text_omc_thresh_c.Text = 'Threshold code [m]:';
            app.text_omc_thresh_p = uilabel(app.uipanel_processingOptions);
            app.text_omc_thresh_p.Tag = 'text_omc_thresh_p';
            app.text_omc_thresh_p.Text = 'Threshold phase [m]:';
            app.text_omc_fac = uilabel(app.uipanel_processingOptions);
            app.text_omc_fac.Tag = 'text_omc_fac';
            app.text_omc_fac.Text = 'Factor:';
            app.edit_omc_thresh_c = uieditfield(app.uipanel_processingOptions, 'text');
            app.edit_omc_thresh_c.Tag = 'edit_omc_thresh_c';
            app.edit_omc_thresh_c.Value = '25';
            app.edit_omc_thresh_p = uieditfield(app.uipanel_processingOptions, 'text');
            app.edit_omc_thresh_p.Tag = 'edit_omc_thresh_p';
            app.edit_omc_thresh_p.Value = '2';
            app.edit_omc_fac = uieditfield(app.uipanel_processingOptions, 'text');
            app.edit_omc_fac.Tag = 'edit_omc_fac';
            app.edit_omc_fac.Value = 'NaN';
            app.text_omc_window = uilabel(app.uipanel_processingOptions);
            app.text_omc_window.Tag = 'text_omc_window';
            app.text_omc_window.Text = 'Epochs:';
            app.edit_omc_window = uieditfield(app.uipanel_processingOptions, 'text');
            app.edit_omc_window.Tag = 'edit_omc_window';
            app.edit_omc_window.Value = 'NaN';
            app.text_smooth = uilabel(app.uipanel_processingOptions);
            app.text_smooth.Tag = 'text_smooth';
            app.text_smooth.Visible = 'off';
            app.text_smooth.Text = 'Smoothing factor:';
            app.edit_smooth = uieditfield(app.uipanel_processingOptions, 'text');
            app.edit_smooth.Tag = 'edit_smooth';
            app.edit_smooth.Visible = 'off';
            app.edit_smooth.Value = '0.5';
            app.text_version = uilabel(app.figure1);
            app.text_version.Tag = 'text_version';
            app.text_version.Text = 'Version 1.0, © TUW 2020';
            app.pushbutton_run = uibutton(app.figure1, 'push');
            app.pushbutton_run.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_run_Callback, true);
            app.pushbutton_run.Tag = 'pushbutton_run';
            app.pushbutton_run.Text = 'RUN';
            app.pushbutton_stop_calculation = uibutton(app.figure1, 'push');
            app.pushbutton_stop_calculation.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_stop_calculation_Callback, true);
            app.pushbutton_stop_calculation.Tag = 'pushbutton_stop_calculation';
            app.pushbutton_stop_calculation.Text = 'STOP';
            app.pushbutton_download = uibutton(app.figure1, 'push');
            app.pushbutton_download.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_download_Callback, true);
            app.pushbutton_download.Tag = 'pushbutton_download';
            app.pushbutton_download.Enable = 'off';
            app.pushbutton_download.Text = 'DOWNLOAD';
            app.text_output = uilabel(app.figure1);
            app.text_output.Tag = 'text_output';
            app.text_output.Text = 'Subdirectory/Processing-Name:';
            app.edit_output = uieditfield(app.figure1, 'text');
            app.edit_output.ValueChangedFcn = createCallbackFcn(app, @edit_output_Callback, true);
            app.edit_output.Tag = 'edit_output';
            app.pushbutton_delete = uibutton(app.figure1, 'push');
            app.pushbutton_delete.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_delete_Callback, true);
            app.pushbutton_delete.Tag = 'pushbutton_delete';
            app.pushbutton_delete.Text = 'DELETE';
            app.pushbutton_menu_close_figures = uibutton(app.figure1, 'push');
            app.pushbutton_menu_close_figures.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_menu_close_figures_Callback, true);
            app.pushbutton_menu_close_figures.Tag = 'pushbutton_menu_close_figures';
            app.pushbutton_menu_close_figures.Text = 'Close All Figures';
            app.checkbox_batch_proc = uicheckbox(app.figure1);
            app.checkbox_batch_proc.ValueChangedFcn = createCallbackFcn(app, @checkbox_batch_proc_Callback, true);
            app.checkbox_batch_proc.Tag = 'checkbox_batch_proc';
            app.checkbox_batch_proc.Text = 'Batch-Processing';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_PPP_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)GUI_PPP_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end